classdef simulate_rf_pulse_b1_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        calc_rf_pulse_toolUIFigure      matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        reset_Gradients_Button          matlab.ui.control.Button
        B1_is_cryo_CheckBox             matlab.ui.control.CheckBox
        TabGroup2                       matlab.ui.container.TabGroup
        D1_simulationTab                matlab.ui.container.Tab
        Simulate1D_Button               matlab.ui.control.Button
        freq_hz_npoints_EditField       matlab.ui.control.EditField
        freq_hz_low_EditField           matlab.ui.control.NumericEditField
        freq_hz_high_EditField          matlab.ui.control.NumericEditField
        yrange_cm_npoints_EditField     matlab.ui.control.EditField
        yrange_cm_EditField             matlab.ui.control.NumericEditField
        gradientstrength_khzcm_EditField  matlab.ui.control.NumericEditField
        pulseduration_ms_npoints_EditField  matlab.ui.control.EditField
        pulseduration_ms_EditField      matlab.ui.control.NumericEditField
        PulseDurationmsEditFieldLabel   matlab.ui.control.Label
        pulseduration_ms_res_Label      matlab.ui.control.Label
        yrange_cm_res_Label             matlab.ui.control.Label
        freqHzEditFieldLabel            matlab.ui.control.Label
        freq_hz_res_Label               matlab.ui.control.Label
        ycmLabel                        matlab.ui.control.Label
        yGradientStrengthkHzcmLabel     matlab.ui.control.Label
        slice_thick                     matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        valueLabel                      matlab.ui.control.Label
        resolutionLabel                 matlab.ui.control.Label
        pointsLabel                     matlab.ui.control.Label
        D2_simulationTab                matlab.ui.container.Tab
        Simulate2D_Button               matlab.ui.control.Button
        freq2D_hz_low_EditField         matlab.ui.control.NumericEditField
        freq2D_hz_EditFieldLabel        matlab.ui.control.Label
        freq2D_hz_res_Label             matlab.ui.control.Label
        freq2D_hz_npoints_EditField     matlab.ui.control.EditField
        freq2D_hz_high_EditField        matlab.ui.control.NumericEditField
        y2D_cm_Label                    matlab.ui.control.Label
        yrange2D_cm_EditField           matlab.ui.control.NumericEditField
        yrange2D_cm_res_Label           matlab.ui.control.Label
        yrange2D_cm_npoints_EditField   matlab.ui.control.EditField
        xrange2D_cm_EditField           matlab.ui.control.NumericEditField
        x2D_cm_Label                    matlab.ui.control.Label
        xrange2D_cm_resLabel            matlab.ui.control.Label
        xrange2D_cm_npoints_EditField   matlab.ui.control.EditField
        gradientduration2D_ms_resLabel  matlab.ui.control.Label
        gradientduration2D_ms_npoints_EditField  matlab.ui.control.EditField
        gradientduration2D_ms_EditField  matlab.ui.control.NumericEditField
        gradientduration2D_ms_EditFieldLabel  matlab.ui.control.Label
        pulseduration2D_ms_EditField    matlab.ui.control.NumericEditField
        pulseduration2D_ms_EditFieldLabel  matlab.ui.control.Label
        pulseduration2D_ms_res_Label    matlab.ui.control.Label
        pulseduration2D_ms_npoints_EditField  matlab.ui.control.EditField
        valueLabel2D                    matlab.ui.control.Label
        resolutionLabel2D               matlab.ui.control.Label
        pointsLabel2D                   matlab.ui.control.Label
        gradientstrength2D_khzcm_EditField  matlab.ui.control.NumericEditField
        yGradientStrengthkHzcmLabel_2   matlab.ui.control.Label
        slice_thick2D                   matlab.ui.control.Label
        EditField2D                     matlab.ui.control.EditField
        GradientEditField               matlab.ui.control.EditField
        GradientEditFieldLabel          matlab.ui.control.Label
        LoadGradient_Button             matlab.ui.control.Button
        SelectGradient_Button           matlab.ui.control.Button
        reset_B1map_Button              matlab.ui.control.Button
        startparpoolButton              matlab.ui.control.Button
        progress_sim_repLabel           matlab.ui.control.Label
        SimulateRepsButton              matlab.ui.control.Button
        SimulaterepeatedExcitationFISPbSSFPLabel  matlab.ui.control.Label
        SimulateRepetitionsTabGroup     matlab.ui.container.TabGroup
        BasicParameterTab               matlab.ui.container.Tab
        repetition_time_msEditField     matlab.ui.control.NumericEditField
        repetition_time_npointsEditField  matlab.ui.control.NumericEditField
        nrepetitions_EditField          matlab.ui.control.NumericEditField
        first_repetition_time_msLabel   matlab.ui.control.Label
        amp_1stpulse_EditField          matlab.ui.control.NumericEditField
        freq_1stpulse_HzEditField       matlab.ui.control.NumericEditField
        freq_1stpulse_EditFieldLabel    matlab.ui.control.Label
        FirstpulseampEditFieldLabel     matlab.ui.control.Label
        progress_simRepLabel_10         matlab.ui.control.Label
        progress_simRepLabel_9          matlab.ui.control.Label
        progress_simRepLabel_8          matlab.ui.control.Label
        progress_simRepLabel_7          matlab.ui.control.Label
        progress_simRepLabel_6          matlab.ui.control.Label
        progress_simRepLabel_5          matlab.ui.control.Label
        progress_simRepLabel_4          matlab.ui.control.Label
        progress_simRepLabel_3          matlab.ui.control.Label
        progress_simRepLabel_2          matlab.ui.control.Label
        progress_simRepLabel            matlab.ui.control.Label
        incphaseLabel                   matlab.ui.control.Label
        NRepsLabel                      matlab.ui.control.Label
        NpointsLabel                    matlab.ui.control.Label
        TRmsLabel                       matlab.ui.control.Label
        first_repetition_time_msEditField  matlab.ui.control.NumericEditField
        incphaseEditField               matlab.ui.control.NumericEditField
        SpoilerGradCheckBox             matlab.ui.control.CheckBox
        TipbackCheckBox                 matlab.ui.control.CheckBox
        PlotresultsButton               matlab.ui.control.Button
        AdvParameterTab                 matlab.ui.container.Tab
        phasetbEditField                matlab.ui.control.NumericEditField
        phasetbEditFieldLabel           matlab.ui.control.Label
        alpha2prepCheckBox              matlab.ui.control.CheckBox
        highresoutCheckBox              matlab.ui.control.CheckBox
        homoB1CheckBox                  matlab.ui.control.CheckBox
        altphaseEditField               matlab.ui.control.NumericEditField
        altphaseEditFieldLabel          matlab.ui.control.Label
        freq_2ndpulse_HzEditField       matlab.ui.control.NumericEditField
        fHzEditFieldLabel               matlab.ui.control.Label
        AlterNTimesEditField            matlab.ui.control.NumericEditField
        AlterNTimesLabel                matlab.ui.control.Label
        metabsCheckBox                  matlab.ui.control.CheckBox
        alpha2ndPulseEditField          matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        TRGmsLabel                      matlab.ui.control.Label
        duration_refocus_grad           matlab.ui.control.NumericEditField
        SimrangeTab                     matlab.ui.container.Tab
        a2CheckBox                      matlab.ui.control.CheckBox
        TR2CheckBox                     matlab.ui.control.CheckBox
        plotresCheckBox                 matlab.ui.control.CheckBox
        TrfEditFieldLabel               matlab.ui.control.Label
        TrfRange_1EditField             matlab.ui.control.NumericEditField
        TrfRange_2EditField             matlab.ui.control.NumericEditField
        TrfRange_NEditField             matlab.ui.control.NumericEditField
        simrangeCheckBox                matlab.ui.control.CheckBox
        SimRangeButton                  matlab.ui.control.Button
        T2Label_3                       matlab.ui.control.Label
        FARange_1EditField              matlab.ui.control.NumericEditField
        FARange_2EditField              matlab.ui.control.NumericEditField
        FARange_NEditField              matlab.ui.control.NumericEditField
        TRRange_NEditField              matlab.ui.control.NumericEditField
        TRRange_2EditField              matlab.ui.control.NumericEditField
        TRRange_1EditField              matlab.ui.control.NumericEditField
        T2Label_2                       matlab.ui.control.Label
        T2Range_1EditField              matlab.ui.control.NumericEditField
        T2Label                         matlab.ui.control.Label
        T2Range_NEditField              matlab.ui.control.NumericEditField
        T2Range_2EditField              matlab.ui.control.NumericEditField
        T1Range_NEditField              matlab.ui.control.NumericEditField
        T1Range_2EditField              matlab.ui.control.NumericEditField
        T1Range_1EditField              matlab.ui.control.NumericEditField
        T1EditFieldLabel                matlab.ui.control.Label
        nslices_3                       matlab.ui.control.Label
        nslices_2                       matlab.ui.control.Label
        nslices                         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        findGradTab                     matlab.ui.container.Tab
        find_GradButton                 matlab.ui.control.Button
        findGradientstrengthforB1compensationPanel  matlab.ui.container.Panel
        progress_grad_findLabel         matlab.ui.control.Label
        pointsyLabel                    matlab.ui.control.Label
        NpointsLabel_2                  matlab.ui.control.Label
        G2kHzcmLabel                    matlab.ui.control.Label
        G1kHzcmLabel                    matlab.ui.control.Label
        find_Gradients_ind2EditField    matlab.ui.control.NumericEditField
        find_Gradients_ind1EditField    matlab.ui.control.NumericEditField
        BestGradEditField               matlab.ui.control.NumericEditField
        BestGradEditFieldLabel          matlab.ui.control.Label
        find_Gradients_npointsEditField  matlab.ui.control.NumericEditField
        find_Gradients_highEditField    matlab.ui.control.NumericEditField
        find_Gradients_lowEditField     matlab.ui.control.NumericEditField
        RFpowerTab                      matlab.ui.container.Tab
        WButton                         matlab.ui.control.Button
        overlayrefpowCheckBox           matlab.ui.control.CheckBox
        xmmEditField                    matlab.ui.control.NumericEditField
        xmmEditFieldLabel               matlab.ui.control.Label
        RefpowLabel                     matlab.ui.control.Label
        calcrefpowButton                matlab.ui.control.Button
        ymmEditField                    matlab.ui.control.NumericEditField
        ymmEditFieldLabel               matlab.ui.control.Label
        findRFPowerCheckBox             matlab.ui.control.CheckBox
        RFPowerWEditFieldLabel          matlab.ui.control.Label
        RFPowerLabel                    matlab.ui.control.Label
        RFPowerWEditField               matlab.ui.control.NumericEditField
        usemapCheckBox                  matlab.ui.control.CheckBox
        saveTab                         matlab.ui.container.Tab
        save_fileEditField              matlab.ui.control.EditField
        fileEditFieldLabel              matlab.ui.control.Label
        saveButton                      matlab.ui.control.Button
        repEditField_high               matlab.ui.control.NumericEditField
        repEditField_low                matlab.ui.control.NumericEditField
        repEditFieldLabel               matlab.ui.control.Label
        saveoptions_ListBox             matlab.ui.control.ListBox
        b1_mag_khz_EditField            matlab.ui.control.NumericEditField
        alphaLabel_4                    matlab.ui.control.Label
        SamplePanel                     matlab.ui.container.Panel
        T1_sEditField                   matlab.ui.control.EditField
        T1sEditFieldLabel               matlab.ui.control.Label
        T2_sEditField                   matlab.ui.control.EditField
        HPEditField                     matlab.ui.control.NumericEditField
        HPEditFieldLabel                matlab.ui.control.Label
        T2sEditFieldLabel               matlab.ui.control.Label
        NucleusButtonGroup              matlab.ui.container.ButtonGroup
        CButton                         matlab.ui.control.RadioButton
        HButton                         matlab.ui.control.RadioButton
        SelectAnatImage_Button          matlab.ui.control.Button
        calcButton                      matlab.ui.control.Button
        LoadAnatImage_Button            matlab.ui.control.Button
        AnatImageEditField              matlab.ui.control.EditField
        AnatImageEditFieldLabel         matlab.ui.control.Label
        alphaLabel_3                    matlab.ui.control.Label
        bwfac_HzsEditField              matlab.ui.control.NumericEditField
        sint_EditField                  matlab.ui.control.NumericEditField
        alphaLabel_2                    matlab.ui.control.Label
        flipangle_degEditField          matlab.ui.control.NumericEditField
        alphaLabel                      matlab.ui.control.Label
        LoadB1Profile_Button            matlab.ui.control.Button
        SelectB1Profile_Button          matlab.ui.control.Button
        LoadRFPulse_Button              matlab.ui.control.Button
        SelectRFPulse_Button            matlab.ui.control.Button
        B1ProfileEditField              matlab.ui.control.EditField
        B1ProfileEditFieldLabel         matlab.ui.control.Label
        RFPulseEditField                matlab.ui.control.EditField
        RFPulseEditFieldLabel           matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        refpow_map_caxis_2              matlab.ui.control.Slider
        repSlider                       matlab.ui.control.Slider
        repSliderLabel                  matlab.ui.control.Label
        responseprofileCheckBox         matlab.ui.control.CheckBox
        bssfpsignalCheckBox             matlab.ui.control.CheckBox
        refpow_map_caxis                matlab.ui.control.Slider
        homoB1ListBox                   matlab.ui.control.ListBox
        homoB1ListBoxLabel              matlab.ui.control.Label
        inhomoB1ListBox                 matlab.ui.control.ListBox
        inhomoB1ListBoxLabel            matlab.ui.control.Label
        UseFrequenciesCheckBox          matlab.ui.control.CheckBox
        FrequenciesPanel                matlab.ui.container.Panel
        HzLabel_2                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        Freq2EditField                  matlab.ui.control.NumericEditField
        Freq2EditFieldLabel             matlab.ui.control.Label
        Freq1EditField                  matlab.ui.control.NumericEditField
        Freq1EditFieldLabel             matlab.ui.control.Label
        MetabolitesPanel                matlab.ui.container.Panel
        PulseFreqEditField              matlab.ui.control.NumericEditField
        HzLabel_3                       matlab.ui.control.Label
        PulseFreqEditFieldLabel         matlab.ui.control.Label
        ppmLabel_3                      matlab.ui.control.Label
        BasicFreqEditField              matlab.ui.control.NumericEditField
        BasicFreqEditFieldLabel         matlab.ui.control.Label
        ppmLabel_2                      matlab.ui.control.Label
        ppmLabel                        matlab.ui.control.Label
        Freq2_ppmEditField              matlab.ui.control.NumericEditField
        Freq2EditField_2Label           matlab.ui.control.Label
        Freq1_ppmEditField              matlab.ui.control.NumericEditField
        Freq1EditField_2Label           matlab.ui.control.Label
        SliceSpinner                    matlab.ui.control.Spinner
        SliceSpinnerLabel               matlab.ui.control.Label
        ShowExcitationProfileCheckBox   matlab.ui.control.CheckBox
        Show2FreqsCheckBox              matlab.ui.control.CheckBox
        freqSlider                      matlab.ui.control.Slider
        freqSliderLabel                 matlab.ui.control.Label
        UIAxes_anat_image               matlab.ui.control.UIAxes
        UIAxes_mag_vs_freq              matlab.ui.control.UIAxes
        UIAxes_rfpulse                  matlab.ui.control.UIAxes
        UIAxes_b1profile                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        parameters = struct();% Description
        data = struct();% Description
    end
    
    methods (Access = private)
        function [] = the_updater_func(app)
            app.parameters.khz_in_g = app.parameters.gamma / 1000;
            
            % check wether 2 frequency option can be activated:
            if (app.parameters.freq_hz_npoints == 1)
                app.Show2FreqsCheckBox.Enable = 0;
                app.Show2FreqsCheckBox.Value = 0;
            else
                app.Show2FreqsCheckBox.Enable = 1;
            end
            

        end
        
        %% calc stuff -----------------------------
        function [grad_kHz_cm_range_sim, gradient_G_cm_range_sim] = calc_gradients(app, dt_sim, Trf, dim)
            % grad_Gcm = grad_kHzcm / khz_in_g;
            khz_in_g = app.parameters.gamma / 1000;
            if (dim == 1)
                gradient_kHz_cm = app.parameters.gradient_kHz_cm;
            else
                gradient_kHz_cm = app.parameters.gradientstrength2D_khzcm;
            end
            gradient_G_cm = gradient_kHz_cm / khz_in_g;
            app.parameters.gradient_G_cm = gradient_G_cm; 
            
            % range to simulate over
            % range_cm_sim = app.parameters.khz_in_g; % 4 cm
            
            % number of points:
            grad_on_points = round((Trf / dt_sim));
            grad_range_sim = ones(grad_on_points, 1);
            % plus/minus
            grad_range_sim = [grad_range_sim.' -grad_range_sim.'/2]; % rephasing gradients on
            
            % multiply by gradient amplitude (G/cm)
            gradient_G_cm_range_sim = grad_range_sim * gradient_G_cm;

            % multiply by gradient amplitude (kHz/cm)
            grad_kHz_cm_range_sim = grad_range_sim * gradient_kHz_cm;
            
            
            % save in app.data:
            app.data.gradient_G_cm_range_sim = gradient_G_cm_range_sim;
            app.data.grad_kHz_cm_range_sim = grad_kHz_cm_range_sim ;
        end
        
        function [] = calc_freq_hz_res(app)
            %-- function that calculates the frequency resolution

            % calc frequency resolution:
            app.parameters.freq_hz_res = (app.parameters.freq_hz_high + 1 - ...
                                          app.parameters.freq_hz_low) / ...
                                          app.parameters.freq_hz_npoints;
            % update the Label:
            app.freq_hz_res_Label.Text = num2str(app.parameters.freq_hz_res);

            % calculate the frequency rage
            app.parameters.freq_hz_range = linspace(app.parameters.freq_hz_low, ...
                                       app.parameters.freq_hz_high, ...
                                       app.parameters.freq_hz_npoints);
            % update slider of result 
            app.freqSlider.Limits = [app.freq_hz_low_EditField.Value 
                                     app.freq_hz_high_EditField.Value];

        end
        
        function [] = calc_yrange_cm_res(app)
            % -- calculates the y-range resolution
            
            % calcresolution:
            app.parameters.yrange_cm_res = app.parameters.yrange_cm / ...
                                           app.parameters.yrange_cm_npoints;
            % update label:
            app.yrange_cm_res_Label.Text = num2str(app.parameters.yrange_cm_res);
        end
        
        function [] = calc_pulse_duration_ms_res(app, dim)
            % -- calculates the duration of the RF Pulse:
            
            % if 1D simulation:
            if (dim == 1)
            app.parameters.pulse_duration_ms_res = app.parameters.pulse_duration_ms / ...
                                                   app.parameters.pulse_duration_ms_npoints;
            app.pulseduration_ms_res_Label.Text = num2str(app.parameters.pulse_duration_ms_res);
            % if 2D/3D simulation:
            else
            app.parameters.pulse_duration2D_ms_res = app.parameters.pulse_duration2D_ms / ...
                                                     app.parameters.pulse_duration2D_ms_npoints;
            app.pulseduration2D_ms_res_Label.Text = num2str(app.parameters.pulse_duration2D_ms_res);
            end
        end
        
        function [] = calc_sint(app)
            % -- calculates the integration factor. The integration factor
            % is related to the necessary B1 to reach a certain flip angle.
            
            try
                % get RF Pulse
                pulse = app.data.rfpulse;
                % Calculate integration factor:
                sint = sum(real(pulse))/numel(pulse);
                % Test if integration factor is smaller 0 (can't be)
                if (sint < 0)
                    warning('integration factor < 0, are you maybe using adiabtic pulses?')
                    disp('setting to sint=abs(sinth)');
                    sint = abs(sint);
                    app.sint_EditField.Tooltip = {'was set to abs(sint) because it was' ,'negative, dont trust this'};
                    app.sint_EditField.BackgroundColor = 'r';
                    app.bwfac_HzsEditField.BackgroundColor = 'r';
                    app.bwfac_HzsEditField.Tooltip = {'was set to abs(sint) because it was' ,'negative, dont trust this'};
                else
                    app.sint_EditField.Tooltip = 'Integration factor --> flipangle';
                    app.sint_EditField.BackgroundColor = 'w';
                    app.bwfac_HzsEditField.Tooltip = 'Bandwidth time product --> slice thickness' ;
                    app.bwfac_HzsEditField.BackgroundColor = 'w';
                end

                % save integration factor:
                app.parameters.sint =sint;
                % display:
                app.sint_EditField.Value = sint;
                % calculate time bandwidth factor. This is an intrinsic
                % property of a RF Pulse and connects pulse duration and
                % pulse bandwidth. Plays an important role in the
                % determination of the necessary slice selective gradient
                bwfac = 1000/sint;
                % save time bandwidth factor:
                app.parameters.bwfac_hz_ms = bwfac;
            catch
                warning('Integration factor sint couldnt be calculated!');
            end
            % display:
            app.bwfac_HzsEditField.Value = app.parameters.bwfac_hz_ms;
            app.sint_EditField.Value = app.parameters.sint;
        end
        
        function [] = calc_fa_b1_app(app, fa_changed, b1_changed)
            % -- calculate either the B1 when the flipangle was changed or
            % vice versa
            % -------------------------------------------------------------

            % if the flipangle was changed:
            if fa_changed
                gamma = app.parameters.gamma;
                khz_in_g = gamma / 1000;
                trf = app.parameters.pulse_duration_ms * 1e-3;    
                alpha = app.flipangle_degEditField.Value; % Degrees.
                sint = app.parameters.sint;
                % calculate the new B1 (in Gauss)
                b1_amp_g = (pi * alpha / 180 ) * ... 
                    1/((2*pi*gamma*1e4) * trf* sint) * 1e4;% G
                % save the new B1:
                app.parameters.b1_amp_khz = b1_amp_g * khz_in_g;
                app.b1_mag_khz_EditField.Value = app.parameters.b1_amp_khz;
            
            %  if the B1 was changed:
            else
                gamma = app.parameters.gamma;
                khz_in_g = gamma / 1000;
                trf = app.parameters.pulse_duration_ms * 1e-3;
                sint = app.parameters.sint;
                app.parameters.b1_amp_khz;
                b1_amp_g  = app.parameters.b1_amp_khz / khz_in_g ;
                % calculate the new flipangle:
                alpha = 180* ((2*pi*gamma*1e4) * trf* sint) * ...
                    b1_amp_g / (1e4) / pi;% G
                app.parameters.alpha = alpha;
                app.flipangle_degEditField.Value = alpha;
                pause(0.00001);
            end
        end

        function results = calc_b1(app, fa)
            % -- calulate the B1 depending on the flipangle
            % -------------------------------------------------------------

            gamma = app.parameters.gamma;
            khz_in_g = gamma / 1000;
            trf = app.parameters.pulse_duration_ms * 1e-3;    
            alpha = fa; % Degrees.
            sint = app.parameters.sint;
            
            b1_amp_g = (pi * alpha / 180 ) * ...
                1/((2*pi*gamma*1e4) * trf* sint) * 1e4;% G
            
            % save results:
            results.alpha = alpha;
            results.b1_amp_khz = b1_amp_g * khz_in_g;
            results.b1_amp_g = b1_amp_g;
        end

        function results = calc_fa(app, b1_amp_khz)
            % -- calculate the new flipangle after the B1 amplitude was
            % changed:
            % -------------------------------------------------------------

            gamma = app.parameters.gamma;
            khz_in_g = gamma / 1000;
            trf = app.parameters.pulse_duration_ms * 1e-3;
            sint = app.parameters.sint;
            b1_amp_g  = b1_amp_khz / khz_in_g ;
            alpha = 180* ((2*pi*gamma*1e4) * ...
                trf* sint)* b1_amp_g / (1e4) / pi;% G

            % save results:
            results.alpha = alpha;
            results.b1_amp_khz = b1_amp_khz;
            results.b1_amp_g = b1_amp_g;
        end

        function [x_pos, y_pos] = calc_mmpos_in_voxpos(app, matrix_size, ...
                fov, x_pos_mm, y_pos_mm, offset_mm)
            % calc position of voxel (in mm) in voxels:
            % -------------------------------------------------------------

            % resolution
            res_mm = fov ./ matrix_size ;
            
            % calc positions:
            % image center in voxel:
            center_vox = matrix_size/2;

            % position of pointer in voxel (x)
            x_pos_vox = x_pos_mm /res_mm(2);
            % offset in voxel:
            x_off_vox = offset_mm(2)/res_mm(2);


            % position of pointer in voxel (x)
            y_pos_vox = y_pos_mm /res_mm(1);
            % offset in voxel:
            y_off_vox = offset_mm(1)/res_mm(1);


            x_pos = center_vox(2) + x_pos_vox  + x_off_vox ;
            y_pos = center_vox(1) + y_pos_vox  + y_off_vox ;
            

            % calc positions + taking into account the offset of the
            % anatomical image:
             
        end

        function [] = calc_b1map(app)
            % -- calculate the b1 map according to the coordinates of the
            % anatomical image:
            % -------------------------------------------------------------

            % simple test to see if an anatomical image was loaded:
            if sum(sum(sum(app.data.anat_image))) > 0
                fov_anat = app.parameters.anat_image_fov_mm;
                fov_refpow_map = app.parameters.refpow_map_fov_mm;
                refpow_map = app.data.refpow_map;
            else
            end
            warning('not yet fully implemented!');
        end

        function [] = calc_refpow(app)
            % -- calculate a reference power map from the loaded B1 map:
            % -------------------------------------------------------------

            % calc position of voxel (in mm) in voxels:
            [x_pos, y_pos] = calc_mmpos_in_voxpos(app, ...
                                size(app.data.refpow_map), ...
                                app.parameters.refpow_map_fov_mm, ...
                                app.parameters.refpow_pos_x_mm, ...
                                app.parameters.refpow_pos_y_mm, ...
                                app.parameters.refpow_map_offset_mm);

%             if app.parameters.calc_refpow_outside_map
                % try interpolation along y:
%                     refpow_y_measured = app.data.refpow_map(:, round(x_pos));
%                     refpow_y_interp = exp(interp1(linspace(-app.parameters.refpow_map_fov_mm(1)/2, ...
%                                                             app.parameters.refpow_map_fov_mm(1)/2, 3;)
%             else
                refpow = app.data.refpow_map(round(y_pos), round(x_pos));
%             end
            app.RefpowLabel.Text = [num2str(refpow) 'W'];
            warning('not yet fully implemented!');
        end
      
        function [] = calc_refpow_anat(app)
            % -- calculate the reference power  map according to the coordinates 
            % of the anatomical image:
            % -------------------------------------------------------------

            % calc grid of refpowmap:
            app.data.refpow_map_calc = griddata(app.parameters.refpow_gridy, ...
                                                app.parameters.refpow_gridx, ...
                                                app.data.refpow_map, ...
                                                squeeze(app.parameters.anat_gridy(:,:,app.parameters.anat_central_slice)), ...
                                                squeeze(app.parameters.anat_gridx(:,:,app.parameters.anat_central_slice)));
            warning('not yet fully implemented!');
        end
            
        function [] = calc_slice_thick(app)
            % -- calculate the slice thickness, depending on the pulse
            % duration ath the time-bandwidth factor
            % -------------------------------------------------------------
            
            % get the time-bandwidth factor:
            bw_hz = app.parameters.bwfac_hz_ms / app.parameters.pulse_duration_ms;
            % calculate the slice thickness:
            slice_thick_cm = bw_hz / (app.parameters.gradient_kHz_cm *1e3);
            % display:
            app.slice_thick.Text = [num2str(slice_thick_cm) ' cm'];
        end
        
        function [gridy, gridx, gridz, offset_mm] = define_grid(app, ...
                fov, mat, off, patient_pos, read_orient)
            % -- defines a grid to match B1 map, anatomical image and
            % simulation

            % -------------------------------------------------------------

            % Bruker specific parameter (patient position, chosen when a 
            % study is set up:
            if strcmp(patient_pos, 'Head_Supine')
                offset_mm = permute(off, [2 1 3]);
            elseif 'Head_Prone'
                offset_mm = permute(off, [1 2 3]);
                offset_mm(1) = - offset_mm(1);
            end
            y = ((-fov(1)/2+fov(1)/mat(1)/2):fov(1)/mat(1):(fov(1)/2-fov(1)/mat(1)/2)) + offset_mm(1);
            x = ((-fov(2)/2+fov(2)/mat(2)/2):fov(2)/mat(2):(fov(2)/2-fov(2)/mat(2)/2)) + offset_mm(2);
            z = ((-fov(3)/2+fov(3)/mat(3)/2):fov(3)/mat(3):(fov(3)/2-fov(3)/mat(3)/2)) + offset_mm(3);
            % 2D:
            if isempty(z)
                z = zeros(size(x));
            end


            [gridx, gridy, gridz] = meshgrid(x,y,z);
            disp('');
        end
        %% plot stuff -----------------------------
        function [] = plot_rfpulse(app)
            % -- plot the loaded RF Pulse in the top left subplot
            % -------------------------------------------------------------

            % load loaded pulse:
            pulse = app.data.rfpulse;
            
            % parameters
            % Pulse duration:
            Trf = app.parameters.pulse_duration_ms * 1e-3;    
            % flipangle
            alpha = app.flipangle_degEditField.Value; % Degrees.
            % gyromagnetic ratio
            gamma = app.parameters.gamma; % Hz/G 13C
            % integration factor:
            sint = app.parameters.sint;
            % time-bandwidth factor:
            bwfac =  1000/sint;
            khz_in_g = gamma / 1000;

            % time resolution simulation
            dt_sim = app.parameters.pulse_duration_ms_res*1e-3;
            % time course simulation
            t_rf_sim = 0:dt_sim:Trf-dt_sim;

            % time resolution loaded pulse
            dt_b1 = Trf / numel(pulse(:,1));
            % time course loaded pulse
            t_b1 = 0:dt_b1:Trf-dt_b1;
            
            % interpolate loaded pulse onto to simulate pulse:
            pulse_sim = interp1(t_b1, pulse.', t_rf_sim);
            % plot into top left subplot:
            plot(app.UIAxes_rfpulse,1000 * t_rf_sim, app.b1_mag_khz_EditField.Value * abs(pulse_sim))
            app.UIAxes_rfpulse.YAxis.Label.String = 'Exc. B1 [kHz]';
        end

        function [] = plot_anat_image(app, slice, show_exc_profile)
            % -- plot an anatomical image (lower right subplot)
            % -------------------------------------------------------------
            
            % test if an anatomical image was loaded:
            if sum(sum(app.data.anat_image))
                % reset subplot:
                cla(app.UIAxes_anat_image, 'reset');
                % get anatomical image from desired slice:
                anat_image_slice = squeeze(app.data.anat_image(:,:, slice));
                
                % plot image
                imagesc(app.UIAxes_anat_image, flipud(anat_image_slice));              
                colormap(app.UIAxes_anat_image, 'bone');
    
                % plot coverage of the animal with the simulated 
                % slice profiles:
                if (app.ShowExcitationProfileCheckBox.Value && ...
                        (abs(sum(sum(sum(app.data.mxy_b1)))) > 0))
                    % B1 simulated range:
                    y_sim_mm = 10 * linspace(-app.parameters.yrange_cm/2, ...
                                        app.parameters.yrange_cm/2, ...
                                        app.parameters.yrange_cm_npoints);
    
                    % interpolate profile onto anatomical range:
                    y_range_anat = linspace(-app.parameters.anat_image_fov_mm(1)/2, ...
                                             app.parameters.anat_image_fov_mm(1)/2, ...
                                             size(app.data.anat_image,2));
    
                    % frequency range:
                    freq_range_hz = linspace(app.parameters.freq_hz_low, ...
                                    app.parameters.freq_hz_high, ...
                                    app.parameters.freq_hz_npoints); % Hz
       
                    % use "Hz" settings
                    if app.UseFrequenciesCheckBox.Value
                    
                    % show 2 profiles (from 2 different simulated frequencies):
                        if app.Show2FreqsCheckBox.Value
                            freq1_hz = app.Freq1EditField.Value;
                            freq2_hz = app.Freq2EditField.Value;
                            
                            % find freqs closest to set frequencies
                            [~,freq1_ind] = min(abs(freq_range_hz-freq1_hz));
                            [~,freq2_ind] = min(abs(freq_range_hz-freq2_hz));
        
                            mxy_b1_interp1 = interp1(y_sim_mm, squeeze(abs(app.data.mxy_b1(:, end, freq1_ind))), y_range_anat);
                            mxy_b1_interp2 = interp1(y_sim_mm, squeeze(abs(app.data.mxy_b1(:, end, freq2_ind))), y_range_anat);
                            
                            % make same intensity:
                            mxy_b1_interp2 = mxy_b1_interp2  / max(mxy_b1_interp2 ) * max(mxy_b1_interp1 );
        
                            cla(app.UIAxes_anat_image);

                            % create a "plane that will cover the anatomical image:
                            % exication profile 1
                            exc_prof1 = zeros(size(app.data.anat_image,1), size(app.data.anat_image,2));
                            exc_prof1(round(end/2)+1:end, :) = repmat(mxy_b1_interp1, [round(size(app.data.anat_image,1)/ 2) 1]);
        
                            % exication profile 1
                            exc_pro2 = zeros(size(app.data.anat_image,1), size(app.data.anat_image,2));
                            exc_pro2(1:round(end/2), :) = repmat(mxy_b1_interp2 , [round(size(app.data.anat_image,1)/ 2) 1]);
        
                            profile_im_1 = exc_prof1 + exc_pro2;
                            profile_im_1 = rot90(profile_im_1, -1);
        
                            BG_1 = ind2rgb(im2uint8(profile_im_1 / max(max(profile_im_1)) ),jet(256));

                            anat_image = mat2gray(anat_image_slice / max(max(anat_image_slice)));
                            
                            % blend parameters
                            threshold = 0.0;
                            alpha = 0.6;
                            amap = (anat_image>=threshold)*alpha; % blend based on gray value!
                            blended = im2double(anat_image).*amap + im2double(BG_1).*(1-amap) ;%+ im2double(BG_2).*(1-amap);
                            
                            % show image in lower right subplot:
                            imagesc(app.UIAxes_anat_image, blended);
                    
                        % show the excitation profile of 1 frequency:
                        else
                            freq1_hz = app.freqSlider.Value;
                            [~,freq1_ind] = min(abs(freq_range_hz-freq1_hz));
                            mxy_b1_interp1 = interp1(y_sim_mm, squeeze(abs(app.data.mxy_b1(:, end, freq1_ind))), y_range_anat);
        
                            cla(app.UIAxes_anat_image);
                            % create a "plane that will cover the anatomical image:
                            profile_im_1 = repmat(mxy_b1_interp1, [size(app.data.anat_image,1) 1]);
                            profile_im_1 = rot90(profile_im_1, -1);


                            BG_1 = ind2rgb(im2uint8(profile_im_1 / max(max(profile_im_1)) ),jet(256));
                            anat_image = mat2gray(anat_image_slice / max(max(anat_image_slice)));
                            
                            % blend parameters
                            threshold = 0.0;
                            alpha = 0.6;
                            amap = (anat_image>=threshold)*alpha; % blend based on gray value!
                            blended = im2double(anat_image).*amap + im2double(BG_1).*(1-amap);
                            
                            imagesc(app.UIAxes_anat_image, blended);
                        end
    
                    % use ppm settings
                    else
                        % translate ppm into Hz
                        freq1_ppm = app.Freq1_ppmEditField.Value;
                        freq2_ppm = app.Freq2_ppmEditField.Value;
                        freqbasic_ppm = app.BasicFreqEditField.Value;
        
                        % ppm to hz depending on nucleus:
                        ppm2hz = app.parameters.gamma*7/100;
        
                        freq1_hz = (freqbasic_ppm - freq1_ppm) * ppm2hz;
                        freq2_hz = (freqbasic_ppm - freq2_ppm) * ppm2hz;
    
                        % add "pulse frequency offset"
                        freq1_hz = freq1_hz + app.PulseFreqEditField.Value;
                        freq2_hz = freq2_hz + app.PulseFreqEditField.Value;
    
                        % show 2 simulated frequencies:
                        if app.Show2FreqsCheckBox.Value
                            % find freqs closest to set frequencies
                            [~,freq1_ind] = min(abs(freq_range_hz-freq1_hz));
                            [~,freq2_ind] = min(abs(freq_range_hz-freq2_hz));
        
                            mxy_b1_interp1 = interp1(y_sim_mm, squeeze(abs(app.data.mxy_b1(:, end, freq1_ind))), y_range_anat);
                            mxy_b1_interp2 = interp1(y_sim_mm, squeeze(abs(app.data.mxy_b1(:, end, freq2_ind))), y_range_anat);
                            % make same intensity
                            mxy_b1_interp2 = mxy_b1_interp2  / max(mxy_b1_interp2 ) * max(mxy_b1_interp1 );
        
                            cla(app.UIAxes_anat_image);
                            % create a "plane that will cover the anatomical image:
                            exc_prof1 = zeros(size(app.data.anat_image,1), size(app.data.anat_image,2));
                            exc_prof1(round(end/2)+1:end, :) = repmat(mxy_b1_interp1, [round(size(app.data.anat_image,1)/ 2) 1]);
        
                            exc_pro2 = zeros(size(app.data.anat_image,1), size(app.data.anat_image,2));
                            exc_pro2(1:round(end/2), :) = repmat(mxy_b1_interp2 , [round(size(app.data.anat_image,1)/ 2) 1]);
        
                            profile_im_1 = exc_prof1 + exc_pro2;
                            profile_im_1 = rot90(profile_im_1, -1);

               
                            BG_1 = ind2rgb(im2uint8(profile_im_1 / max(max(profile_im_1)) ),jet(256));

                            anat_image = mat2gray(anat_image_slice / max(max(anat_image_slice)));
                            
                            % blend parameters
                            threshold = 0.0;
                            alpha = 0.6;
                            amap = (anat_image>=threshold)*alpha; % blend based on gray value!
                            blended = im2double(anat_image).*amap + im2double(BG_1).*(1-amap) ;%+ im2double(BG_2).*(1-amap);
                            
                            imagesc(app.UIAxes_anat_image, blended);
        
                        % show 1 simulated frequencies:
                        else
                            [~,freq1_ind] = min(abs(freq_range_hz-freq1_hz));
                            mxy_b1_interp1 = interp1(y_sim_mm, squeeze(abs(app.data.mxy_b1(:, end, freq1_ind))), y_range_anat);
        
                            cla(app.UIAxes_anat_image);
                            % create a "plane that will cover the anatomical image:
                            profile_im_1 = repmat(mxy_b1_interp1, [size(app.data.anat_image,1) 1]);
                            BG_1 = ind2rgb(im2uint8(profile_im_1 / max(max(profile_im_1)) ),jet(256));
                            anat_image = mat2gray(anat_image_slice / max(max(anat_image_slice)));
                            
                            % blend parameters
                            threshold = 0.0;
                            alpha = 0.6;
                            amap = (anat_image>=threshold)*alpha; % blend based on gray value!
                            blended = im2double(anat_image).*amap + im2double(BG_1).*(1-amap);
                            
                            % show image in lower right subplot:
                            imagesc(app.UIAxes_anat_image, rot90(blended, -1));
                        end
                    end
                end
                
                % overlay reference power map onto anatomical image
                if app.overlayrefpowCheckBox.Value
                    calc_refpow_anat(app)
                    % get refpowmap
                    refpow_map = app.data.refpow_map_calc;
                    % rgb
                    val = app.refpow_map_caxis_2.Value;
                    BG_1 = ind2rgb(im2uint8(0.5*refpow_map / max(max(refpow_map))), jet(256));
                    % rgb
                    anat_image = ind2rgb(im2uint8(anat_image_slice / max(max(anat_image_slice))), bone(256));
                   
                    % blend parameters
                    threshold = 0.0;
                    alpha = 0.6;
                    amap = (anat_image>=threshold)*alpha; % blend based on gray value!
                    blended = im2double(anat_image).*amap + im2double(BG_1).*(1-amap) ;%+ im2double(BG_2).*(1-amap);
                    
                    imagesc(app.UIAxes_anat_image, flip(blended));
                    
                    pbaspect(app.UIAxes_anat_image, [size(app.data.anat_image,1) size(app.data.anat_image,2) 1]);

                end

                % labeling:
                %----------------------------------------------------------
                    sz = size(app.data.anat_image);
                    res = app.parameters.anat_image_fov_mm(1:2) ./ sz(1:2);
                    
                    % set ticks + labels:
                    xtick = linspace(1,size(app.data.anat_image,2),5);
                    ytick = linspace(1,size(app.data.anat_image,1),5);

                    xticklabel = linspace(-app.parameters.anat_image_fov_mm(1)/2+res(1)/2, ...
                                           app.parameters.anat_image_fov_mm(1)/2-res(1)/2, 5);
                    yticklabel = linspace(-app.parameters.anat_image_fov_mm(2)/2+res(2)/2, ...
                                           app.parameters.anat_image_fov_mm(2)/2-res(2)/2, 5);

                    xticklabel = round(xticklabel  - app.parameters.anat_image_offset_mm(2), 1);
                    yticklabel = round(yticklabel  - app.parameters.anat_image_offset_mm(1), 1);

                    app.UIAxes_anat_image.XTick = xtick;
                    app.UIAxes_anat_image.YTick = ytick;
                    
                    app.UIAxes_anat_image.XTickLabel = xticklabel;
                    app.UIAxes_anat_image.YTickLabel = yticklabel;
                    app.UIAxes_anat_image.XLabel.String = 'x [mm]';
                    app.UIAxes_anat_image.YLabel.String = 'y [mm]';
                    grid1 = [xtick;xtick];
                    grid2 = repmat([ytick(1);ytick(end)], 1, length(xtick));
                    hold(app.UIAxes_anat_image, 'on');
                    plot(app.UIAxes_anat_image, grid1, grid2, 'w-', 'LineWidth', 0.025);
                    app.UIAxes_anat_image.YDir = 'normal';
                    grid1 = [ytick;ytick];
                    grid2 = repmat([xtick(1);xtick(end)], 1, length(ytick));
                    plot(app.UIAxes_anat_image, grid2, grid1, 'w-', 'LineWidth', 0.025);
                    hold(app.UIAxes_anat_image, 'off');


                    % plot position of the refpow:
                    [x_pos, y_pos] = calc_mmpos_in_voxpos(app, ...
                                    size(app.data.anat_image), ...
                                    app.parameters.anat_image_fov_mm, ...
                                    app.parameters.refpow_pos_x_mm, ...
                                    app.parameters.refpow_pos_y_mm, ...
                                    app.parameters.anat_image_offset_mm);            
    
                    hold(app.UIAxes_anat_image, 'on');
                    % plot result
                    plot(app.UIAxes_anat_image, x_pos, y_pos, 'rx');
                    hold(app.UIAxes_anat_image, 'off');
                    % keep image at correct ratio
                    pbaspect(app.UIAxes_anat_image, [size(app.data.anat_image,2) size(app.data.anat_image,1) 1]);
                    yyaxis(app.UIAxes_anat_image, 'right')
                    set(app.UIAxes_anat_image, 'ytick', ytick, 'yticklabels',  ...
                        flip(linspace(-app.parameters.anat_image_fov_mm(2)/2+res(2)/2, ...
                                  app.parameters.anat_image_fov_mm(2)/2-res(2)/2, 5) - app.parameters.anat_image_offset_mm(2)), 'Ycolor', 'k')
            end
            app.SliceSpinner.Value = slice;
        end

        function [] = plot_b1profile(app)
            % -- plots a 1D profile of the loaded B1 Map
            % -------------------------------------------------------------

            % load pulse:
            % get B1:
            try
                b1profile_log = app.data.b1TransmitProfileFit_log;
            end
            if (sum(b1profile_log) == 0)
                b1profile_log = ones(64, 1);
            end

            % range over which the B1 map was acquired (hardcode for now):
            x_b1_cm = linspace(0.25, 31.75, 64)/10;
            x_b1_cm = x_b1_cm - 1.6;
            x_b1_cm = x_b1_cm - app.parameters.refpow_map_offset_mm(1)/10;

            % B1 simulated range:
            x_sim_cm = linspace(-app.parameters.yrange_cm/2, ...
                                app.parameters.yrange_cm/2, ...
                                app.parameters.yrange_cm_npoints);

            % interpolate
            b1Transmit_sim_log = interp1(x_b1_cm, b1profile_log, x_sim_cm, 'spline', 'extrap');
            b1Transmit_sim = exp(b1Transmit_sim_log) / max(exp(b1Transmit_sim_log ));
            cla(app.UIAxes_b1profile, 'reset');
            % plot the B1 profile:
            plot(app.UIAxes_b1profile,x_sim_cm,b1Transmit_sim);
        end

        function [] = plot_mag_vs_dist(app, freq)
            % -- plot the simulated magnetization over a defined
            % position/range
            % -------------------------------------------------------------

            % get simulated Magnetization (with B1 field)
            mxy_b1 = app.data.mxy_b1;             
            mz_b1 = app.data.mz_b1;  

            % get simulated Magnetization (homogeneous B1 field)
            mxy = app.data.mxy;             
            mz = app.data.mz;  
            
            % B1 simulated range:
            % if more than 1 position point was simulated:
            if (app.parameters.yrange_cm_npoints > 1)
                x_sim_cm = linspace(-app.parameters.yrange_cm/2, ...
                                     app.parameters.yrange_cm/2, ...
                                     app.parameters.yrange_cm_npoints);
            else
                % take single value:
                x_sim_cm = app.parameters.yrange_cm;
            end
            % get frequency range:
            freq_range_hz = linspace(app.parameters.freq_hz_low, ...
                                app.parameters.freq_hz_high, ...
                                app.parameters.freq_hz_npoints); % Hz
            % get the chosen frequency value:
            freq1_hz = app.Freq1EditField.Value;
            % find the frequency value in the array that's closest to the
            % desired frequency:
            [~,freq1_ind] = min(abs(freq_range_hz-freq));
            x_1 = size(mxy_b1,2);
            try
            if app.UseFrequenciesCheckBox.Value
                % check if 2 frequencies should be plotted:
                if app.Show2FreqsCheckBox.Value
                    freq2_hz = app.Freq2EditField.Value;

                    % find freqs closest to set frequencies
                    [~,freq1_ind] = min(abs(freq_range_hz-freq1_hz));
                    [~,freq2_ind] = min(abs(freq_range_hz-freq2_hz));

                    freq1_hz = freq_range_hz(freq1_ind);
                    freq2_hz = freq_range_hz(freq2_ind);
                    app.Freq1EditField.Value = freq1_hz;
                    app.Freq2EditField.Value = freq2_hz;

                    
                    % plot stuff
                    cla(app.UIAxes_mag_vs_freq);
                    
                    % surface B1
                    l =[];
                    if (app.parameters.plot_inhomo_mx)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(real(mxy_b1(:, x_1 , freq1_ind))), ...
                            'LineStyle','-', 'Color',[0.4940, 0.1840, 0.5560]);
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(real(mxy_b1(:, x_1, freq2_ind))), ...
                            'LineStyle','--', 'Color',[0.4940, 0.1840, 0.5560]);
                        l = [l; "Mx (surface)"; ""];
                    end
                    if (app.parameters.plot_inhomo_my)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(imag(mxy_b1(:, x_1, freq1_ind))), 'r-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(imag(mxy_b1(:, x_1, freq2_ind))), 'r--');
                        l = [l; "My (surface)"; ""];
                    end
                    if (app.parameters.plot_inhomo_mxy)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(abs(mxy_b1(:, x_1, freq1_ind))), 'k-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(abs(mxy_b1(:, x_1, freq2_ind))), 'k--');
                        l = [l; "|Mxy| (surface)"; ""];
                    end
                    if (app.parameters.plot_inhomo_mz)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze((mz_b1(:, x_1, freq1_ind))), 'b-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze((mz_b1(:, x_1, freq2_ind))), 'b--');
                        l = [l; "Mz (surface)"; ""];
                    end
                    if (app.parameters.plot_inhomo_phase)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(angle(mxy_b1(:, x_1, freq1_ind))), 'g-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(angle(mxy_b1(:, x_1, freq2_ind))), 'g--');
                        l = [l; "\phi (surface)"; ""];
                    end


                    % uniform B1
                    if (app.parameters.plot_homo_mx)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(real(mxy(:, x_1, freq1_ind))), 'k.-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(real(mxy(:, x_1, freq2_ind))), 'k.--');
                        l = [l; "Mx (original)"; ""];
                    end
                    if (app.parameters.plot_homo_my)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(imag(mxy(:, x_1, freq1_ind))), 'r-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(imag(mxy(:, x_1, freq2_ind))), 'r--');
                        l = [l; "My (original)"; ""];
                    end
                    if (app.parameters.plot_homo_mxy)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(abs(mxy(:, x_1, freq1_ind))), 'k-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(abs(mxy(:, x_1, freq2_ind))), 'k--');
                        l = [l; "|Mxy| (original)"; ""];
                    end
                    if (app.parameters.plot_homo_mz)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze((mz(:, x_1, freq1_ind))), 'b-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze((mz(:, x_1, freq2_ind))), 'b--');
                        l = [l; "Mz (original)"; ""];
                    end
                    if (app.parameters.plot_homo_phase)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(angle(mxy(:, x_1, freq1_ind))), 'g-');
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(angle(mxy(:, x_1, freq2_ind))), 'g--');
                        l = [l; "\phi (original)"; ""];
                    end
                    
                    app.UIAxes_mag_vs_freq.Title.String = ['f_1 = ' num2str(freq1_hz) 'Hz & f_2 = ' num2str(freq2_hz) 'Hz'];

                    ylims = app.UIAxes_anat_image.YLim;
%                     plot(app.UIAxes_mag_vs_freq,x_sim_cm(app.parameters.find_grad_ind1)*ones(1,10), linspace(ylims(1),ylims(2),10), 'r-');
%                     plot(app.UIAxes_mag_vs_freq,x_sim_cm(app.parameters.find_grad_ind2)*ones(1,10), linspace(ylims(1),ylims(2),10), 'r-');
                    legend(app.UIAxes_mag_vs_freq, l);
                else
                    cla(app.UIAxes_mag_vs_freq);
                    hold(app.UIAxes_mag_vs_freq, 'on');
                    % surface B1
                    l = [];
                    if (app.parameters.plot_inhomo_mx)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(real(mxy_b1(:, x_1, freq1_ind))), ...
                            'LineStyle','--', 'Color',[0.4940, 0.1840, 0.5560]);
                        l = [l; "Mx (surface)"];
                    end
                    if (app.parameters.plot_inhomo_my)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(imag(mxy_b1(:, x_1, freq1_ind))), 'r--');
                        l = [l; "My (surface); "];
                    end
                    if (app.parameters.plot_inhomo_mxy)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(abs(mxy_b1(:, x_1, freq1_ind))), 'k--');
                        l = [l; "|Mxy| (surface)"];
                    end
                    if (app.parameters.plot_inhomo_mz)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze((mz_b1(:, x_1, freq1_ind))), 'b--');
                        l = [l; "Mz (surface)"];
                    end
                    if (app.parameters.plot_inhomo_phase)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(angle(mxy_b1(:, x_1, freq1_ind))), 'g--');
                        l = [l; "\phi (surface)"];
                    end

                    % uniform B1
                    if (app.parameters.plot_homo_mx)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(real(mxy(:, x_1, freq1_ind))), ...
                            'LineStyle','-', 'Color',[0.4940, 0.1840, 0.5560]);
                        l = [l; 'Mx (uniform)'];
                    end
                    if (app.parameters.plot_homo_my)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(imag(mxy(:, x_1, freq1_ind))), 'r-');
                        l = [l; "Mx (uniform)"];
                    end
                    if (app.parameters.plot_homo_mxy)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(abs(mxy(:, x_1, freq1_ind))), 'k-');
                        l = [l; "|Mxy| (uniform)"];
                    end
                    if (app.parameters.plot_homo_mz)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze((mz(:, x_1, freq1_ind))), 'b-');
                        l = [l; "Mz (uniform)"];
                    end
                    if (app.parameters.plot_homo_phase)
                        plot(app.UIAxes_mag_vs_freq,x_sim_cm,squeeze(angle(mxy(:, x_1, freq1_ind))), 'g-');
                        l = [l; "\phi (uniform)"];
                    end
                   app.UIAxes_mag_vs_freq.Title.String = ['f = ' num2str(freq_range_hz(freq1_ind)) 'Hz'];
                    
                legend(app.UIAxes_mag_vs_freq, l);
                end
            % use ppm
            else 
                % translate ppm into Hz
                freq1_ppm = app.parameters.freq_ppm1;
                freq2_ppm = app.parameters.freq_ppm2;
                freqbasic_ppm = app.parameters.freq_ppm_basic;

                % ppm to hz depending on nucleus:
                ppm2hz = app.parameters.gamma*7/100;

                freq1_hz = (freqbasic_ppm - freq1_ppm) * ppm2hz;
                freq2_hz = (freqbasic_ppm - freq2_ppm) * ppm2hz;

                % add "pulse freqencies":
                freq1_hz = freq1_hz + app.parameters.freq_hz_pulse;
                freq2_hz = freq2_hz + app.parameters.freq_hz_pulse;

                freq_range_hz = linspace(app.parameters.freq_hz_low, ...
                                app.parameters.freq_hz_high, ...
                                app.parameters.freq_hz_npoints); % Hz
                % find freqs closest to set frequencies
                [~,freq1_ind] = min(abs(freq_range_hz-freq1_hz));
                [~,freq2_ind] = min(abs(freq_range_hz-freq2_hz));
                
                % plot stuff
                cla(app.UIAxes_mag_vs_freq);
                plot(app.UIAxes_mag_vs_freq,squeeze(abs(mxy_b1(:, x_1, freq1_ind))), 'k');
                hold(app.UIAxes_mag_vs_freq, 'on');
                plot(app.UIAxes_mag_vs_freq,squeeze(abs(mxy_b1(:, x_1, freq2_ind))), 'r');
                app.UIAxes_mag_vs_freq.Title.String = ['f_1 = ' num2str(freq1_hz) 'Hz & f_2 = ' num2str(freq2_hz) 'Hz'];
                plot(app.UIAxes_mag_vs_freq,x_sim_cm(app.parameters.find_grad_ind1)*ones(1,10), linspace(0,0.0001,10), 'ro');
                plot(app.UIAxes_mag_vs_freq,x_sim_cm(app.parameters.find_grad_ind2)*ones(1,10), linspace(0,0.0001,10), 'ro');
            end
            catch
                warning('did you change parameters (e.g. frequency range) after running a simulation?');
            end

        end

        function [] = plot_bssfp_vs_freq(app, freq)
            % -- plot the bssfp signal vs the frequency (frequency response
            % profile)
            % -------------------------------------------------------------

            % load simulated magnetization:
            mxy = app.data.mxy_b1_rep;             
            mz = app.data.mz_b1_rep;  

            % load simulated magnetization (inhomoegneois B1):
            mxy_b1 = app.data.mxy_b1_rep;             
            mz_b1 = app.data.mz_b1_rep;  
            
            % B1 simulated range:
            x_sim_cm = linspace(-app.parameters.yrange_cm/2, ...
                                app.parameters.yrange_cm/2, ...
                                app.parameters.yrange_cm_npoints);
            % simulated frequency range:
            freq_range_hz = linspace(app.parameters.freq_hz_low, ...
                                app.parameters.freq_hz_high, ...
                                app.parameters.freq_hz_npoints); % Hz
            freq1_hz = app.Freq1EditField.Value;
            % find freq closest to set frequencies
            [~,freq1_ind] = min(abs(freq_range_hz-freq));
            x_1 = 1;
        
            % empty subplot for new plot:
            cla(app.UIAxes_mag_vs_freq);
            % empty legends string array:
            l = [];
            % if 2 frequencies should be plotted:
            if app.Show2FreqsCheckBox.Value
                freq2_hz = app.Freq2EditField.Value;

                % find freq closest to set frequencies
                [~,freq2_ind] = min(abs(freq_range_hz-freq2_hz));
                
                % inhomogeneous B1
                if (app.parameters.plot_inhomo_mx)
                    plot(app.UIAxes_mag_vs_freq,squeeze(real(mxy_b1(:, x_1, freq1_ind))), 'k.-');
                    plot(app.UIAxes_mag_vs_freq,squeeze(real(mxy_b1(:, x_1, freq2_ind))), 'k.--');
                    l = [l; "Mx (surface)"; ""];
                end
                if (app.parameters.plot_inhomo_my)
                    plot(app.UIAxes_mag_vs_freq,squeeze(imag(mxy_b1(:, x_1, freq1_ind))), 'r-');
                    plot(app.UIAxes_mag_vs_freq,squeeze(imag(mxy_b1(:, x_1, freq2_ind))), 'r--');
                    l = [l; "My (surface)"; ""];
                end
                if (app.parameters.plot_inhomo_mxy)
                    plot(app.UIAxes_mag_vs_freq,squeeze(abs(mxy_b1(:, x_1, freq1_ind))), 'k-');
                    plot(app.UIAxes_mag_vs_freq,squeeze(abs(mxy_b1(:, x_1, freq2_ind))), 'k--');
                    l = [l; "|Mxy| (surface)"; ""];
                end
                if (app.parameters.plot_inhomo_mz)
                    plot(app.UIAxes_mag_vs_freq,squeeze((mz_b1(:, x_1, freq1_ind))), 'b-');
                    plot(app.UIAxes_mag_vs_freq,squeeze((mz_b1(:, x_1, freq2_ind))), 'b--');
                    l = [l; "Mz (surface)"; ""];
                end
                if (app.parameters.plot_inhomo_phase)
                    plot(app.UIAxes_mag_vs_freq,squeeze(angle(mxy_b1(:, x_1, freq1_ind))), 'g-');
                    plot(app.UIAxes_mag_vs_freq,squeeze(angle(mxy_b1(:, x_1, freq2_ind))), 'g--');
                    l = [l; "\phi (surface)"; ""];
                end


                % uniform B1
                if (app.parameters.plot_homo_mx)
                    plot(app.UIAxes_mag_vs_freq,squeeze(real(mxy(:, x_1, freq1_ind))), 'k.-');
                    plot(app.UIAxes_mag_vs_freq,squeeze(real(mxy(:, x_1, freq2_ind))), 'k.--');
                    l = [l; "Mx (original)"; ""];
                end
                if (app.parameters.plot_homo_my)
                    plot(app.UIAxes_mag_vs_freq,squeeze(imag(mxy(:, x_1, freq1_ind))), 'r-');
                    plot(app.UIAxes_mag_vs_freq,squeeze(imag(mxy(:, x_1, freq2_ind))), 'r--');
                    l = [l; "My (original)"; ""];
                end
                if (app.parameters.plot_homo_mxy)
                    plot(app.UIAxes_mag_vs_freq,squeeze(abs(mxy(:, x_1, freq1_ind))), 'b-');
                    plot(app.UIAxes_mag_vs_freq,squeeze(abs(mxy(:, x_1, freq2_ind))), 'b--');
                    l = [l; "|Mxy| (original)"; ""];
                end
                if (app.parameters.plot_homo_mz)
                    plot(app.UIAxes_mag_vs_freq,squeeze((mz(:, x_1, freq1_ind))), 'b-');
                    plot(app.UIAxes_mag_vs_freq,squeeze((mz(:, x_1, freq2_ind))), 'bp--');
                    l = [l; "Mz (original)"; ""];
                end
                if (app.parameters.plot_homo_phase)
                    plot(app.UIAxes_mag_vs_freq,squeeze(angle(mxy(:, x_1, freq1_ind))), 'g-');
                    plot(app.UIAxes_mag_vs_freq,squeeze(angle(mxy(:, x_1, freq2_ind))), 'bh--');
                    l = [l; "\phi (original)"; ""];
                end
                yl = ylim(app.UIAxes_mag_vs_freq);
                hold(app.UIAxes_mag_vs_freq, 'on');
                plot(app.UIAxes_mag_vs_freq, round(app.repSlider.Value)*ones(1,10), linspace(yl(1), yl(2), 10), 'k--');
                l = [l; "repetition"; ""];
                
                app.UIAxes_mag_vs_freq.Title.String = ['f_1 = ' num2str(freq1_hz) 'Hz & f_2 = ' num2str(freq2_hz) 'Hz'];

                ylims = app.UIAxes_anat_image.YLim;
                legend(app.UIAxes_mag_vs_freq, l);
            % plot 1 frequency:
            else
                hold(app.UIAxes_mag_vs_freq, 'on');

                % inhomogeneous B1
                if (app.parameters.plot_inhomo_mx)
                    plot(app.UIAxes_mag_vs_freq,squeeze(real(mxy_b1(:, x_1, freq1_ind))), 'k.-');
                    l = [l; "Mx (surface)"];
                end
                if (app.parameters.plot_inhomo_my)
                    plot(app.UIAxes_mag_vs_freq,squeeze(imag(mxy_b1(:, x_1, freq1_ind))), 'r-');
                    l = [l; "My (surface); "];
                end
                if (app.parameters.plot_inhomo_mxy)
                    plot(app.UIAxes_mag_vs_freq,squeeze(abs(mxy_b1(:, x_1, freq1_ind))), 'k-');
                    l = [l; "|Mxy| (surface)"];
                end
                if (app.parameters.plot_inhomo_mz)
                    plot(app.UIAxes_mag_vs_freq,squeeze((mz_b1(:, x_1, freq1_ind))), 'b-');
                    l = [l; "Mz (surface)"];
                end
                if (app.parameters.plot_inhomo_phase)
                    plot(app.UIAxes_mag_vs_freq,squeeze(angle(mxy_b1(:, x_1, freq1_ind))), 'g-');
                    l = [l; "\phi (surface)"];
                end

                % uniform B1
                if (app.parameters.plot_homo_mx)
                    plot(app.UIAxes_mag_vs_freq,max(squeeze(real(mxy_b1(:, x_1, freq1_ind))))*...
                                                              squeeze(real(mxy(:, x_1, freq1_ind)))/...
                                                          max(squeeze(real(mxy(:, x_1, freq1_ind)))), 'k.-');
                    l = [l; 'Mx (uniform)'];
                end
                if (app.parameters.plot_homo_my)
                    plot(app.UIAxes_mag_vs_freq,max(squeeze(imag(mxy_b1(:, x_1, freq1_ind))))*...
                                                              squeeze(imag(mxy(:, x_1, freq1_ind)))/...
                                                          max(squeeze(imag(mxy(:, x_1, freq1_ind)))), 'r-');
                    l = [l; "Mx (uniform)"];
                end
                if (app.parameters.plot_homo_mxy)
                    plot(app.UIAxes_mag_vs_freq,max(squeeze(abs(mxy_b1(:, x_1, freq1_ind))))*...
                                                              squeeze(abs(mxy(:, x_1, freq1_ind)))/...
                                                          max(squeeze(abs(mxy(:, x_1, freq1_ind)))), 'b-');
                    l = [l; "|Mxy| (uniform)"];
                end
                if (app.parameters.plot_homo_mz)
                    plot(app.UIAxes_mag_vs_freq,squeeze((mz(:, x_1, freq1_ind))), 'b-');
                    l = [l; "Mz (uniform)"];
                end
                if (app.parameters.plot_homo_phase)
                    plot(app.UIAxes_mag_vs_freq,squeeze(angle(mxy(:, x_1, freq1_ind))), 'g-');
                    l = [l; "\phi (uniform)"];
                end
                yl = ylim(app.UIAxes_mag_vs_freq);
                hold(app.UIAxes_mag_vs_freq, 'on');
                plot(app.UIAxes_mag_vs_freq, round(app.repSlider.Value)*ones(1,10), linspace(yl(1), yl(2), 10), 'k--');
                app.UIAxes_mag_vs_freq.Title.String = ['f = ' num2str(freq_range_hz(freq1_ind)) 'Hz'];
                app.UIAxes_mag_vs_freq.XLabel.String = 'Repetitions';
                legend(app.UIAxes_mag_vs_freq, l);
            end
        end

        function [] = plot_bssfp_vs_rep(app, rep, freq)
            % -- plot the frequency response profile at a certain
            % repetition
            % -------------------------------------------------------------
            if (freq == Inf)
                freq= 0 ;
            end

            % get simulated magnetization:
            mxy = app.data.mxy_b1_rep;             
            mz = app.data.mz_b1_rep;  

            % get simulated magnetization (inhomogeneous B1):
            mxy_b1 = app.data.mxy_b1_rep;             
            mz_b1 = app.data.mz_b1_rep;  
            
            % parameters:
            % B1 simulated range:
            x_sim_cm = linspace(-app.parameters.yrange_cm/2, ...
                                app.parameters.yrange_cm/2, ...
                                app.parameters.yrange_cm_npoints);
            % frequency range:
            freq_range_hz = linspace(app.parameters.freq_hz_low, ...
                                app.parameters.freq_hz_high, ...
                                app.parameters.freq_hz_npoints); % Hz
            % empty legends string array:
            l = [];
            x_1 = 1;
            % clear subplot:
            cla(app.UIAxes_anat_image);
            % which options to plot:
            if app.parameters.plot_homo_mx
                plot(app.UIAxes_anat_image, freq_range_hz,squeeze(real(mxy(rep, x_1, :))), 'k.-');
                l = [l; "Mx "];
            end
            if (app.parameters.plot_homo_my)
                plot(app.UIAxes_anat_image, freq_range_hz,squeeze(imag(mxy(rep, x_1, :))), 'r-');
                l = [l; "My "];
            end
            if (app.parameters.plot_homo_mxy)
                plot(app.UIAxes_anat_image, freq_range_hz,squeeze(abs(mxy(rep, x_1, :))), 'b-');
                l = [l; "|Mxy| "];
            end
            if (app.parameters.plot_homo_mz)
                plot(app.UIAxes_anat_image, freq_range_hz,squeeze((mz(rep, x_1, :))), 'b-');
                l = [l; "Mz "];
            end
            if (app.parameters.plot_homo_phase)
                plot(app.UIAxes_anat_image, freq_range_hz,squeeze(angle(mxy(rep, x_1, :))), 'g-');
                l = [l; "\phi"];
            end
            hold(app.UIAxes_anat_image, 'on');
            yl = ylim(app.UIAxes_anat_image);
            plot(app.UIAxes_anat_image, freq * ones(1,10), linspace(yl(1), yl(2), 10), 'k--');
            l = [l; "freq pos"];
            % set title:
            app.UIAxes_anat_image.Title.String = ['rep = ' num2str(rep)];
            app.UIAxes_anat_image.XLabel.String = 'f [Hz]';
            %ylims = app.UIAxes_anat_image.YLim;
            legend(app.UIAxes_anat_image, l); 
        end

        function [] = plot_b1map(app)
            % -- plots the measured & loaded B1 map
            % -------------------------------------------------------------

            disp('');
            cla(app.UIAxes_b1profile);
            
            % to remove white part in image
            app.UIAxes_b1profile.XLim=  [1, size(app.data.refpow_map,1)];
            app.UIAxes_b1profile.YLim=  [1, size(app.data.refpow_map,2)];
            % plot image
            imagesc(app.UIAxes_b1profile, app.data.refpow_map(:,:,1));

            % set ticks + labels:
            sz = size(app.data.refpow_map);
            res = app.parameters.refpow_map_fov_mm ./ sz;

            xtick = linspace(1,size(app.data.refpow_map,1),5);
            ytick = linspace(1,size(app.data.refpow_map,2),5);

            xticklabel = linspace(-app.parameters.refpow_map_fov_mm(2)/2 + res(2)/2, ...
                                   app.parameters.refpow_map_fov_mm(2)/2 - res(2)/2, 5);
            yticklabel = linspace(-app.parameters.refpow_map_fov_mm(1)/2 + res(1)/2, ...
                                   app.parameters.refpow_map_fov_mm(1)/2 - res(1)/2, 5);

            yticklabel = yticklabel  - app.parameters.refpow_map_offset_mm(1);
            xticklabel = xticklabel  - app.parameters.refpow_map_offset_mm(2); %anat_image_offset_mm(1);

            app.UIAxes_b1profile.XTick = xtick;
            app.UIAxes_b1profile.YTick = ytick;

            app.UIAxes_b1profile.XTickLabel = xticklabel;
            app.UIAxes_b1profile.YTickLabel = yticklabel;
            app.UIAxes_b1profile.XLabel.String = 'x [mm]';
            app.UIAxes_b1profile.YLabel.String = 'y [mm]';

            % keep image at correct ratio
            pbaspect(app.UIAxes_b1profile, [size(app.data.refpow_map,1) size(app.data.refpow_map,2) 1]);
            
            % plot point where ref pow sould be calulated:
            hold(app.UIAxes_b1profile, 'on');
            
            % calc position of voxel (in mm) in voxels:  matrix_size, fov, x_pos_mm, y_pos_mm, offset_mm)
            [x_pos, y_pos] = calc_mmpos_in_voxpos(app, ...
                                    size(app.data.refpow_map), ...
                                    app.parameters.refpow_map_fov_mm, ...
                                    app.parameters.refpow_pos_x_mm, ...
                                    app.parameters.refpow_pos_y_mm, ...
                                    app.parameters.refpow_map_offset_mm);
            warning('this has to be optimized!, dont trust the positions!');
            
            % plot result
            plot(app.UIAxes_b1profile, x_pos, y_pos, 'rx');

            % add colorbar
            colorbar(app.UIAxes_b1profile);
            app.refpow_map_caxis.Visible = 'on';
            app.refpow_map_caxis.Limits = [0 10];

            if (app.refpow_map_caxis.Value < 1e-6)
                app.refpow_map_caxis.Value = 1e-6;
            end
            caxis(app.UIAxes_b1profile, [0 app.refpow_map_caxis.Value]);
            
            % Grid
            grid1 = [xtick;xtick];
            grid2 = repmat([ytick(1);ytick(end)], 1, length(xtick));
            plot(app.UIAxes_b1profile, grid1, grid2, 'w-', 'LineWidth', 0.025);
            plot(app.UIAxes_b1profile, grid2, grid1, 'w-', 'LineWidth', 0.025);

        end

        function [] = plot_bssfp_signal(app)
            % -- plot the simulated bssfp signal in an extra figure:
            % -------------------------------------------------------------

            figure()
            subplot(2,3,1);
            imagesc(squeeze(real(app.data.mxy_b1_rep(:,:,round(end/2)))));
            title(['real - f = ' num2str(app.parameters.freq_hz_range(round(end/2))) 'Hz']);
            colorbar();

            subplot(2,3,2);
            plot(app.parameters.freq_hz_range, abs(squeeze((app.data.mxy_b1_rep(end,round(end/2),:)))));
            title(['spec - y = ' num2str(app.parameters.x_sim(round(end/2))) 'cm']);
            xlabel('f [Hz]')
            
            subplot(2,3,3);
            imagesc(squeeze(abs(app.data.mxy_b1_rep(:,:,round(end/2)))));
            title(['mag - f = ' num2str(app.parameters.freq_hz_range(round(end/2))) 'Hz']);
            colorbar();

            subplot(2,3,4);
            plot(squeeze(real(app.data.mxy_b1_rep(:,round(end/2),round(end/2)))));
            hold on;
            plot(squeeze(imag(app.data.mxy_b1_rep(:,round(end/2),round(end/2)))));
            plot(squeeze(abs(app.data.mxy_b1_rep(:,round(end/2),round(end/2)))));
            title(['f = ' num2str(app.parameters.freq_hz_range(round(end/2))) 'Hz']);
            xlabel('Repetitions');

            subplot(2,3,5);
            plot((app.parameters.repetitions_time_ms:app.parameters.repetitions_time_ms:app.parameters.repetitions_time_ms*numel(squeeze(app.data.mz_b1_rep(:,round(end/2),round(end/2)))))*1e-3, ...
                squeeze((app.data.mz_b1_rep(:,round(end/2),round(end/2)))));
            title(['f = ' num2str(app.parameters.freq_hz_range(round(end/2))) 'Hz']);
            xlabel('t [s]');

            subplot(2,3,6);
            plot((app.parameters.dt_sim_short:app.parameters.dt_sim_short:app.parameters.dt_sim_short*numel(app.parameters.grad_range_sim_short_G_cm))*1e-3, ...
                app.parameters.grad_range_sim_short_G_cm);
            hold on;
            plot((app.parameters.dt_sim_short:app.parameters.dt_sim_short:app.parameters.dt_sim_short*numel(app.parameters.grad_range_sim_short_G_cm))*1e-3, ...
                abs(app.parameters.pulse_sim_short));
            title('pulse sequence');
            xlabel('t [s]');
        end

        function [] = find_gradient_strength(app)
            % -- This function runs a bloch simulation of a rf pulse that
            % is played on an inhomogeneous B1 field (such as a surface coil
            % while a gradient is active. When choosing a proper pulse
            % shape and gradient strength, the inhomogeneous B1 field can
            % be compensated (see also ISMRM 2022 2933, luca nagel:
            % B1-Compensating RF Excitation for Imaging hyperpolarized
            % 13C nuclei with bSSFP using a 13C Cryo-Coil)
            % -------------------------------------------------------------

            % Range of gradient strengths over which the bloch simulations
            % will be performed:
            grad_kHz_cm_list = linspace(app.parameters.find_grad_low, ...
                                        app.parameters.find_grad_high, ...
                                        app.parameters.find_grad_npoints);
            
            khz_in_g = app.parameters.gamma / 1000;
            grad_G_cm_list = grad_kHz_cm_list / khz_in_g;  
           
            % range (space) to simulate over:
            range_cm_sim = app.parameters.yrange_cm; % 4 cm
            % pulse duration:
            Trf = app.parameters.pulse_duration_ms;
            % time resolution
            dt_sim = app.parameters.pulse_duration_ms / app.parameters.pulse_duration_ms_npoints;
            
            % pulse duration = slice selective gradient duration
            grad_on_points = round((Trf / dt_sim));
            % fill with ones (amplitude will come later)
            grad_kHz_cm_range_sim = ones(grad_on_points, 1);
            % rephasing gradients on
            grad_kHz_cm_range_sim = [grad_kHz_cm_range_sim.' -grad_kHz_cm_range_sim.'/2 zeros(1, 1000)]; 
            grad_kHz_cm_range_sim = grad_kHz_cm_range_sim .';
            
            
            % spsace over which the B1 map was acquired (hardcoded so far):
            warning('Carefull, make sure the B1 Map was actually acquired over this range!');
            x_b1 = linspace(0.25, 31.75, 64)/10;
            % bring around center:
            x_b1 = x_b1 - 1.6;

            % desired simulation range:
            x_sim = linspace(-range_cm_sim/2, range_cm_sim/2, app.parameters.yrange_cm_npoints);
            
            % Fitted B1 profile (lin)
            b1profile_log = app.data.b1TransmitProfileFit_log;
            
            % Interpolated fit B1 profile onto desired x range:
            b1Transmit_sim_log = interp1(x_b1, b1profile_log, x_sim, 'spline', 'extrap');
            % take the exp of the lin --> actual b1 profile
            b1Transmit_sim = exp(b1Transmit_sim_log) / max(exp(b1Transmit_sim_log ));
            
            % simulation time:
            t_rf_sim = 0:dt_sim:Trf-dt_sim;
            
            
            % pulse stuff
            pulse = app.data.rfpulse;
            % flipangle:
            alpha = app.parameters.alpha;
            % integration factor
            sint = app.parameters.sint;
            % gyromagnetic ratio <--> nucleus:
            gamma = app.parameters.gamma;
            
            % time resolution of loaded RF pulse:
            dt_pulse = Trf / numel(pulse(:,1));
            % generate time range of loaded pulse:
            t_b1 = 0:dt_pulse:Trf-dt_pulse;
            % interpolate loaded pulse onto desired simualteion range:
            pulse_sim = interp1(t_b1, pulse.', t_rf_sim);
            
            
            khz_in_g = gamma / 1000;
            b1_amp_khz  = app.parameters.b1_amp_khz;
            b1_amp_G = b1_amp_khz / khz_in_g;
            
            % get pulse in Gauss
            pulse_sim = pulse_sim * b1_amp_G;
            pulse_sim = [pulse_sim zeros(1, numel(pulse_sim)+1000)];
            pulse_sim = pulse_sim .';
            
            % parameters :
            freq = app.parameters.freq_hz_range;
            T1 = app.parameters.T1;
            T2 = app.parameters.T2;
            
            % interpolate the pulse onto the to be simulated time
            pulse_sim_short = interp1(linspace(0,1,numel(pulse_sim)), pulse_sim, linspace(0,1,1000));
            % interpolate the gradient onto the to be simulated time
            grad_kHz_cm_range_sim_short = interp1(linspace(0,1,numel(pulse_sim)), grad_kHz_cm_range_sim, linspace(0,1,1000));
            dt_sim_short = numel(pulse_sim) / 1000 * dt_sim;

            app.find_GradButton.Text = 'searching for best Grad!';
            app.find_GradButton.FontAngle = 'italic';
            app.find_GradButton.BackgroundColor = [0.00,0.00,1.00];

            pause(0.000001);
            
            % init empty array
            mxy_x = zeros(numel(grad_G_cm_list), numel(x_sim), numel(pulse_sim_short), numel(freq));
            mz_x = zeros(numel(grad_G_cm_list), numel(x_sim), numel(pulse_sim_short), numel(freq));
            gc = 0;
            % 13C
            if sum(app.CButton.Value) % 
                % different gradient strengths:
                for g = grad_G_cm_list
                    gc = gc+1;
                    % because of the inhomogeneous B1, the simulation has
                    % to be done for each point in space individually
                    % because each point has a different B1
                    for i = 1:numel(x_sim)
                        [mxx, myx, mzx] = bloch(pulse_sim_short * b1Transmit_sim(i), g* grad_kHz_cm_range_sim_short, dt_sim_short/1000, T1, T2, freq, x_sim(i), 2);
                        mxy_x(gc, i, :, :) = mxx + 1i* myx;
                        mz_x(gc, i, :, :) = mzx;
                    end
                    % necessary for updating the Label
                    pause(0.00000001);
                    app.progress_grad_findLabel.Text = [num2str(gc) '/' num2str(numel(grad_G_cm_list))];
                end
            % proton
            else
                % different gradient strengths:
                for g = grad_G_cm_list
                    gc = gc+1;
                    % because of the inhomogeneous B1, the simulation has
                    % to be done for each point in space individually
                    % because each point has a different B1
                    for i = 1:numel(x_sim)
                        [mxx, myx, mzx] = bloch(pulse_sim_short * b1Transmit_sim(i), g* grad_kHz_cm_range_sim_short, dt_sim_short/1000, T1, T2, freq, x_sim(i), 2);
                        mxy_x(gc, i, :, :) = mxx + 1i* myx;
                        mz_x(gc, i, :, :) = mzx;
                    end
                    % necessary for updating the Label
                    pause(0.00000001);
                    app.progress_grad_findLabel.Text = [num2str(gc) '/' num2str(numel(grad_G_cm_list))];
                end
            end
            app.find_GradButton.Text = 'find Grad!';
            app.find_GradButton.FontAngle = 'normal';
            app.find_GradButton.BackgroundColor = [0.96,0.96,0.96];
            
            % plot simulation results:
            figure();
            subplot(2,1,1);
            imagesc(squeeze(abs(mxy_x(:, :,end,round(end/2)))));
            hold on;
            plot(app.parameters.find_grad_ind1 * ones(1, numel(grad_G_cm_list)),  (1:numel(grad_G_cm_list)),'r-')
            plot(app.parameters.find_grad_ind2 * ones(1, numel(grad_G_cm_list)),  (1:numel(grad_G_cm_list)),'r-')

            % find the value where the set plateau range is the most
            % homogeneous. This means that the signal is constant over a
            % certain range which means that the B1 was compensated and the
            % excitation is the same over the "plateau" range
            find_plat = squeeze(mxy_x(:, app.parameters.find_grad_ind1:app.parameters.find_grad_ind2, ...
                end, ... % end of RF Pulse (time)
                round(end/2))); % frequency in center of frequency range (should be 0Hz)
            % init empty array:
            find_plat_diff = zeros(numel(grad_G_cm_list), numel(app.parameters.find_grad_ind1:app.parameters.find_grad_ind2) - 1);
            % calculate the difference in signal along the simulated y-range
            for i = 1:numel(grad_G_cm_list)
               find_plat_diff(i, :) = diff(squeeze(abs(find_plat(i, :))));
            end
            % sum up the difference:
            find_plat_diff = sum(find_plat_diff, 2);
            % find the repetition where the difference aling the plateau is
            % the smallest --> most homogeneous excitation:
            [~, find_plat_min] = min(abs(find_plat_diff));
            % calculate which gradient corresponds to the best B1
            % compensation:
            grad_G_cm_best = grad_G_cm_list(find_plat_min);
            grad_kHz_cm_best = grad_kHz_cm_list(find_plat_min);

            % plot results:
            subplot(2,1,2);
            plot(grad_kHz_cm_list, abs(find_plat_diff));
            xlabel('kHz/cm')
            title(['Best Grad: ' num2str(grad_G_cm_best) 'G/cm -- ' num2str(grad_kHz_cm_best) 'kHz/cm']);
        
            app.BestGradEditField.Value = grad_kHz_cm_best;   
        end
        

        function [] = simulate_repetitions(app)
            % -- Simulate repeated excitation (~bssfp/fisp)
            % -------------------------------------------------------------

%             try
            % simulation parameters:
            dt_sim = app.parameters.pulse_duration_ms_res*1e-3;
            trf = app.parameters.pulse_duration_ms*1e-3;
            trep = app.parameters.repetitions_time_ms * 1e-3;
            tref = (trep - trf) / 2;
            trep_npoints = app.parameters.repetitions_time_ms_npoints;
            N = app.parameters.nrepetitions;
            T1= app.parameters.T1;
            T2= app.parameters.T2;

            % calculate how many additional round (preparation pulse,
            % tipback, spoiling) need to be simulated:
            nadd = 0;
            % preparation pulse:
            if (app.parameters.first_pulse_amp ~= 1)
                nadd = nadd + 1;
            end
            % tipback
            if strcmp((app.parameters.tipback_pulse), "on")
                nadd = nadd + 1;
            end
            % spoiler at end of repetition
            if app.parameters.spoiler_grad
                nadd = nadd + 1;
            end
            N = N + nadd;
            app.parameters.Nsims = N;
        
            %% GRADIENT STUFF
            % number of points
            dt_sim_rep = trep / trep_npoints;
            trep_npoints_new = trep_npoints;
            dt_sim_rep_new = trep / trep_npoints_new ;

            % the following part is supposed to set the amount of points so
            % that the gradients and pulses all fit into the arrays
            if (tref > 0)
                % init wrong amount of leftover points
                grad_off_points = 1;
                while grad_off_points ~= 0
                    % number of pointer the pulse is playing ("excitation gradient"):
                    grad_exc_points = round(trf/dt_sim_rep_new );
                    % number of points the refocus gradients are on:
                    grad_ref_points = round(tref/dt_sim_rep_new );
                    % number of point nothing is on:
                    grad_off_points = round(trep/dt_sim_rep_new ) - grad_exc_points - 2*grad_ref_points;
                    fprintf("tref %d \n", tref);
                    fprintf("grad_off_points %d \n", grad_off_points);
                    fprintf("round(trep/dt_sim_rep_new ) %d \n", round(trep/dt_sim_rep_new ));
                    fprintf("grad_exc_points %d \n", grad_exc_points);
                    fprintf("2*grad_ref_points %d \n", 2*grad_ref_points);

                    % nubmer of points any gradient is on:
                    grad_on_points = grad_exc_points + 2 * grad_ref_points;

                    trep_npoints_new  = trep_npoints_new - abs(grad_off_points);
                    dt_sim_rep_new = trep / trep_npoints_new ;
                end
            end

            trep_npoints = trep_npoints_new;
            dt_sim_rep = trep / trep_npoints ;

            app.parameters.repetitions_time_ms_npoints = trep_npoints;
            app.repetition_time_npointsEditField.Value = trep_npoints
                

            % if the duration of the rephasing gradient lobe is defined:
            if (tref > 0)
                % number of pointer the pulse is playing ("excitation gradient"):
                grad_exc_points = round(trf/dt_sim_rep);
                % number of points the refocus gradients are on:
                grad_ref_points = round(tref/dt_sim_rep);
                % number of point nothing is on:
                grad_off_points = round(trep/dt_sim_rep) - grad_exc_points - 2*grad_ref_points;
                % nubmer of points any gradient is on:
                grad_on_points = grad_exc_points + 2 * grad_ref_points;

                % to calculate the strength of the refocusing gradients:
                grad_exc_area = grad_exc_points;
                grad_ref_area = grad_ref_points;
                grad_reph_amp = grad_exc_area / grad_ref_area /2 ;
    
                % init empty array
                grad_ss_sim = zeros(1, trep_npoints);
                % fill the refocusing gradient (first half)
                grad_ss_sim(1:round(grad_ref_points)) = ...
                -grad_reph_amp*ones(1, grad_ref_points);
                % fill the excitation gradient
                grad_ss_sim(round(grad_ref_points)+1:...
                            round(grad_ref_points)+grad_exc_points) = ...
                            1*ones(1, grad_exc_points);
                % fill the refocusing gradient (second half)
                grad_ss_sim(grad_ref_points+grad_exc_points +1 : ...
                            grad_ref_points+grad_exc_points +grad_ref_points) = ...
                            -grad_reph_amp*ones(1, grad_ref_points);
            else
                % points of RF pulse
                grad_exc_points = round(trf/dt_sim_rep);
                % refocusing points (1 lobe)
                grad_ref_points = round((trep_npoints - grad_exc_points) /2);
                grad_off_points = grad_ref_points ;
                % ratio:
                amp_reph =  grad_exc_points / 2 / grad_ref_points ;

                grad_ss_sim = zeros(trep_npoints, 1);
                grad_ss_sim(1:grad_ref_points) = -amp_reph;
                grad_ss_sim(grad_ref_points+1:grad_ref_points+grad_exc_points) = 1;
                grad_ss_sim(grad_ref_points+grad_exc_points+1:end) = -amp_reph;
            end

            % test if the gradient are balanced (=integral has to end at 0):
            grad_sum = sum(grad_ss_sim);
            if (abs(grad_sum) > 0)
                warning(['gradients not balanced: Sum = ' num2str(grad_sum)]);
                % for now continue anyway
                grad_sim = grad_ss_sim;
            else
                grad_sim = grad_ss_sim;
            end


            %% PULSE STUFF
            pulse = app.data.rfpulse;
            alpha = app.parameters.alpha;
            sint = app.parameters.sint;
            gamma = app.parameters.gamma;
            
            dt_pulse = trf / numel(pulse(:,1));
            
            % pulse timing (loaded pulse size scaled to rf pulse duration)
            t_b1 = 0:dt_pulse:trf-dt_pulse;
            % goal of pulse time points
            t_rf_sim = 0:dt_sim_rep:trf-dt_sim_rep;

            pulse_sim = interp1(t_b1, pulse.', t_rf_sim);

            % if there a refocussing gradient is to be simulated:
            if (tref > 0)
                pulse_range_sim = zeros(size(grad_sim));
                pulse_range_sim((grad_ref_points+2):(grad_ref_points+grad_exc_points)) = pulse_sim;
                
            else
                pulse_range_sim = zeros(size(grad_sim));
                pulse_range_sim(grad_ref_points+1:grad_ref_points+grad_exc_points) = pulse_sim;
            end

            %% REDUCE NUMBER OF POINTS TO SPEED UP SIMULATION:
            pulse_sim_short = interp1(linspace(0,1,numel(pulse_range_sim)), pulse_range_sim, linspace(0,1,trep_npoints));
            grad_sim_short = interp1(linspace(0,1,numel(grad_sim)), grad_sim, linspace(0,1,trep_npoints));
            dt_sim_short = trep / numel(grad_sim_short);

            % RF amplitude:
            khz_in_g = gamma / 1000;
            b1_amp_khz  = app.parameters.b1_amp_khz;
            b1_amp_G = b1_amp_khz / khz_in_g;
           
            % scale gradients:
            grad_amp_kHz_cm = app.parameters.gradient_kHz_cm;
            grad_amp_G_cm  = grad_amp_kHz_cm / khz_in_g;
            grad_range_sim_short_G_cm = grad_sim_short * grad_amp_G_cm;
           
            %% RUN SIMULATION
            % check how many points along y should be simulated:
            if (app.parameters.yrange_cm_npoints > 1)
                x_sim = linspace(-app.parameters.yrange_cm/2, ...
                                    app.parameters.yrange_cm/2, ...
                                    app.parameters.yrange_cm_npoints);
            else
                x_sim = app.parameters.yrange_cm;
            end
            
            % frequency range over which we want to simulate
            freq = linspace(app.parameters.freq_hz_low, ...
                            app.parameters.freq_hz_high, ...
                            app.parameters.freq_hz_npoints); 
            
            % frequency offset of the 1st pulse
            freq_offset_1stpulse = app.parameters.freq_1stfreq_hz;

            % frequenct offset of the 2nd pulse:
            freq_offset_2ndpulse = app.parameters.freq_2ndfreq_hz;
            % how often one alternates between 2 frequencies
            n_alternations = app.parameters.alternate_freq_Ntimes_2nd_freq;

            % check if high res sim. data sould be stored + init empty array:
            if strcmp(app.parameters.save_all_sim_data, 'on')
                mx_x_b1 = zeros(N*n_alternations, numel(x_sim), numel(pulse_sim_short), numel(freq));
                my_x_b1 = zeros(N*n_alternations, numel(x_sim), numel(pulse_sim_short), numel(freq));
                mz_x_b1 = app.parameters.sample_mag * ones(N*n_alternations, numel(x_sim), numel(pulse_sim_short), numel(freq));
            else
                mx_x_b1 = zeros(N*n_alternations, numel(x_sim), numel(freq));
                my_x_b1 = zeros(N*n_alternations, numel(x_sim), numel(freq));
                mz_x_b1 = app.parameters.sample_mag * ones(N*n_alternations, numel(x_sim), numel(freq));
            end
            
            % check if 2nd pulse (can be different frequency and different
            % flipangle) is played):
            if strcmp(app.parameters.simulate_alternating_freqs, 'on')
                % frequency offset:
                freqoff = repmat([freq_offset_1stpulse freq_offset_2ndpulse], [1 n_alternations]);
                % different B1 (flipangle)
                b1_amp_G_2ndfreq = (pi * app.parameters.alpha_2ndfreq / 180 ) * 1/((2*pi*gamma*1e4) * trf* sint) * 1e4;% G
                b1_amp_G = repmat([b1_amp_G b1_amp_G_2ndfreq ], [1 n_alternations]);
            else
                freqoff = freq_offset_1stpulse;
            end
            % prepare the B1 map:
            b1Transmit_sim_log = interp1(linspace(0.25, 31.75, 64)/10 - 1.6, ...
                                app.data.b1TransmitProfileFit_log, ...
                                x_sim, "spline", 'extrap');
            b1Transmit_sim_log(isnan(b1Transmit_sim_log)) = 0;
            b1Transmit_sim = exp(b1Transmit_sim_log) / max(exp(b1Transmit_sim_log));
            
            % interface stuff
            app.SimulateRepsButton.Text = 'simu. reptd. excts!';
            app.SimulateRepsButton.FontAngle = 'italic';
            app.SimulateRepsButton.BackgroundColor = [0.02,0.67,0.01];

            pause(0.00000001);
%             catch
%                 app.repetition_time_npointsEditField.BackgroundColor = 'r';
%                 warning('probably wrong amount of points, try even number of point.');
%                 return;
%             end

            % alternating phase from 1st to second RF Pulse
            phase_diff_pulses = app.parameters.phase_pulse_deg;
            phase_diff_pulses = repmat([0 phase_diff_pulses], [1 n_alternations]);
            % alternating phase between N Repetitions with one frequency
            phase_2nd_pulse =  app.parameters.alternating_phase;
            phase_2nd_pulse = repmat([0 phase_2nd_pulse], [1 n_alternations*N]);
            % factor of alpha prep pulse
            first_pulse_amp = app.parameters.first_pulse_amp;
            % calc the first pulse flipangle (degree)
            fa_first_pulse = repmat(first_pulse_amp * [alpha app.parameters.alpha_2ndfreq], [1 n_alternations]);
            % calc B1 of first pulse:
            results = calc_b1(app, fa_first_pulse);
            % calc ratio of B1 of first pulse to the following pulses:
            if sum(b1_amp_G)>0
                first_pulse_amp = results.b1_amp_g ./ b1_amp_G;
            else
                % to avoid NaNs
                first_pulse_amp = results.b1_amp_g;
            end

%             if app.parameters.alternating_phase
            % calculate the tipbackpulse:
            tipback_pulse_phase=0;
            if strcmp((app.parameters.tipback_pulse), "on")
                tipback_pulse_amp = 0.5;
                tipback_pulse_phase = app.parameters.phase_tipback_pulse;
                %tipback_pulse_amp = 0.3882; % in case of fa = 4 degree and 
                % effective flipangle = 3.1055 @ f=245 (pyruvate)
            end

            % if first pulse amplitude should be played after each
            % repetition
            if app.parameters.first_pulse_always
                first_pulse_amp_nreps = 1; % // divide first amp by 1 --> keeps first amp for the follwing 
                % first pulses
            else
                first_pulse_amp_nreps = first_pulse_amp(1); % // divide first amp by the first amp factor -->
                % following first pulses have alpha amplitude
                if (first_pulse_amp_nreps == 0)
                    first_pulse_amp_nreps = 1;
                end
            end

            % if only 1 point in space is to be simulated there is no speed
            % gain for using parallel computing
            if (numel(x_sim) == 1)
                n_par_workers = 0;
            else
                p = gcp();
                if isempty(p)
                    n_par_workers = 0;
                else
                    n_par_workers = p.NumWorkers;
                end
            end

            % output simulation information:
            try
                disp(['Running Simulation; expected size:']);
                ByteSize(mx_x_b1)
                % https://stackoverflow.com/questions/4845561/how-to-know-the-size-of-a-variable-in-matlab
            end
            disp(['First TR / TR: ' num2str(app.parameters.first_repetition_time_ms) 'ms / ' num2str(trep*1e3) 'ms']);
            disp(['TRF: ' num2str(trf*1e3) 'ms']);
            disp(['NR per pulse: ' num2str(N)]);
            disp(['# alternations between pulses: ' num2str(n_alternations)]);
            disp(['Excitation frequencies: ' num2str(freqoff) 'Hz']);
            disp(['Phase between 2 diff freq. pulses: ' num2str(phase_2nd_pulse) '°']);
            disp(['Excitation amplitudes: ' num2str(b1_amp_G) 'G']);
            disp(['First Excitation amplitude: ' num2str(first_pulse_amp*100) '%']);
            disp(['Flipangles: ' num2str(app.parameters.alpha) '° / '  num2str(app.parameters.alpha_2ndfreq) '°']);
            disp(['Gradient Strength: ' num2str(grad_amp_G_cm) 'G/cm']);
            disp(['Polarization: ' num2str(app.parameters.sample_mag)]);
            disp(['T1 / T2: ' num2str(T1) ' / ' num2str(T2) 's']);
            try
                disp(['Simulated range / res: ' num2str(abs(x_sim(end)-x_sim(1))) 'cm / ' num2str(abs(x_sim(2)-x_sim(1))) 'cm']);
            catch
                disp(['Simulated range / res: ' num2str(abs(x_sim)) 'cm / ' num2str(abs(x_sim)) 'cm']);
            end
            disp(['Simulated freq. range / res: ' num2str(abs(freq(end)-freq(1))) 'Hz / ' num2str(abs(freq(2)-freq(1))) 'Hz']);

        % check if the RF pulse contains too many NaN (can happen during
        % the interpolation)
        if (sum(isnan(pulse_sim_short)) > 20)
            warning('more than 20 NaN in pulse_sim_short');
            figure()
            plot(abs(pulse_sim_short));
            title(['pulse_sim_short with ' num2str(sum(isnan(pulse_sim_short))) ' NaNs']);
        end
        % replace NaNs with 0s
        pulse_sim_short(isnan(pulse_sim_short)) = 0;
        % init time measurement
        tic
    phase_2nd_pulse = [phase_2nd_pulse];
    % same repetition time i.e. TR-TR-TR bssfp:
    if (app.parameters.first_repetition_time_ms == ...
        app.parameters.repetitions_time_ms)
        ir = 0;
        % n_alternations means how many times one simulates an alternation 
        % between 2 RF pulses
        for i_pulse = 1:n_alternations 
            % get the RF amplitude of that RF Pulse:
            pulse_amp = b1_amp_G(i_pulse);
            % get the fequency offset of that RF Pulse:
            freq_offset = freqoff(i_pulse);
            % get the RF amplitude of the very first RF Pulse:
            fpamp = first_pulse_amp(i_pulse);
            % repetitions per excitation frequency:
            for i_rep = 1:N
                ir=ir+1;
                phase_rep = phase_2nd_pulse(i_rep) + phase_diff_pulses(i_pulse);
                % very first pulse:
                if ((i_rep == 1) && (i_pulse == 1))
                    v1 = squeeze(mx_x_b1(1, :, :));
                    v2 = squeeze(my_x_b1(1, :, :));
                    v3 = squeeze(mz_x_b1(1, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                    % Explanation:
                    %  
                    % [mxx, myx, mzx] = //output         
                    % bloch // mex file (compiled .c)
                    % (first_pulse_amp * ... // amplitude  of first pulse
                    % (exp(2*pi*1i*ones(1,numel(pulse_sim_short )) .* ir*phase /360)) .* ...// phase of pulse (might vary) (was removed)
                    % pulse_sim_short * ... // pulse shape
                    % b1Transmit_sim(i) .*... // B1 field of coil (depends on x = distance to coil, magnitude = 1)
                    % exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) ... // frequency offset of pulse
                    % * pulse_amp .* // pulse amplitude (kHz)
                    % exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * phase_rep/360), ... // phase of pulse
                    % grad_range_sim_short_G_cm, ... // Gradients
                    % dt_sim_short // time resolution
                    % , T1, T2, freq, x_sim(i), 2, ... // should be clear
                    % v1(i, :), ... // magnetization mx from previous excitation
                    % v2(i, :), ... // magnetization my from previous excitation
                    % v3(i, :));    // magnetization mz from previous excitation
                        [mxx, myx, mzx] = bloch(fpamp .* pulse_sim_short * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) *...
                                                    pulse_amp .* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * phase_rep/360), ...
                                                    grad_range_sim_short_G_cm, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end
                % first pulse after alternating frequency (starts with
                % a different pulse amplitude (first_pulse_amp))
                elseif ((i_rep == 1) && (i_pulse ~= 1))
                    v1 = squeeze(mx_x_b1((i_pulse-1)*N, :, :));
                    v2 = squeeze(my_x_b1((i_pulse-1)*N, :, :));
                    v3 = squeeze(mz_x_b1((i_pulse-1)*N, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(fpamp / first_pulse_amp_nreps .* pulse_sim_short * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp .* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * phase_rep/360), ...
                                                    grad_range_sim_short_G_cm, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end

                % 2nd last pulse (if a tipback is requested and spoiler )
                elseif ((i_rep == N-1) && strcmp((app.parameters.tipback_pulse), "on") && app.parameters.spoiler_grad)
                    v1 = squeeze(mx_x_b1(ir-1, :, :));
                    v2 = squeeze(my_x_b1(ir-1, :, :));
                    v3 = squeeze(mz_x_b1(ir-1, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(tipback_pulse_amp  .* pulse_sim_short * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp .* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * (tipback_pulse_phase+ phase_rep)/360), ...
                                                    grad_range_sim_short_G_cm, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end

                % last pulse (if a tipback is requested and no spoiler )
                elseif ((i_rep == N) && strcmp((app.parameters.tipback_pulse), "on") && ~app.parameters.spoiler_grad)
                    v1 = squeeze(mx_x_b1(ir-1, :, :));
                    v2 = squeeze(my_x_b1(ir-1, :, :));
                    v3 = squeeze(mz_x_b1(ir-1, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(tipback_pulse_amp  .* pulse_sim_short * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp .* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * (tipback_pulse_phase+ phase_rep)/360), ...
                                                    grad_range_sim_short_G_cm, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end


                % was tipped back and will be spoiled --> dont pulse, just
                % use previously simulated data (mx & will be set to 0)
                elseif ((i_rep == N) && strcmp((app.parameters.tipback_pulse), "on") &&(app.parameters.spoiler_grad))
                    a = squeeze(mx_x_b1(ir-1, :, :));
                    b = squeeze(my_x_b1(ir-1, :, :));
                    c = squeeze(mz_x_b1(ir-1, :, :));

                % all other repetitions
                else
                    v1 = squeeze(mx_x_b1(ir-1, :, :));
                    v2 = squeeze(my_x_b1(ir-1, :, :));
                    v3 = squeeze(mz_x_b1(ir-1, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end

                    parfor (k = 1:numel(x_sim), n_par_workers)
                       [mxx, myx, mzx] = bloch(pulse_sim_short * b1Transmit_sim(k) .*...
                                                   exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                   pulse_amp .* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * phase_rep/360), ...
                                                   grad_range_sim_short_G_cm, ...
                                                   dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                   v1(k, :), ...
                                                   v2(k, :), ...
                                                   v3(k, :));
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);
                    end


                end
                mx_x_b1(ir, :, :) = a;
                my_x_b1(ir, :, :) = b;
                mz_x_b1(ir, :, :) = c;
                
                %                         progress_simRep(app, ir, N);
                app.progress_sim_repLabel.Text = [num2str(ir) '/' num2str(N*n_alternations)];
                pause(0.000000000001);
            end
            % "Spoil mxy magnetization"
            if (app.parameters.spoiler_grad)
                mx_x_b1(ir, :, :) = zeros(size(a));
                my_x_b1(ir, :, :) = zeros(size(a));
            end
        end

    % different repetition time (first vs following), eg TR/2-TR-TR
    else
        % this makes the calculation of the positioning of the first and
        % second RF Pulse a little more complicated
        first_trep = app.parameters.first_repetition_time_ms;
        first_trep_npoints = first_trep * 1e-3/ dt_sim_rep;
        diff_points = round((trep - first_trep*1e-3) / dt_sim_rep);
    
        %% pulse:
        first_pulse = zeros(1, 2*trep_npoints);
        first_pulse(1:trep_npoints) = pulse_sim_short;
        % give first pulse amplitude here:
        first_pulse = first_pulse * first_pulse_amp(1); % might not be 100% precise
        
        first_pulse_2ndpulse = zeros(1, 2*trep_npoints);
        first_pulse_2ndpulse(trep_npoints+1:end) = pulse_sim_short;
    
        first_pulse = circshift(first_pulse, diff_points);
        first_pulse  = first_pulse + first_pulse_2ndpulse;
        %% gradient:
        first_grad = zeros(1, 2*trep_npoints);
        first_grad(1:trep_npoints) = grad_range_sim_short_G_cm;
        
        first_grad_2ndpulse = zeros(1, 2*trep_npoints);
        first_grad_2ndpulse(trep_npoints+1:end) = grad_range_sim_short_G_cm;
    
        first_grad = circshift(first_grad, diff_points);
        first_grad  = first_grad + first_grad_2ndpulse;
    
    
        %% split:
        second_pulse = first_pulse(trep_npoints+1:end);
        first_pulse = first_pulse(1:trep_npoints);
    
        second_grad = first_grad(trep_npoints+1:end);
        first_grad = first_grad(1:trep_npoints);
    
        % check if there are too many NaNs (20 is arbitrary)
        if (sum(isnan(pulse_sim_short)) > 20)
            warning('more than 20 NaN in pulse_sim_short');
                figure()
                plot(abs(pulse_sim_short));
                title(['pulse_sim_short with ' num2str(sum(isnan(pulse_sim_short))) ' NaNs']);
        end
        % replace the NaNs with 0s:
        pulse_sim_short(isnan(pulse_sim_short)) = 0;
         if (sum(isnan(first_pulse)) > 20)
            warning('more than 20 NaN in first_pulse');
                figure()
                plot(abs(first_pulse));
                title(['first_pulse with ' num2str(sum(isnan(first_pulse))) ' NaNs']);
         end
         % replace the NaNs with 0s:
        first_pulse(isnan(first_pulse)) = 0;
        if (sum(isnan(second_pulse)) > 20)
            warning('more than 20 NaN in second pulse');
            figure()
            plot(abs(second_pulse));
            title(['Second pulse with ' num2str(sum(isnan(second_pulse))) ' NaNs']);
        end
        % replace the NaNs with 0s:
        second_pulse(isnan(second_pulse)) = 0;

        ir = 0;
   
        % n_alternations means how many times one simulates an alternation 
        % between 2 RF pulses
        for i_pulse = 1:n_alternations 
            % get the RF amplitude of that RF Pulse:
            pulse_amp = b1_amp_G(i_pulse);
            % get the fequency offset of that RF Pulse:
            freq_offset = freqoff(i_pulse);
            % repetitions per excitation frequency:
            for i_rep = 1:N
                phase_rep = phase_2nd_pulse(i_rep) + phase_diff_pulses(i_pulse);
                ir=ir+1;
                % very first pulse, first half:
                if ((i_rep == 1) && (i_pulse == 1))
                    v1 = squeeze(mx_x_b1(1, :, :));
                    v2 = squeeze(my_x_b1(1, :, :));
                    v3 = squeeze(mz_x_b1(1, :, :));
                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(first_pulse * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp.* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * phase_rep/360), ...
                                                    first_grad, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end
                % very first pulse, second half
                elseif ((i_rep == 2) && (i_pulse == 1))
                    % to avoid changing the phase of the 2nd pulse aswell:
                    phase_term = zeros(size(ones(1,numel(pulse_sim_short))));
                    % phase of second pulse:
                    phase_rep_p2 = phase_2nd_pulse(i_rep) + phase_diff_pulses(i_pulse);
                    % phase of first pulse:
                    phase_rep_p1 = phase_2nd_pulse(i_rep-1) + phase_diff_pulses(i_pulse);
                    
                    % find first point that is zero (not best way but q&d)
                    ind = find(abs(second_pulse)==0);


                    % up to first point where the this pulse = 0 (should
                    % mean the first pulse finished: use the phase from
                    % first_pulse
                    phase_term(1:ind(1)) = phase_rep_p1;
                    % afterwards apply the (potential) phase shift for the
                    % second pulse:
                    phase_term(ind(1)+1:end) = phase_rep_p2;

                    % previous magnetization:
                    v1 = squeeze(mx_x_b1(1, :, :));
                    v2 = squeeze(my_x_b1(1, :, :));
                    v3 = squeeze(mz_x_b1(1, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(second_pulse * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp.* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) .* phase_term/360), ...
                                                    second_grad, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end
                % first pulse of every repetition (besides the very first), first half:
                elseif ((i_rep == 1) && (i_pulse ~= 1))
                    v1 = squeeze(mx_x_b1((i_pulse-1)*N, :, :));
                    v2 = squeeze(my_x_b1((i_pulse-1)*N, :, :));
                    v3 = squeeze(mz_x_b1((i_pulse-1)*N, :, :));
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(first_pulse / first_pulse_amp_nreps * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp.* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * phase_rep/360), ...
                                                    first_grad, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end
                % first pulse of every repetition (besides the very first), second half:
                 elseif ((i_rep == 2) && (i_pulse ~= 1))
                    v1 = squeeze(mx_x_b1((i_pulse-1)*N+1, :, :));
                    v2 = squeeze(my_x_b1((i_pulse-1)*N+1, :, :));
                    v3 = squeeze(mz_x_b1((i_pulse-1)*N+1, :, :));

                    % to avoid changing the phase of the 2nd pulse aswell:
                    phase_term = zeros(size(ones(1,numel(pulse_sim_short))));
                    % phase of second pulse:
                    phase_rep_p2 = phase_2nd_pulse(i_rep) + phase_diff_pulses(i_pulse);
                    % phase of first pulse:
                    phase_rep_p1 = phase_2nd_pulse(i_rep-1) + phase_diff_pulses(i_pulse);
                    
                    % find first point that is zero (not best way but q&d)
                    ind = find(abs(second_pulse)==0);

                    % up to first point where the this pulse = 0 (should
                    % mean the first pulse finished: use the phase from
                    % first_pulse
                    phase_term(1:ind(1)) = phase_rep_p1;
                    % afterwards apply the (potential) phase shift for the
                    % second pulse:
                    phase_term(ind(1)+1:end) = phase_rep_p2;

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(second_pulse / first_pulse_amp_nreps * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp.* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) .* phase_term/360), ...
                                                    second_grad, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end
                % 2nd last pulse (if a tipback is requested and spoiler )
                elseif ((i_rep == N-1) && strcmp((app.parameters.tipback_pulse), "on") && app.parameters.spoiler_grad)
                    v1 = squeeze(mx_x_b1(ir-1, :, :));
                    v2 = squeeze(my_x_b1(ir-1, :, :));
                    v3 = squeeze(mz_x_b1(ir-1, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(tipback_pulse_amp  .* pulse_sim_short * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp .* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * (tipback_pulse_phase+ phase_rep)/360), ...
                                                    grad_range_sim_short_G_cm, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end

                % last pulse (if a tipback is requested and no spoiler )
                elseif ((i_rep == N) && strcmp((app.parameters.tipback_pulse), "on") && ~app.parameters.spoiler_grad)
                    v1 = squeeze(mx_x_b1(ir-1, :, :));
                    v2 = squeeze(my_x_b1(ir-1, :, :));
                    v3 = squeeze(mz_x_b1(ir-1, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end
    
                    parfor (k = 1:numel(x_sim), n_par_workers)
                        [mxx, myx, mzx] = bloch(tipback_pulse_amp  .* pulse_sim_short * b1Transmit_sim(k) .*...
                                                    exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                    pulse_amp .* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * (tipback_pulse_phase+ phase_rep)/360), ...
                                                    grad_range_sim_short_G_cm, ...
                                                    dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                    v1(k, :), ...
                                                    v2(k, :), ...
                                                    v3(k, :));
                                                    
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);                                
                    end
                
                % all other repetitions
                else
                    v1 = squeeze(mx_x_b1(ir-1, :, :));
                    v2 = squeeze(my_x_b1(ir-1, :, :));
                    v3 = squeeze(mz_x_b1(ir-1, :, :));

                    if ((numel(x_sim)==1) && (size(v1,2) == 1))
                        v1=v1.';
                        v2=v2.';
                        v3=v3.';
                    end

                    parfor (k = 1:numel(x_sim), n_par_workers)
                       [mxx, myx, mzx] = bloch(pulse_sim_short * b1Transmit_sim(k) .*...
                                                   exp(- 1i * 2 * pi * linspace(0, trep, trep_npoints) * freq_offset) * ...
                                                   pulse_amp.* exp(2*pi*1i*ones(1,numel(pulse_sim_short)) * phase_rep/360), ...
                                                   grad_range_sim_short_G_cm, ...
                                                   dt_sim_short, T1, T2, freq, x_sim(k), 2, ...
                                                   v1(k, :), ...
                                                   v2(k, :), ...
                                                   v3(k, :));
                        a(k, :) = mxx(end,:);
                        b(k, :) = myx(end,:);
                        c(k, :) = mzx(end,:);
                    end
                end
                mx_x_b1(ir, :, :) = a;
                my_x_b1(ir, :, :) = b;
                mz_x_b1(ir, :, :) = c;
                
                %                         progress_simRep(app, ir, N);
                app.progress_sim_repLabel.Text = [num2str(ir) '/' num2str(N*n_alternations)];
                pause(0.000000000001);
            end

            % "Spoil mxy magnetization" = set mxy to 0:
            if (app.parameters.spoiler_grad)
                mx_x_b1(ir, :, :) = zeros(size(a));
                my_x_b1(ir, :, :) = zeros(size(b));
            end
        end
    end
    app.parameters.phase_2nd_pulse = phase_2nd_pulse;
            % interface stuff:
            app.SimulateRepsButton.Text = 'Simulate Reps!';
            app.SimulateRepsButton.FontAngle = 'normal';
            app.SimulateRepsButton.BackgroundColor = [0.96,0.96,0.96];
            toc;

            % combine data:
            mxy_x_b1 = mx_x_b1 + 1i * my_x_b1;
    
%             % if all the data shoudld be saved:
%             if strcmp(app.parameters.save_all_sim_data, 'on')
%                 mxy = permute(mxy_x_b1, [3 1 2 4]);
%                 mz = permute(mz_x_b1, [3 1 2 4]);
%                 
%                 sz = size(mxy);
% 
%                 mxy = reshape(mxy, [sz(1)*sz(2) sz(3) sz(4)]);
%                 mz= reshape(mz, [sz(1)*sz(2) sz(3) sz(4)]);
%                 pause();
%             else
                
%             end
            % save simulation results into struct: 
            try
                app.parameters.x_sim = x_sim;
                app.data.mxy_b1_rep = mxy_x_b1;
                app.data.mz_b1_rep = mz_x_b1;

                app.parameters.grad_range_sim_short_G_cm = grad_range_sim_short_G_cm;
                app.parameters.pulse_sim_short = pulse_sim_short;
                app.parameters.b1Transmit_sim = b1Transmit_sim;
                app.parameters.dt_sim_short = dt_sim_short;
            catch
                warning('problems storing data in the app struct, paused!')
                pause();
            end
            % save the simulation results
            if app.simrangeCheckBox.Value
                save_data(app);
            else
            end
            % plot simulation results:
            plot_bssfp_signal(app);
        end

        %% load stuff -----------------------------
        function results = load_rfpulse(app)
            % -- load the RF Pulse
            % -------------------------------------------------------------
            
            results = 0;
            try
                rfpulse = load(app.parameters.rfpulse_file);
            catch
                try
                    if (strcmp(app.parameters.rfpulse_file(end-2:end), 'exc') ||...
                            strcmp(app.parameters.rfpulse_file(end-2:end), 'rfc'))
                        rfpulse = table2array(readtable(app.parameters.rfpulse_file, 'FileType','text'));
                        rfpulse(isnan(rfpulse)) = 0;
%                         rfpulse = rfpulse(:,1) / max(rfpulse(:,1)) .* exp(-1i * 2*pi*rfpulse(:,2)/360);
                        
%                         app.data.rfpulse = rfpulse;
                    end
                catch
                    warning('RF pulse could no be loaded');
                    app.RFPulseEditField.Value = '';
                    app.parameters.rfpulse_file_name = '';
                    app.parameters.rfpulse_file_path = '';
                end
            end
            
            % save loaded RF pulse:
            [~, dims] = size(rfpulse);
            try
                if (dims == 2)
                    rfpulse = rfpulse(:,1) .*exp(1i*pi * rfpulse(:, 2) / 180);
                    rfpulse = rfpulse / max(abs(rfpulse));
                    app.data.rfpulse = rfpulse;
                elseif (dims == 4) % probably from RF Pulse Wizard
                    % first column amplitude [au], third column phase
                    % [degree]
                    rfpulse = rfpulse(:,1) .*exp(1i*pi * rfpulse(:, 3) / 180);
                    rfpulse = rfpulse / max(abs(rfpulse));
                    app.data.rfpulse = rfpulse;
                else
                    try
                        rfpulse = rfpulse / max(abs(rfpulse));
                        app.data.rfpulse = rfpulse.';
                    % maybe it's a struct?
                    catch
                        try
                            % from python tool:
                            rfpulse = rfpulse.b1;
                            rfpulse = rfpulse / max(abs(rfpulse));
                            app.data.rfpulse = rfpulse.';
                        catch
                            % maybe confused the way of saving pulses in
                            % structs...
                            rfpulse = rfpulse.rfpulse;
                            rfpulse = rfpulse / max(abs(rfpulse));
                            app.data.rfpulse = rfpulse.';
                        end
                    end
                end
                results = 1;
            catch
                warning('dont know what to do...');
                results = 0;
            end
            
        end

        function results = load_gradient(app)
            % load the gradient shape
            results = 0;
            % assume 1D
            d = 0;
            try
                gradient = load(app.parameters.gradient_file);
                % 2D gradients from python
                try
                    gx = gradient.gx;
                    gy = gradient.gy;
                    gz = gradient.gz;
                    d=3;
                catch
                    try
                        gx = gradient.gx;
                        gy = gradient.gy;
                        d=2;
                    catch
                        try
                            gx = gradient.gx;
                            d=1;
                        end
                    end
                end
            catch
                warning('Gradient could no be loaded');
                app.GradientEditField.Value = '';
                app.parameters.gradient_file_name = '';
                app.parameters.gradient_file_path = '';
            end
            
            % save gradient:
            try
            if (d==1)
                if size(gx,1) == 1
                    app.data.gradient = gx.';
                else
                    app.data.gradient = gx;
                end
            elseif (d==2)
                    if size(gx,1) == 1
                        app.data.gradient = [gx;gy].';
                    else
                        app.data.gradient = [gx';gy'].';
                    end
            elseif (d==3)
                    if size(gx,1) == 1
                        app.data.gradient = [gx;gy;gz].';
                    else
                        app.data.gradient = [gx';gy';gz].';
                    end
            else
                app.data.gradient = [];
            end
            try
                app.data.gradient = app.data.gradient / max(max(max(app.data.gradient)));
            end
            catch
                warning('dont know what to do...');
                results = 0;
            end
        end
        
        function [] = load_anatomical(app)
            try
                imageObj = ImageDataObject(app.parameters.anatimage_path);
                imageObj = imageObj.readReco;
                imageObj = imageObj.readMethod;
                imageObj = imageObj.readAcqp;
            catch
                warning('Anatomical Image could no be loaded');
            end
            anat_image = squeeze(imageObj.data);
            if (imageObj.Method.PVM_NSPacks == 1)
                anat_image = reshape(anat_image, [size(anat_image) 1]);
            end
            
            app.parameters.anat_patient_pos = imageObj.Acqp.ACQ_patient_pos;
            
            
            anat_image = rot90(flip(anat_image, 1));
            
            app.data.anat_image = anat_image;
            
            
            app.parameters.mat_anat_image = size(anat_image);
            app.parameters.anat_image_fov_mm = imageObj.Method.PVM_Fov;
            
            % multislice:
            if imageObj.Method.PVM_SPackArrNSlices > 1
                app.parameters.anat_image_fov_mm = [app.parameters.anat_image_fov_mm ...
                    imageObj.Method.PVM_SPackArrNSlices * imageObj.Method.PVM_SliceThick + ...
                    (imageObj.Method.PVM_SPackArrNSlices-1) * imageObj.Method.PVM_SPackArrSliceGap];
                % caculate central slice:
                warning('implement method to calculate the central slice:');
                app.parameters.anat_central_slice = round(imageObj.Method.PVM_SPackArrNSlices/2);
            
            else
                app.parameters.anat_image_fov_mm = [app.parameters.anat_image_fov_mm 0];
            end
            
            app.parameters.anat_nslices = imageObj.Method.PVM_SPackArrNSlices;
            
            app.parameters.anat_image_offset_mm = [imageObj.Method.PVM_Phase0Offset(1) ...
                                                   imageObj.Method.PVM_Phase1Offset(1) ...
                                                   imageObj.Method.PVM_Phase2Offset(1)];
            
            app.parameters.anat_read_orient = imageObj.Method.PVM_SPackArrReadOrient;
             
            %             % define grid that Map sits on:
            [vy, vx, vz, off] = define_grid(app, ...
                                app.parameters.anat_image_fov_mm, ...
                                app.parameters.mat_anat_image, ...                                
                                app.parameters.anat_image_offset_mm, ...
                                app.parameters.anat_patient_pos, ...
                                app.parameters.anat_read_orient);
            app.parameters.anat_image_offset_mm = off;
            app.parameters.anat_gridx = vx;
            app.parameters.anat_gridy = vy;
            app.parameters.anat_gridz = vz;
            
            
            %             
            %             app.parameters.refpow_gridx = vx;
            %             app.parameters.refpow_gridy = vy;
            
            
            % calculate refereference power map:
            try
                calc_refpow_anat(app);
            end

        end

        function [] = find_rfpower(app)
            % calc RF power map:
            disp('');
        end
        
        function [] = simulate_2D(app)
            % get pulse:
            try
                pulse = app.data.rfpulse;
            end

            % get gradient shape:
            try
                grad = app.data.gradient;
            end
            % if no Gradient was loaded, generate arbitrary gradient along
            % y
            if (sum(size(grad) == [0 0]) == 2)
                grad = zeros(size(pulse,1), 2);
                grad(:,2) = 1;
            end

            % get B1:
            try
                b1profile_log = app.data.b1TransmitProfileFit_log;
            catch
                b1profile_log  = 0;
            end
            if (sum(b1profile_log) == 0)
                b1profile_log = ones(64, 1);
            end

            calc_fa_b1_app(app,1,0);

            % perform Bloch Simulation:
            % 1.st get parameters:
            Trf = app.parameters.pulse_duration2D_ms * 1e-3;
            Tgrad = app.parameters.gradientduration2D_ms * 1e-3;
            dt = app.parameters.pulse_duration2D_ms_res * 1e-3;
            alpha = app.flipangle_degEditField.Value; % Degrees.
            
            % gamma = 4257.7478518;		% Hz/G. 1H
            gamma = app.parameters.gamma; % Hz/G 13C
            
            t1 = app.parameters.T1;			% Sec.
            t2 = app.parameters.T2;		% Sec.
            freq = linspace(app.parameters.freq2D_hz_low, ...
                            app.parameters.freq2D_hz_high, ...
                            app.parameters.freq2D_hz_npoints); % Hz
%            N = 100; % 5 x T1 for steady state without alpha/2
 %           Ttot = 10 * Trf;
            sint = app.parameters.sint;
            bwfac =  1000/sint;
            % khz_in_g = 4.25755; % 4.25755 KHz =^=  1 G
            khz_in_g = gamma / 1000;

            % time resolution simulation
            dt_sim = app.parameters.pulse_duration2D_ms_res*1e-3;
            grad_duration_ms_res = app.parameters.gradientduration2D_ms / ...
                                   app.parameters.gradientduration2D_ms_npoints;
            dt_sim_grad = grad_duration_ms_res  * 1e-3;
            
            
            % time course simulation
            t_rf_sim = 0:dt_sim:Trf-dt_sim;
            t_grad_sim = 0:dt_sim_grad:Tgrad-dt_sim_grad;

            % time resolution loaded pulse/gradient
            dt_b1 = Trf / numel(pulse(:,1));
            dt_grad = Tgrad / numel(grad(:,1));
            % time course loaded pulse/gradient
            t_b1 = 0:dt_b1:Trf-dt_b1;
            t_grad = 0:dt_grad:Tgrad-dt_grad;
            
            % interpolate loaded pulse onto to simulate pulse:
            pulse_sim = interp1(t_b1, pulse.', t_rf_sim);
            pulse_sim(isnan(pulse_sim)) = 0;

            % 2D/3D Gradients:
            % check if gradients were loaded:
            if (size(app.data.gradient,1)) > 0
                npbefore = 0;
                npafter= 0;
                % 2D Gradient:
                if (size(app.data.gradient,2)) > 1
                    grad_sim = app.data.gradient;
                    calc_gradients(app, dt_sim, Trf, 2);
                    for k = 1:2
                        temp(:,k) = interp1(t_grad, grad_sim(:,k), t_grad_sim);
                    end
                    % normalize
                    temp = temp / max(max(abs(temp)));
                    % set gradient amplitude to calculated gradient
                    % amplitude:
                    grad_sim_G= temp  * app.parameters.gradient_G_cm;
                % 1D Gradient (for now)
                else
                    grad_sim = app.data.gradient;
                    calc_gradients(app, dt_sim, Trf, 2);
                    for k = 1:2
                        temp(:,k) = interp1(t_grad, grad_sim, t_grad_sim);
                    end
                    % normalize
                    temp = temp / max(max(abs(temp)));
                    % set gradient in y to 0:
                    temp(:,1) = 0;
                    % set gradient amplitude to calculated gradient
                    % amplitude:
                    grad_sim_G= temp  * app.parameters.gradient_G_cm;
                end
            % no gradient was loaded:
            else
                % for calculation of rephase gradient/pulse:
                npbefore = numel(t_grad_sim);
                for k = 1:size(grad,2)
                    % calc gradient including rephasing gradient
                    [grad_kHz_cm_range_sim, gradient_G_cm_range_sim] = calc_gradients(app, dt_sim, Trf, 2);
                    grad_sim(:,k) = gradient_G_cm_range_sim;%interp1(t_grad, grad(:,k), t_grad_sim);
                 end
                grad_sim(:,1) = 0;
                npafter = size(grad_sim,1);
                grad_sim(isnan(grad_sim)) = 0;
                grad_sim_G = grad_sim;
            end
            
            % calculate pulse amplitude (if B1 amp in settings = 0):
%             if (app.parameters.b1_amp_khz == 0)
             % b1_amp_G = pi /2 * 1/((2*pi*gamma*1e4) * Trf* sint) * 1e4 * 4  % G 
            
            b1_amp_g  = app.parameters.b1_amp_khz / khz_in_g;
            grad_amp_g = app.parameters.gradientstrength2D_khzcm / khz_in_g;
%             else
%                 b1_amp_khz  = app.parameters.b1_amp_khz;
%                 b1_amp_g =  b1_amp_khz / khz_in_g;
%                 alpha = b1_amp_g / pi * 180 *((2*pi*gamma*1e4) * Trf* sint) / 1e4;
%                 app.flipangle_degEditField.Value = alpha;
%             end

            % get pulse in Gauss
            pulse_sim_G = pulse_sim * b1_amp_g;

            pulse_sim_G = [pulse_sim_G zeros(1, npafter-npbefore)].';
%             grad_sim_G = grad_sim * grad_amp_g;
            
            
            % B1 measured range:
            y_b1_cm = linspace(0.25, 31.75, 64)/10;
            y_b1_cm = y_b1_cm - 1.6;

            % B1 simulated range:
            x_sim_cm = linspace(-app.parameters.xrange2D_cm/2, ...
                                 app.parameters.xrange2D_cm/2, ...
                                 app.parameters.xrange2D_cm_npoints).';

            y_sim_cm = linspace(-app.parameters.yrange2D_cm/2, ...
                                 app.parameters.yrange2D_cm/2, ...
                                 app.parameters.yrange2D_cm_npoints).';


            % Gradient Amplitude:

            % This is done in the 1D case, for 2D use the loaded B1 Map
%             b1Transmit_sim_log = interp1(y_b1_cm, b1profile_log, y_sim_cm, 'spline', 'extrap');
%             b1Transmit_sim = exp(b1Transmit_sim_log) / max(exp(b1Transmit_sim_log ));
            if sum(numel(app.data.b1TransmitMap)) > 0 % means that a B1 maps was loaded
                b1Transmit_measured = app.data.b1TransmitMap;
                
                % Array on which the B1 Map was acquired (needs to be centered):
                warning('Position of the B1 Map needs to be determined');
                fovB1x = (-32/2+(32/2/64) : 32/64 : 32/2-(32/2/64))/10; 
                fovB1y = (-32/2+(32/2/64) : 32/64 : 32/2-(32/2/64))/10;
                fovB1y = fovB1y - app.parameters.refpow_map_offset_mm(1)/10;
                fovB1x = fovB1x - app.parameters.refpow_map_offset_mm(2)/10;
                fovSimx = x_sim_cm;
                fovSimy = y_sim_cm;
                
                
                % resolution:
                dx = (fovB1x(2)-fovB1x(1));
                
                [XB1,YB1] = meshgrid(fovB1x,fovB1y);
                [XSim,YSim] = meshgrid(fovSimx ,fovSimy);
                
                b1Transmit_sim = interp2(XB1,YB1, ...
                        b1Transmit_measured, XSim, YSim);
                b1Transmit_sim = abs(b1Transmit_sim) / max(max(abs(b1Transmit_sim)));
            else
                % uniform B1
                b1Transmit_sim = ones(numel(y_sim_cm),numel(x_sim_cm));
            end

            
            % add 0s to the pulse:
%             pulse_sim_G = [pulse_sim_G zeros(1, numel(pulse_sim_G))];

            % Bloch Simulation:
            mxy_x_b1 = zeros(numel(y_sim_cm),numel(pulse_sim_G),  numel(x_sim_cm), numel(freq));
            mz_x_b1 = zeros(numel(y_sim_cm),numel(pulse_sim_G),  numel(x_sim_cm), numel(freq));
            
            app.Simulate2D_Button.Text = 'Simulating!';
            app.Simulate2D_Button.FontAngle = 'italic';
            app.Simulate2D_Button.BackgroundColor = 'r';
            
                [x,y] =meshgrid(x_sim_cm, y_sim_cm);
            

            pause(0.00000001);
            
            % inhomogeneous B1
            if sum(app.CButton.Value) % C13
                for iy = 1:numel(y_sim_cm)
                for ix = 1:numel(x_sim_cm)
                    if ~isnan(b1Transmit_sim(iy,ix))
                    pos = [x(:,ix) y(:,ix)];
                    [mxx, myx, mzx] = bloch(pulse_sim_G * b1Transmit_sim(iy,ix), ...
                        grad_sim_G, dt_sim, t1, t2, freq, pos, 2);
                    else
                        mxx = nan*ones(numel(pulse_sim_G), numel(y_sim_cm), numel(freq));
                        myx = nan*ones(numel(pulse_sim_G), numel(y_sim_cm), numel(freq));
                        mzx = nan*ones(numel(pulse_sim_G), numel(y_sim_cm), numel(freq));
                    end
                    mxy_x_b1(iy,:, ix, :) = mxx(:,iy,:) + 1i* myx(:,iy,:);
                    mz_x_b1(iy,:, ix, :) = mzx(:,iy,:);
                end
                end
            else
                for iy = 1:numel(y_sim_cm)
                for ix = 1:numel(x_sim_cm)
                    if ~isnan(b1Transmit_sim(iy,ix))
                    pos = [y(:,ix) x(:,ix) ];
                    [mxx, myx, mzx] = bloch(pulse_sim_G * b1Transmit_sim(iy,ix), ...
                        grad_sim_G, dt_sim, t1, t2, freq, pos, 2);
                    else
                        mxx = nan*ones(numel(pulse_sim_G), numel(y_sim_cm), numel(freq));
                        myx = nan*ones(numel(pulse_sim_G), numel(y_sim_cm), numel(freq));
                        mzx = nan*ones(numel(pulse_sim_G), numel(y_sim_cm), numel(freq));
                    end
                    mxy_x_b1(iy,:, ix, :) = mxx(:,iy,:) + 1i* myx(:,iy,:);
                    mz_x_b1(iy,:, ix, :) = mzx(:,iy,:);
                end
                end
            end
            
            % homogeneous B1
%             mxy_x = zeros(numel(y_sim_cm), numel(pulse_sim_G), numel(freq));
%             mz_x = zeros(numel(y_sim_cm), numel(pulse_sim_G), numel(freq));

%             if sum(app.CButton.Value) % C13
%                 [mxx, myx, mzx] = bloch(pulse_sim_G, grad_sim_G, dt_sim, t1, t2, freq, y_sim_cm, 2);
%                 mxy_x = mxx + 1i* myx;
%                 mz_x = mzx;
%             else
%                 [mxx, myx, mzx] = bloch(pulse_sim_G, grad_sim_G, dt_sim, t1, t2, freq, y_sim_cm, 2);
%                 mxy_x = mxx + 1i* myx;
%                 mz_x = mzx;
%             end

            app.Simulate2D_Button.Text = 'Simulate!';
            app.Simulate2D_Button.FontAngle = 'normal';
            app.Simulate2D_Button.BackgroundColor = [0.96,0.96,0.96];
            
            % save simulations:
            app.data.mxy_b1 = mxy_x_b1;
            app.data.mz_b1 = mz_x_b1;

            % permute to match b1 mxy + b1 mz
%             app.data.mxy = permute(mxy_x, [2 1 3]);  
%             app.data.mz = permute(mz_x, [2 1 3]);  

            % plot simulated data:
            plot_b1profile(app);
            plot_rfpulse(app);
            try
                plot_mag_vs_dist(app, app.parameters.freq_ind);
            catch
                plot_mag_vs_dist(app, 1);
            end
            
        end
        
         % save data
        function success = save_data(app)
            try
            % check which save options are ticked:
            save_options = app.saveoptions_ListBox.Value;
            save_opts = zeros(1, numel(app.saveoptions_ListBox.Items));
            save_struct = [];
            if sum(strcmp(save_options, 'homo'))
                save_opts(1) = 1;
                time_ind = 1:size(app.data.mxy,2);
            else
                save_opts(1) = 0;
                time_ind = size(app.data.mxy,2)
            end
            if sum(strcmp(save_options, 'inhomo'))
                save_opts(2) = 1;
                time_ind = 1:size(app.data.mxy,2);
            else
                save_opts(2) = 0;
                time_ind = size(app.data.mxy,2)
            end

            if sum(strcmp(save_options, 'time'))
                save_opts(3) = 1;
                time_ind = 1:size(app.data.mxy,2);
            else
                save_opts(3) = 0;
                time_ind = size(app.data.mxy,2)
            end
            if sum(strcmp(save_options, 'freqs'))
                save_opts(4) = 1;
                freq_ind = 1:numel(app.parameters.freq_hz_range);
            else
                save_opts(4) = 0;
                freq_ind = round(numel(app.parameters.freq_hz_range)/2);
            end
            if sum(strcmp(save_options, 'dist'))
                save_opts(5) = 1;
                dist_ind = 1:app.parameters.yrange_cm_npoints;
            else
                save_opts(5) = 0;
                dist_ind = round(app.parameters.yrange_cm_npoints/2);
            end
            if sum(strcmp(save_options, 'reps'))
                save_opts(6) = 1;
            end
            if sum(strcmp(save_options, 'plots'))
                save_opts(7) = 1;
            end

            if save_opts(1)
                try
                    data.mxy = app.data.mxy(dist_ind, time_ind, freq_ind);
                    data.mz = app.data.mz(dist_ind, time_ind, freq_ind);
                catch
                    warning('could not homogenous B1');
                end
            end

            if save_opts(2)
            try
                data.mxy_b1 = app.data.mxy_b1(dist_ind, time_ind, freq_ind);
                data.mz_b1 = app.data.mz_b1(dist_ind, time_ind, freq_ind);
            catch
                warning('could not inhomogenous B1');
            end
            end

            % save repetead excitation simulation
            if save_opts(6)
                try
                data.mxy_rep = app.data.mxy_b1_rep;
                data.mz_rep= app.data.mz_b1_rep;
                catch
                    warning('could not save repeated excitation simulation!');
                end
            end

%             % save repetead excitation simulation
%             if ~save_opts(5)
%                 try
%                 data.mxy = [];
%                 data.mz= [];
%                 data.mxy_b1 = [];
%                 data.mz_b1= [];
%                 catch
%                     warning('could not NOT save single excitation simulation!');
%                 end
%             end

            parameters = app.parameters;

            if strcmp(app.save_fileEditField.Value, '')
                if app.simrangeCheckBox.Value
                    disp('');
                    pathname = app.parameters.save_multi_sim_path;
                    filename = ['\' num2str(app.parameters.multi_sim_n)];
                else
                    filterspec = {'*.mat'};
                    
                    try
                        pulse_path = app.parameters.rfpulse_file_path;
                        [filename, pathname] = uiputfile(filterspec, 'Choose file', fullfile(pulse_path, '\'));
                    catch
                        [filename, pathname] = uiputfile(filterspec, 'Choose file', fullfile(pulse_path, '\'));
                    end
                end
            

            else
%                 absPath = fileparts(mfilename('fullpath'))
                if app.simrangeCheckBox.Value
                    disp('');
                    data.mxy_rep 
                    data.mxy_rep 
                    pathname = app.parameters.save_multi_sim_path;
                    filename = ['\' num2str(app.parameters.multi_sim_n)];
                else
                absPath = pwd();
%                 pathname = [absPath  '\simulations\'];
                pathname = [absPath '\'];
                filename = app.save_fileEditField.Value;
                if numel(filename) > 3
                    if ~strcmp(filename(end-3:end), 'mat')
                        filename = [filename '.mat'];
                    end
                end
                end

            end
            % try saving data:
            try
                save([pathname filename], 'data', 'parameters');
            catch
                try
                    save([pathname filename '.mat'], 'data', 'parameters');
                catch
                    warning('data could not be saved');
                end
            end

                success = 1;
            catch
                success = 0;
        end
        end

        function  progress_simRep(app, ir, N)
            if round(ir/N, 2) < 0.1
                app.progress_simRepLabel_11.BackgroundColor = 'w';
                app.progress_simRepLabel_12.BackgroundColor = 'w';
                app.progress_simRepLabel_13.BackgroundColor = 'w';
                app.progress_simRepLabel_14.BackgroundColor = 'w';
                app.progress_simRepLabel_15.BackgroundColor = 'w';
                app.progress_simRepLabel_16.BackgroundColor = 'w';
                app.progress_simRepLabel_17.BackgroundColor = 'w';
                app.progress_simRepLabel_18.BackgroundColor = 'w';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.1) && (round(ir/N, 2) < 0.2)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'w';
                app.progress_simRepLabel_13.BackgroundColor = 'w';
                app.progress_simRepLabel_14.BackgroundColor = 'w';
                app.progress_simRepLabel_15.BackgroundColor = 'w';
                app.progress_simRepLabel_16.BackgroundColor = 'w';
                app.progress_simRepLabel_17.BackgroundColor = 'w';
                app.progress_simRepLabel_18.BackgroundColor = 'w';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.2) && (round(ir/N, 2) < 0.3)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'w';
                app.progress_simRepLabel_14.BackgroundColor = 'w';
                app.progress_simRepLabel_15.BackgroundColor = 'w';
                app.progress_simRepLabel_16.BackgroundColor = 'w';
                app.progress_simRepLabel_17.BackgroundColor = 'w';
                app.progress_simRepLabel_18.BackgroundColor = 'w';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.3) && (round(ir/N, 2) < 0.4)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'g';
                app.progress_simRepLabel_14.BackgroundColor = 'w';
                app.progress_simRepLabel_15.BackgroundColor = 'w';
                app.progress_simRepLabel_16.BackgroundColor = 'w';
                app.progress_simRepLabel_17.BackgroundColor = 'w';
                app.progress_simRepLabel_18.BackgroundColor = 'w';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.4) && (round(ir/N, 2) < 0.5)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'g';
                app.progress_simRepLabel_14.BackgroundColor = 'g';
                app.progress_simRepLabel_15.BackgroundColor = 'w';
                app.progress_simRepLabel_16.BackgroundColor = 'w';
                app.progress_simRepLabel_17.BackgroundColor = 'w';
                app.progress_simRepLabel_18.BackgroundColor = 'w';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.5) && (round(ir/N, 2) < 0.6)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'g';
                app.progress_simRepLabel_14.BackgroundColor = 'g';
                app.progress_simRepLabel_15.BackgroundColor = 'g';
                app.progress_simRepLabel_16.BackgroundColor = 'w';
                app.progress_simRepLabel_17.BackgroundColor = 'w';
                app.progress_simRepLabel_18.BackgroundColor = 'w';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.6) && (round(ir/N, 2) < 0.7)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'g';
                app.progress_simRepLabel_14.BackgroundColor = 'g';
                app.progress_simRepLabel_15.BackgroundColor = 'g';
                app.progress_simRepLabel_16.BackgroundColor = 'g';
                app.progress_simRepLabel_17.BackgroundColor = 'w';
                app.progress_simRepLabel_18.BackgroundColor = 'w';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.7) && (round(ir/N, 2) < 0.8)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'g';
                app.progress_simRepLabel_14.BackgroundColor = 'g';
                app.progress_simRepLabel_15.BackgroundColor = 'g';
                app.progress_simRepLabel_16.BackgroundColor = 'g';
                app.progress_simRepLabel_17.BackgroundColor = 'g';
                app.progress_simRepLabel_18.BackgroundColor = 'w';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.8) && (round(ir/N, 2) < 0.9)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'g';
                app.progress_simRepLabel_14.BackgroundColor = 'g';
                app.progress_simRepLabel_15.BackgroundColor = 'g';
                app.progress_simRepLabel_16.BackgroundColor = 'g';
                app.progress_simRepLabel_17.BackgroundColor = 'g';
                app.progress_simRepLabel_18.BackgroundColor = 'g';
                app.progress_simRepLabel_19.BackgroundColor = 'w';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif (round(ir/N, 2) >= 0.9) && (round(ir/N, 2) < 1)
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'g';
                app.progress_simRepLabel_14.BackgroundColor = 'g';
                app.progress_simRepLabel_15.BackgroundColor = 'g';
                app.progress_simRepLabel_16.BackgroundColor = 'g';
                app.progress_simRepLabel_17.BackgroundColor = 'g';
                app.progress_simRepLabel_18.BackgroundColor = 'g';
                app.progress_simRepLabel_19.BackgroundColor = 'g';
                app.progress_simRepLabel_20.BackgroundColor = 'w';
            elseif round(ir/N, 2) == 1
                app.progress_simRepLabel_11.BackgroundColor = 'g';
                app.progress_simRepLabel_12.BackgroundColor = 'g';
                app.progress_simRepLabel_13.BackgroundColor = 'g';
                app.progress_simRepLabel_14.BackgroundColor = 'g';
                app.progress_simRepLabel_15.BackgroundColor = 'g';
                app.progress_simRepLabel_16.BackgroundColor = 'g';
                app.progress_simRepLabel_17.BackgroundColor = 'g';
                app.progress_simRepLabel_18.BackgroundColor = 'g';
                app.progress_simRepLabel_19.BackgroundColor = 'g';
                app.progress_simRepLabel_20.BackgroundColor = 'g';
            end
            pause(0.00000001);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % change folder to file directory
            if(~isdeployed)
                cd(fileparts(which(mfilename)))
            end
            % set parameters values to start with
            app.parameters.gradient_kHz_cm = app.gradientstrength_khzcm_EditField.Value;
            app.parameters.gamma = 4257.7478518; % [1H]
            app.parameters.T1 = str2double(app.T1_sEditField.Value);
            app.parameters.T2 = str2double(app.T2_sEditField.Value);
            app.parameters.sample_mag = app.HPEditField.Value;
            
            % y range:
            app.parameters.yrange_cm = app.yrange_cm_EditField.Value;
            app.parameters.yrange_cm_npoints = str2double(app.yrange_cm_npoints_EditField.Value);
            
            % pulse duration
            app.parameters.pulse_duration_ms = app.pulseduration_ms_EditField.Value;
            app.parameters.pulse_duration_ms_npoints = str2double(app.pulseduration_ms_npoints_EditField.Value);
            app.parameters.pulse_duration_ms_res = app.parameters.pulse_duration_ms / app.parameters.pulse_duration_ms_npoints;
            app.duration_refocus_grad.Value = app.parameters.pulse_duration_ms_res;
            
            % frequency [hz]
            app.parameters.freq_hz_high = app.freq_hz_high_EditField.Value;
            app.parameters.freq_hz_low = app.freq_hz_low_EditField.Value;
            app.parameters.freq_hz_npoints = str2double(app.freq_hz_npoints_EditField.Value);
            app.parameters.freq_ind = 1;

            % frequency [ppm]
            app.parameters.freq_ppm1 = app.Freq1_ppmEditField.Value;
            app.parameters.freq_ppm2 = app.Freq2_ppmEditField.Value;
            app.parameters.freq_ppm_basic = app.BasicFreqEditField.Value;
            app.parameters.freq_hz_pulse = app.PulseFreqEditField.Value;

            % flipangle
            app.parameters.alpha = app.flipangle_degEditField.Value;
            app.parameters.b1_amp_khz = app.b1_mag_khz_EditField.Value;

            % integration factor
            app.parameters.sint = app.sint_EditField.Value;
            app.parameters.bwfac_hz_ms = 1000/app.parameters.sint;

            % run simulation for finding gradient strength for B1
            % compensation:
            app.parameters.find_grad_low = app.find_Gradients_lowEditField.Value;
            app.parameters.find_grad_high = app.find_Gradients_highEditField.Value;
            app.parameters.find_grad_npoints  = app.find_Gradients_npointsEditField.Value;

            app.parameters.find_grad_ind1 = app.find_Gradients_ind1EditField.Value;
            app.parameters.find_grad_ind2 = app.find_Gradients_ind2EditField.Value;

            % run simulation of repeated excitation (to see the Steady
            % state)
            app.parameters.repetitions_time_ms = app.repetition_time_msEditField.Value;
            app.parameters.repetitions_time_ms_npoints = app.repetition_time_npointsEditField.Value;
            app.parameters.nrepetitions  = app.nrepetitions_EditField.Value;
            app.parameters.alternating_phase  = app.incphaseEditField.Value;
            app.parameters.first_pulse_amp = app.amp_1stpulse_EditField.Value;
            app.parameters.first_pulse_always = app.alpha2prepCheckBox.Value;
            app.parameters.save_all_sim_data = app.highresoutCheckBox.Value;
            app.parameters.ref_grad_dur_ms = app.repetition_time_msEditField.Value - 2*app.pulseduration_ms_EditField.Value;
            app.parameters.first_repetition_time_ms  = app.parameters.repetitions_time_ms;
            app.first_repetition_time_msEditField.Value = app.parameters.first_repetition_time_ms;

            % 2nd pulse:
            app.parameters.simulate_alternating_freqs = 'off';
            app.parameters.alpha_2ndfreq = app.alpha2ndPulseEditField.Value;
            app.parameters.freq_2ndfreq_hz = app.freq_2ndpulse_HzEditField.Value;
            app.parameters.alternate_freq_Ntimes_2nd_freq = app.AlterNTimesEditField.Value;
            app.parameters.phase_pulse_deg = app.altphaseEditField.Value;

            % 1st pulse 
            app.parameters.freq_1stfreq_hz = app.freq_1stpulse_HzEditField.Value;

            % Spoiler Gradient:
            app.parameters.spoiler_grad = app.SpoilerGradCheckBox.Value;

            % Tipback pulse (after N repetitions)
            app.parameters.tipback_pulse= app.TipbackCheckBox.Value;
            app.parameters.phase_tipback_pulse = app.phasetbEditField.Value;
            
            % number of rounds to simulated (N phase encodes + tipback,
            % spoiling, perparation pulse)
            app.parameters.Nsims = app.parameters.nrepetitions;


            % use map instead of profile:
            app.parameters.use_map = app.usemapCheckBox.Value;
            % pointer position 
            app.parameters.pointer2d = [1 1];
            % wether rf power should be found:
            app.parameters.find_rfpower = app.findRFPowerCheckBox.Value;
            % init refpowmap FOV
            app.parameters.refpow_map_fov_mm = [0 0];
            app.parameters.refpow_pos_x_mm = app.xmmEditField.Value;
            app.parameters.refpow_pos_y_mm = app.ymmEditField.Value;
            app.parameters.mat_refpow_map = [0 0];

            % filenames:
            app.parameters.rfpulse_file_name = '';
            app.parameters.rfpulse_file_path = '';

            % wether to simulate a range of values:
            app.parameters.save_multi_sim_path = '';
            app.parameters.simulate_range_of_vals = app.simrangeCheckBox.Value;
            app.parameters.multi_sim_n =0;
            app.parameters.fa_range_val = 0;
            app.parameters.tr_range_val = 0;
            app.parameters.t1_range_val = 0;
            app.parameters.t2_range_val = 0;

            % anatomical image parameters:
            app.parameters.anat_image_fov_mm = [0 0];
            app.parameters.anat_image_mat= [0 0];
            app.parameters.anat_image_offset_mm = [0 0];

            % plotting parameters:
            app.parameters.plot_bssfp_signal = app.bssfpsignalCheckBox.Value;


            app.parameters.b1_map_loaded = false;
            app.parameters.plot_homo_mx = 0;
            app.parameters.plot_homo_my = 0;
            app.parameters.plot_homo_mxy = 1;
            app.parameters.plot_homo_mz = 0;
            app.parameters.plot_homo_phase = 0;
            app.parameters.plot_homo_none = 0;


            app.parameters.plot_inhomo_mx = 0;
            app.parameters.plot_inhomo_my = 0;
            app.parameters.plot_inhomo_mxy = 0;
            app.parameters.plot_inhomo_mz = 0;
            app.parameters.plot_inhomo_phase = 0;
            app.parameters.plot_inhomo_none = 0;

            app.parameters.anat_image_slice = 1;
            app.parameters.anat_nslices = 1;

            % 2D parameters: 
            app.parameters.pulse_duration2D_ms_npoints = str2double(app.pulseduration2D_ms_npoints_EditField.Value);
            app.parameters.pulse_duration2D_ms = app.pulseduration2D_ms_EditField.Value;
            app.parameters.pulse_duration2D_ms_res = app.parameters.pulse_duration2D_ms / app.parameters.pulse_duration2D_ms_npoints;
            app.parameters.gradientduration2D_ms_npoints = str2double(app.gradientduration2D_ms_npoints_EditField.Value);
            app.parameters.gradientduration2D_ms = app.gradientduration2D_ms_EditField.Value;
            app.parameters.gradientstrength2D_khzcm = app.gradientstrength2D_khzcm_EditField.Value;
            app.parameters.freq2D_hz_npoints = str2double(app.freq2D_hz_npoints_EditField.Value);
            app.parameters.freq2D_hz_high = app.freq2D_hz_high_EditField.Value;
            app.parameters.freq2D_hz_low = app.freq2D_hz_low_EditField.Value;
            app.parameters.yrange2D_cm_npoints = str2double(app.yrange2D_cm_npoints_EditField.Value);
            app.parameters.yrange2D_cm = app.yrange2D_cm_EditField.Value;
            app.parameters.xrange2D_cm_npoints = str2double(app.xrange2D_cm_npoints_EditField.Value);
            app.parameters.xrange2D_cm = app.xrange2D_cm_EditField.Value;

            % if b1 field is from cryo (relevant for offset)
            app.parameters.B1_is_cryo = 0;

            % needs to be intialized, dont know exactly what it's for:
            app.parameters.calc_refpow_outside_map = 0;
            app.parameters.refpow_map_offset_mm = [0 0];


            % data
            app.data.rfpulse = zeros(3,1);
            app.data.b1TransmitProfileFit_log = ones(64, 1);

            app.data.mxy_b1 = zeros(3,3);
            app.data.mz_b1 = zeros(3,3);

            app.data.mxy_b1 = zeros(3,3);

            app.data.mxy = zeros(3,3);
            app.data.mz = zeros(3,3);

            app.data.anat_image = zeros(3,3);

            app.data.gradient = [];
            app.data.b1TransmitMap = [];

            % 
            app.data.refpow_map = zeros(3,3);
            app.data.refpow_map_calc = zeros(3,3);

            if app.UseFrequenciesCheckBox.Value
                app.Freq1_ppmEditField.Enable = 'off';
                app.Freq2_ppmEditField.Enable = 'off';
                app.BasicFreqEditField.Enable = 'off';

            else
                app.Freq1EditField.Enable = 'off';
                app.Freq2EditField.Enable = 'off';
            end


            % calc resolutions:
            calc_freq_hz_res(app);
            calc_yrange_cm_res(app);
            calc_pulse_duration_ms_res(app, 1);
            calc_slice_thick(app);
            calc_fa_b1_app(app,1,0);

            set(0, 'DefaultLineLineWidth', 1);
            
            %app.parameters.pulse_duration_ms = app.pulseduration_ms_EditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.calc_rf_pulse_toolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {921, 921};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {445, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SelectRFPulse_Button
        function SelectRFPulse_ButtonPushed(app, event)
            try 
               [filename, pathname] = uigetfile('*', 'Select Pulse ', 'D:\LRZ Sync+Share\phd\bSSFP\uniform excitation surface');
            catch
               [filename, pathname] = uigetfile('');
            end
            try
                app.parameters.rfpulse_file = [pathname filename];
                app.RFPulseEditField.Value = filename;
                app.parameters.rfpulse_file_name = filename;
                app.parameters.rfpulse_file_path = pathname;
            end
            figure(app.calc_rf_pulse_toolUIFigure);
        end

        % Button pushed function: LoadRFPulse_Button
        function LoadRFPulse_ButtonPushed(app, event)
            % load rf pulse:
            load_rfpulse(app);
            
            % calculate integration factor
            calc_sint(app);
            % calculate slice thickness:
            calc_slice_thick(app);
            % calc B1
            calc_fa_b1_app(app,1,0);
            
            % plot the pulse
            figure(app.calc_rf_pulse_toolUIFigure);
            plot_rfpulse(app);
        end

        % Button pushed function: SelectB1Profile_Button
        function SelectB1Profile_ButtonPushed(app, event)
            try 
               [filename, pathname] = uigetfile('*.mat', 'Select B1 Profile ', 'D:\LRZ Sync+Share\phd\bSSFP\uniform excitation surface');
            catch
               [filename, pathname] = uigetfile('');
            end
            try
                app.parameters.b1profile_file = [pathname filename];
                app.B1ProfileEditField.Value = filename;
            end
            figure(app.calc_rf_pulse_toolUIFigure);
        end

        % Button pushed function: LoadB1Profile_Button
        function LoadB1Profile_ButtonPushed(app, event)
            % try to load (assume cryo B1)
            try
                load(app.parameters.b1profile_file)
            catch
                warning("can't load B1 profile");
            end

            try
                if (ndims(squeeze(fit_res.amp)) == 2)
                    b1TransmitProfile = fit_res.amp(:, end/2);
                    % fit along high SNR points
                    b1TransmitProfileFit_log = fit((6:32).', log(abs(b1TransmitProfile(6:32))), 'poly1');
                    % use fit curve to extent to 
                    app.data.b1TransmitProfileFit_log = b1TransmitProfileFit_log(1:64);
                    app.data.b1TransmitMap = fit_res.amp;
                    % save reference power map:
                    app.data.refpow_map = [];
                    app.data.refpow_map(:,:,1) = fit_res.refpow_map;
                    app.data.refpow_map(:,:,2) = fit_res.refpow_map;
                    % for now
                    app.data.refpow_map_calc = app.data.refpow_map;
                    %%
                    % save fov:
                    refpow_map_fov_mm = fit_res.header.p.FOV;
                    app.parameters.refpow_map_fov_mm = [refpow_map_fov_mm 0];
                    app.parameters.refpow_map_header = fit_res.header;
                    app.parameters.refpow_matsize= size(app.data.refpow_map);

                    refpow_map_offset_mm = [fit_res.header.method.PVM_SPackArrPhase1Offset, ...
                                            fit_res.header.method.PVM_SPackArrPhase2Offset, ...
                                            0];

                    app.parameters.refpow_patient_pos = fit_res.header.ACQ_patient_pos;
                    app.parameters.refpow_read_orient= fit_res.header.method.PVM_SPackArrReadOrient;
                    app.parameters.refpow_map_offset_mm = refpow_map_offset_mm;

                    % define grid that Map sits on:
                    [vy, vx, vz, off] = define_grid(app, app.parameters.refpow_map_fov_mm, ...
                                                    app.parameters.refpow_matsize, ...                                
                                                    app.parameters.refpow_map_offset_mm, ...
                                                    app.parameters.refpow_patient_pos, ...
                                                    app.parameters.refpow_read_orient);
                    app.parameters.refpow_map_offset_mm = off;
                    app.parameters.refpow_gridx = vx(:,:,end/2);
                    app.parameters.refpow_gridy = vy(:,:,end/2);
                    app.parameters.refpow_gridz = vz(:,:,end/2);
                    app.data.refpow_map = app.data.refpow_map(:,:,1);
                       
                    app.parameters.mat_refpow_map = size(app.data.refpow_map);

                    % in case it was red:
                    app.LoadB1Profile_Button.BackgroundColor = [0.96,0.96,0.96];                    % plot
                    app.parameters = orderfields(app.parameters);
                    plot_b1profile(app);
                else
                    b1TransmitProfile = fit_res.amp(:, end/2, end/2);
                    % fit along high SNR points
                    b1TransmitProfileFit_log = fit((6:32).', log(abs(b1TransmitProfile(6:32))), 'poly1');
                    % use fit curve to extent to 
                    app.data.b1TransmitProfileFit_log = b1TransmitProfileFit_log(1:64);
                    % save reference power map:
                    app.data.refpow_map = squeeze(fit_res.refpow_map(:, :, end/2));
                    %% for now
                    app.data.refpow_map_calc = app.data.refpow_map;
                    %%
                    app.parameters.refpow_map_fov_mm = fit_res.header.p.FOV;
                    app.parameters.refpow_map_header = fit_res.header;
                    if strcmp(fit_res.header.ACQ_patient_pos, 'Head_Supine')
                        app.parameters.refpow_map_offset_mm = -[fit_res.header.method.PVM_Phase1Offset ...
                                                            fit_res.header.method.PVM_Phase0Offset];
                    else
                        app.parameters.refpow_map_offset_mm = [fit_res.header.method.PVM_Phase1Offset ...
                                                        fit_res.header.method.PVM_Phase0Offset];
                    end
                    app.parameters.mat_refpow_map = size(app.data.refpow_map);
                    % in case it was red:
                    app.LoadB1Profile_Button.BackgroundColor = [0.96,0.96,0.96];
                    % plot
                    plot_b1profile(app);
                end
                app.parameters.b1_map_loaded = true;
                app.inhomoB1ListBox.Enable = "on";
            catch
                warning('could not load B1 Profile!')
                app.LoadB1Profile_Button.BackgroundColor = 'r';
                app.parameters.b1_map_loaded = false;
                app.inhomoB1ListBox.Enable = "off";
            end
            try
                % try to interpolate the reference power map onto the
                % anatomical image:
                calc_refpow_anat(app);
            end

            figure(app.calc_rf_pulse_toolUIFigure);


        end

        % Selection changed function: NucleusButtonGroup
        function NucleusButtonGroupSelectionChanged(app, event)
            selectedButton = app.NucleusButtonGroup.SelectedObject;
            if sum(app.CButton.Value) % default, 
                app.parameters.gamma = 1070.84; % Hz/G 13C
            else
                app.parameters.gamma = 4257.7478518;		% Hz/G. 1H
            end
            figure(app.calc_rf_pulse_toolUIFigure);
        end

        % Value changed function: yrange_cm_EditField
        function yrange_cm_EditFieldValueChanged(app, event)
            value = app.yrange_cm_EditField.Value;
            app.parameters.yrange_cm = value;
            calc_yrange_cm_res(app);
        end

        % Button pushed function: Simulate1D_Button
        function Simulate1D_ButtonPushed(app, event)
            % get pulse:
            try
                pulse = app.data.rfpulse;
            end

            % get B1:
            try
                b1profile_log = app.data.b1TransmitProfileFit_log;
            end
            if (sum(b1profile_log) == 0)
                b1profile_log = ones(64, 1);
            end

            calc_fa_b1_app(app,1,0);

            % perform Bloch Simulation:
            % 1.st get parameters:
            Trf = app.parameters.pulse_duration_ms * 1e-3;    
            dt = app.parameters.pulse_duration_ms_res * 1e-3;
            alpha = app.flipangle_degEditField.Value; % Degrees.
            
            % gamma = 4257.7478518;		% Hz/G. 1H
            gamma = app.parameters.gamma; % Hz/G 13C
            
            t1 = app.parameters.T1;			% Sec.
            t2 = app.parameters.T2;		% Sec.
            freq = linspace(app.parameters.freq_hz_low, ...
                            app.parameters.freq_hz_high, ...
                            app.parameters.freq_hz_npoints); % Hz
%            N = 100; % 5 x T1 for steady state without alpha/2
 %           Ttot = 10 * Trf;
            sint = app.parameters.sint;
            bwfac =  1000/sint;
            % khz_in_g = 4.25755; % 4.25755 KHz =^=  1 G
            khz_in_g = gamma / 1000;

            % time resolution simulation
            dt_sim = app.parameters.pulse_duration_ms_res*1e-3;
            % time course simulation
            t_rf_sim = 0:dt_sim:Trf-dt_sim;

            % time resolution loaded pulse
            dt_b1 = Trf / numel(pulse(:,1));
            % time course loaded pulse
            t_b1 = 0:dt_b1:Trf-dt_b1;
            
            % interpolate loaded pulse onto to simulate pulse:
            pulse_sim = interp1(t_b1, pulse.', t_rf_sim);
            pulse_sim(isnan(pulse_sim)) = 0;

            % calculate pulse amplitude (if B1 amp in settings = 0):
%             if (app.parameters.b1_amp_khz == 0)
             % b1_amp_G = pi /2 * 1/((2*pi*gamma*1e4) * Trf* sint) * 1e4 * 4  % G 
            
            b1_amp_g  = app.parameters.b1_amp_khz / khz_in_g;
%             else
%                 b1_amp_khz  = app.parameters.b1_amp_khz;
%                 b1_amp_g =  b1_amp_khz / khz_in_g;
%                 alpha = b1_amp_g / pi * 180 *((2*pi*gamma*1e4) * Trf* sint) / 1e4;
%                 app.flipangle_degEditField.Value = alpha;
%             end

            % get pulse in Gauss

            pulse_sim_G = pulse_sim * b1_amp_g;
%             pulse_sim_G = flip(pulse_sim_G);

            % calculate gradients:
            [grad_kHz_cm_range_sim, gradient_G_cm_range_sim] = calc_gradients(app, dt_sim, Trf, 1);
            
            % B1 measured range:
            x_b1_cm = linspace(0.25, 31.75, 64)/10;
            x_b1_cm = x_b1_cm - 1.6;

            % B1 simulated range:
            x_sim_cm = linspace(-app.parameters.yrange_cm/2, ...
                                 app.parameters.yrange_cm/2, ...
                                 app.parameters.yrange_cm_npoints).';

            % interpolate
            b1Transmit_sim_log = interp1(x_b1_cm, b1profile_log, x_sim_cm, 'spline', 'extrap');
            b1Transmit_sim = exp(b1Transmit_sim_log) / max(exp(b1Transmit_sim_log ));

            % add 0s to the pulse:
            pulse_sim_G = [pulse_sim_G zeros(1, numel(pulse_sim_G))];

            % Bloch Simulation:
            mxy_x_b1 = zeros(numel(x_sim_cm), numel(pulse_sim_G), numel(freq));
            mz_x_b1 = zeros(numel(x_sim_cm), numel(pulse_sim_G), numel(freq));
            
            app.Simulate1D_Button.Text = 'Simulating!';
            app.Simulate1D_Button.FontAngle = 'italic';
            app.Simulate1D_Button.BackgroundColor = 'r';

            pause(0.00000001);
            
            % inhomogeneous B1
            if app.parameters.b1_map_loaded
                if sum(app.CButton.Value) % C13
                    for i = 1:numel(x_sim_cm)
                        [mxx, myx, mzx] = bloch(pulse_sim_G * b1Transmit_sim(i), gradient_G_cm_range_sim, dt_sim, t1, t2, freq, x_sim_cm(i), 2);
                        mxy_x_b1(i, :, :) = mxx + 1i* myx;
                        mz_x_b1(i, :, :) = mzx;
                    end
                else
                    for i = 1:numel(x_sim_cm)
                        [mxx, myx, mzx] = bloch(pulse_sim_G * b1Transmit_sim(i), gradient_G_cm_range_sim, dt_sim, t1, t2, freq, x_sim_cm(i), 2);
                        mxy_x_b1(i, :, :) = mxx + 1i* myx;
                        mz_x_b1(i, :, :) = mzx;
                    end
                end
            end
            % homogeneous B1
            mxy_x = zeros(numel(x_sim_cm), numel(pulse_sim_G), numel(freq));
            mz_x = zeros(numel(x_sim_cm), numel(pulse_sim_G), numel(freq));

            if sum(app.CButton.Value) % C13
                [mxx, myx, mzx] = bloch(pulse_sim_G, gradient_G_cm_range_sim, dt_sim, t1, t2, freq, x_sim_cm, 2);
                mxy_x = mxx + 1i* myx;
                mz_x = mzx;
            else
                [mxx, myx, mzx] = bloch(pulse_sim_G, gradient_G_cm_range_sim, dt_sim, t1, t2, freq, x_sim_cm, 2);
                mxy_x = mxx + 1i* myx;
                mz_x = mzx;
            end

            app.Simulate1D_Button.Text = 'Simulate!';
            app.Simulate1D_Button.FontAngle = 'normal';
            app.Simulate1D_Button.BackgroundColor = [0.96,0.96,0.96];
            
            % save simulations:
            app.data.mxy_b1 = mxy_x_b1;
            app.data.mz_b1 = mz_x_b1;

            % permute to match b1 mxy + b1 mz
            app.data.mxy = permute(mxy_x, [2 1 3]);  
            app.data.mz = permute(mz_x, [2 1 3]);  

            % plot simulated data:
            plot_b1profile(app);
            plot_rfpulse(app);
            
            app.repSlider.Enable = 'off';
            app.bssfpsignalCheckBox.Value = false;
            app.parameters.plot_bssfp_signal = app.bssfpsignalCheckBox.Value;
            if app.parameters.plot_bssfp_signal
                plot_bssfp_vs_freq(app, app.freqSlider.Value);
                plot_bssfp_vs_rep(app, round(app.repSlider) , round(app.freqSlider.Value));
            end

            try
                plot_mag_vs_dist(app, app.parameters.freq_ind);
            catch
                plot_mag_vs_dist(app, 1);
            end

        end

        % Value changed function: gradientstrength_khzcm_EditField
        function gradientstrength_khzcm_EditFieldValueChanged(app, event)
            value = app.gradientstrength_khzcm_EditField.Value;
            app.parameters.gradient_kHz_cm = value;
            calc_slice_thick(app);
        end

        % Value changed function: T1_sEditField
        function T1_sEditFieldValueChanged(app, event)
            value = str2double(app.T1_sEditField.Value);
            app.parameters.T1 = value;
        end

        % Value changed function: T2_sEditField
        function T2_sEditFieldValueChanged(app, event)
            value = str2double(app.T2_sEditField.Value);
            app.parameters.T2 = value;
        end

        % Value changed function: pulseduration_ms_EditField
        function pulseduration_ms_EditFieldValueChanged(app, event)
            value = app.pulseduration_ms_EditField.Value;
            app.parameters.pulse_duration_ms = value;
            % treat pulse duration as change in flipangle:
            calc_fa_b1_app(app, 1, 0);
            calc_pulse_duration_ms_res(app, 1);
            calc_slice_thick(app);
            plot_rfpulse(app);
        end

        % Value changed function: pulseduration_ms_npoints_EditField
        function pulseduration_ms_npoints_EditFieldValueChanged(app, event)
            value = str2double(app.pulseduration_ms_npoints_EditField.Value);
            app.parameters.pulse_duration_ms_npoints = value;
            calc_pulse_duration_ms_res(app, 1);
        end

        % Value changed function: yrange_cm_npoints_EditField
        function yrange_cm_npoints_EditFieldValueChanged(app, event)
            value = str2double(app.yrange_cm_npoints_EditField.Value);
            app.parameters.yrange_cm_npoints = value;
            calc_yrange_cm_res(app);
        end

        % Value changed function: freq_hz_npoints_EditField
        function freq_hz_npoints_EditFieldValueChanged(app, event)
            value = str2double(app.freq_hz_npoints_EditField.Value);
            app.parameters.freq_hz_npoints = value;
            calc_freq_hz_res(app);
            app.parameters.freq_ind = round(value/2);
            the_updater_func(app);
        end

        % Value changed function: freq_hz_high_EditField
        function freq_hz_high_EditFieldValueChanged(app, event)
            value = app.freq_hz_high_EditField.Value;
            app.parameters.freq_hz_high = value;
            calc_freq_hz_res(app);
        end

        % Value changed function: freq_hz_low_EditField
        function freq_hz_low_EditFieldValueChanged(app, event)
            value = app.freq_hz_low_EditField.Value;
            app.parameters.freq_hz_low = value;
            calc_freq_hz_res(app);

        end

        % Button pushed function: calcButton
        function calcButtonPushed(app, event)
            calc_sint(app);
            app.bwfac_HzsEditField.Value = app.parameters.bwfac_hz_ms;
            app.sint_EditField.Value = app.parameters.sint;
%             sint = app.
        end

        % Value changed function: sint_EditField
        function sint_EditFieldValueChanged(app, event)
            value = app.sint_EditField.Value;
            app.parameters.sint = value;            
        end

        % Value changed function: flipangle_degEditField
        function flipangle_degEditFieldValueChanged(app, event)
            value = app.flipangle_degEditField.Value;
            calc_fa_b1_app(app, 1, 0);
            app.parameters.alpha = value;
            plot_rfpulse(app);
            
        end

        % Value changing function: freqSlider
        function freqSliderValueChanging(app, event)
            changingValue = event.Value;
            freq = linspace(app.parameters.freq_hz_low, ...
                            app.parameters.freq_hz_high, ...
                            app.parameters.freq_hz_npoints); % Hz
            [~,ind] = min(abs(freq-changingValue));
            app.freqSlider.Value=freq(ind);
            freqSliderValueChanged(app, event)
        end

        % Value changed function: freqSlider
        function freqSliderValueChanged(app, event)
            value = app.freqSlider.Value;
            freq_range = linspace(app.parameters.freq_hz_low, ...
                            app.parameters.freq_hz_high, ...
                            app.parameters.freq_hz_npoints); % Hz
            [~,freq_ind] = min(abs(freq_range-value));
            % save index:
            app.parameters.freq_ind = freq_ind;
            app.freqSlider.Value = freq_range(freq_ind);
            freq = freq_range(freq_ind);
            % if option of plotting 2 frequencies is acitvated:
            if app.Show2FreqsCheckBox.Value
%                 freq_low  = value + app.Freq1EditField.Value;
%                 freq_high = value + app.Freq2EditField.Value;
%                 if ((freq_low < freq(1)) && ... % bad
%                     freq_high < freq(end)) % good
%                     % calc difference from limit to set value:
%                     app.Freq1EditField.Value = freq(1);
%                     app.Freq2EditField.Value = freq(1) + abs(freq_high-freq_low);
%                     
%                     % adjust 
%                 else
%                     app.Freq1EditField.Value = freq_low;
%                 end
%                 if (freq_high > freq(end))
%                     app.Freq2EditField.Value = freq(end);
%                 else
%                     app.Freq2EditField.Value = freq_high;
%                 end
            end


            % plot magnetization on this frequency:
            if app.parameters.plot_bssfp_signal
                try
                    plot_bssfp_vs_freq(app, freq);
                    plot_bssfp_vs_rep(app, round(app.repSlider.Value), freq);
                catch
                    warning('cant plot bssfp signal');
                end
            else
                plot_mag_vs_dist(app, freq );
            end
            try
                plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
            end
            
        end

        % Value changed function: Freq2EditField
        function Freq2EditFieldValueChanged(app, event)
            value = app.Freq2EditField.Value;
            if app.parameters.plot_bssfp_signal
                try
                    plot_bssfp_vs_freq(app, value);
                catch
                    warning('cant plot bssfp signal');
                end
            else
                plot_mag_vs_dist(app, value);
            end
            try
                plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
            end
        end

        % Value changed function: Freq1EditField
        function Freq1EditFieldValueChanged(app, event)
            value = app.Freq1EditField.Value;
            if app.parameters.plot_bssfp_signal
                try
                    plot_bssfp_vs_freq(app, value);
                catch
                    warning('cant plot bssfp signal');
                end
            else
                plot_mag_vs_dist(app, value);
            end
            try
                plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
            end
        end

        % Button pushed function: SelectAnatImage_Button
        function SelectAnatImage_ButtonPushed(app, event)
            try 
               pathname = uigetdir('D:\LRZ Sync+Share\phd\bSSFP\uniform excitation surface');
            catch
               pathname = uigetdir('');
            end
            try
                app.parameters.anatimage_path = pathname;
                app.AnatImageEditField.Value = pathname;
            end
        end

        % Button pushed function: LoadAnatImage_Button
        function LoadAnatImage_ButtonPushed(app, event)
             load_anatomical(app);
            % plot the anatomical image
            plot_anat_image(app, round(app.parameters.anat_nslices/2) , 1);
        end

        % Value changed function: ShowExcitationProfileCheckBox
        function ShowExcitationProfileCheckBoxValueChanged(app, event)
            value = app.ShowExcitationProfileCheckBox.Value;
            try
                plot_anat_image(app, app.parameters.anat_image_slice, value);
            end
        end

        % Value changed function: SliceSpinner
        function SliceSpinnerValueChanged(app, event)
            value = app.SliceSpinner.Value;
            if value > app.parameters.anat_nslices
                app.SliceSpinner.Value = app.parameters.anat_nslices;
                value = app.SliceSpinner.Value;
            end

            app.parameters.anat_image_slice = value;
            try
                plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
            end
        end

        % Value changed function: Show2FreqsCheckBox
        function Show2FreqsCheckBoxValueChanged(app, event)
            value = app.Show2FreqsCheckBox.Value;
            app.parameters.freq_diff = app.Freq2EditField.Value  - app.Freq1EditField.Value;
            if value
                app.freqSlider.Enable = "off";
            else
                app.freqSlider.Enable = "on";
            end
            freqSliderValueChanged(app);
        end

        % Value changed function: UseFrequenciesCheckBox
        function UseFrequenciesCheckBoxValueChanged(app, event)
            value = app.UseFrequenciesCheckBox.Value;
            if value % use frequencies
                app.Freq1_ppmEditField.Enable = 'off';
                app.Freq2_ppmEditField.Enable = 'off';
                app.BasicFreqEditField.Enable = 'off';

                app.Freq1EditField.Enable = 'on';
                app.Freq2EditField.Enable = 'on';
                plot_mag_vs_dist(app, app.parameters.freq_ind);
                plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
            else % use ppm
                app.Freq1EditField.Enable = 'off';
                app.Freq2EditField.Enable = 'off';

                app.Freq1_ppmEditField.Enable = 'on';
                app.Freq2_ppmEditField.Enable = 'on';
                app.BasicFreqEditField.Enable = 'on';

                app.parameters.freq_ppm2
                app.parameters.freq_ppm2
                
                
                plot_mag_vs_dist(app, app.parameters.freq_ind);
                plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
            end
            
        end

        % Value changed function: PulseFreqEditField
        function PulseFreqEditFieldValueChanged(app, event)
            value = app.PulseFreqEditField.Value;
            app.parameters.freq_hz_pulse = value;
            plot_mag_vs_dist(app, app.parameters.freq_hz_pulse);
            plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
        end

        % Value changed function: BasicFreqEditField
        function BasicFreqEditFieldValueChanged(app, event)
            value = app.BasicFreqEditField.Value;
            app.parameters.freq_ppm_basic = value;
            plot_mag_vs_dist(app, app.parameters.freq_ind);
            plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
        end

        % Value changed function: Freq2_ppmEditField
        function Freq2_ppmEditFieldValueChanged(app, event)
            value = app.Freq2_ppmEditField.Value;
            app.parameters.freq_ppm2 = value;
            plot_mag_vs_dist(app, app.parameters.freq_ind);
            plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
        end

        % Value changed function: Freq1_ppmEditField
        function Freq1_ppmEditFieldValueChanged(app, event)
            value = app.Freq1_ppmEditField.Value;
            app.parameters.freq_ppm1 = value;
            plot_mag_vs_dist(app, app.parameters.freq_ind);
            plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
        end

        % Value changed function: find_Gradients_lowEditField
        function find_Gradients_lowEditFieldValueChanged(app, event)
            app.parameters.find_grad_low = app.find_Gradients_lowEditField.Value;
        end

        % Value changed function: find_Gradients_highEditField
        function find_Gradients_highEditFieldValueChanged(app, event)
            app.parameters.find_grad_high = app.find_Gradients_highEditField.Value;
        end

        % Button pushed function: find_GradButton
        function find_GradButtonPushed(app, event)
            find_gradient_strength(app);
        end

        % Value changed function: find_Gradients_npointsEditField
        function find_Gradients_npointsEditFieldValueChanged(app, event)
            app.parameters.find_grad_npoints  = app.find_Gradients_npointsEditField.Value;
        end

        % Value changed function: repetition_time_msEditField
        function repetition_time_msEditFieldValueChanged(app, event)
            value = app.repetition_time_msEditField.Value;
            if (value <= app.parameters.pulse_duration_ms)
                value = app.parameters.pulse_duration_ms + 0.1;
                app.repetition_time_msEditField.Value = value;
            end
            app.parameters.repetitions_time_ms = value;
        end

        % Callback function
        function repetition_time_npointsEditFieldValueChanged(app, event)
            value = app.repetition_time_npointsEditField.Value;
            app.parameters.repetitions_time_ms_npoints = value;            
        end

        % Value changed function: nrepetitions_EditField
        function nrepetitions_EditFieldValueChanged(app, event)
            value = app.nrepetitions_EditField.Value;
            app.parameters.nrepetitions  = value;
            try
                app.repSlider.Enable = 'on';
                app.repSlider.Limits(2) = value;
            catch
                app.repSlider.Enable = 'off';
            end

        end

        % Button pushed function: SimulateRepsButton
        function SimulateRepsButtonPushed(app, event)
            app.repetition_time_npointsEditField.BackgroundColor = 'w';
            app.parameters.repetitions_time_ms = app.repetition_time_msEditField.Value;
            app.parameters.first_repetition_time_ms = app.first_repetition_time_msEditField.Value;
            results = calc_b1(app, app.parameters.alpha);
            app.parameters.b1_amp_khz = results.b1_amp_khz;
            simulate_repetitions(app);
            app.repSlider.Enable = 'on';
            app.bssfpsignalCheckBox.Value = true;
            app.parameters.plot_bssfp_signal = app.bssfpsignalCheckBox.Value;
            if app.parameters.plot_bssfp_signal
                try
                    plot_bssfp_vs_freq(app, app.freqSlider.Value);
                    plot_bssfp_vs_rep(app, round(app.repSlider) , round(app.freqSlider.Value));
                catch
                    warning('cant plot bssfp signal');
                end
            end
        end

        % Callback function
        function nrepetitions_EditFieldValueChanged2(app, event)
            value = app.nrepetitions_EditField.Value;
            app.parameters.nrepetitions = value;
            app.repetition_time_npointsEditField.BackgroundColor = 'w';
        end

        % Value changed function: find_Gradients_ind2EditField
        function find_Gradients_ind2EditFieldValueChanged(app, event)
            value = app.find_Gradients_ind2EditField.Value;
            if (value >= app.parameters.yrange_cm_npoints)
                app.parameters.find_grad_ind2 = app.parameters.yrange_cm_npoints;
            else
                app.parameters.find_grad_ind2 = value;
            end
            plot_mag_vs_dist(app, 1);
        end

        % Value changed function: find_Gradients_ind1EditField
        function find_Gradients_ind1EditFieldValueChanged(app, event)
            value = app.find_Gradients_ind1EditField.Value;
            if (value >= app.parameters.find_grad_ind2)
                app.parameters.find_grad_ind1 = app.parameters.find_grad_ind2;
            else
                app.parameters.find_grad_ind1 = value;
            end
            
            plot_mag_vs_dist(app, 1);
        end

        % Value changed function: repetition_time_npointsEditField
        function repetition_time_npointsEditFieldValueChanged2(app, event)
            value = app.repetition_time_npointsEditField.Value;
            app.parameters.repetitions_time_ms_npoints = value;
        end

        % Value changed function: HPEditField
        function HPEditFieldValueChanged(app, event)
            value = app.HPEditField.Value;
            app.parameters.sample_mag = value;            
        end

        % Value changed function: incphaseEditField
        function incphaseEditFieldValueChanged(app, event)
            value = app.incphaseEditField.Value;
            app.parameters.alternating_phase = value;            
        end

        % Value changed function: highresoutCheckBox
        function highresoutCheckBoxValueChanged(app, event)
            value = app.highresoutCheckBox.Value;
            app.parameters.save_all_sim_data = value ;
        end

        % Value changed function: amp_1stpulse_EditField
        function amp_1stpulse_EditFieldValueChanged(app, event)
            value = app.amp_1stpulse_EditField.Value;
            app.parameters.first_pulse_amp = value;
        end

        % Value changed function: inhomoB1ListBox
        function inhomoB1ListBoxValueChanged(app, event)
            value = app.inhomoB1ListBox.Value;
            if sum(strcmp(value, 'Mx'))
               app.parameters.plot_inhomo_mx  = 1;
            else
                app.parameters.plot_inhomo_mx  = 0;
            end
            if sum(strcmp(value, 'My'))
               app.parameters.plot_inhomo_my  = 1;
            else
                app.parameters.plot_inhomo_my  = 0;
            end
            if sum(strcmp(value, '|Mxy|'))
               app.parameters.plot_inhomo_mxy  = 1;
            else
                app.parameters.plot_inhomo_mxy  = 0;
            end
            if sum(strcmp(value, 'Mz'))
               app.parameters.plot_inhomo_mz  = 1;
            else
                app.parameters.plot_inhomo_mz  = 0;
            end
            if sum(strcmp(value, 'phi'))
               app.parameters.plot_inhomo_phase  = 1;
            else
                app.parameters.plot_inhomo_phase  = 0;
            end
            if sum(strcmp(value, 'none'))
               app.parameters.plot_inhomo_none = 1;
            else
                app.parameters.plot_inhomo_none = 0;
            end
            freq = app.freqSlider.Value;
            if app.parameters.plot_bssfp_signal
                try
                    plot_bssfp_vs_freq(app, freq);
                catch
                    warning('cant plot bssfp signal');
                end
            else
                plot_mag_vs_dist(app, freq);
            end
        end

        % Value changed function: homoB1ListBox
        function homoB1ListBoxValueChanged(app, event)
            value = app.homoB1ListBox.Value;
            if sum(strcmp(value, 'Mx'))
               app.parameters.plot_homo_mx  = 1;
            else
                app.parameters.plot_homo_mx  = 0;
            end
            if sum(strcmp(value, 'My'))
               app.parameters.plot_homo_my  = 1;
            else
                app.parameters.plot_homo_my  = 0;
            end
            if sum(strcmp(value, '|Mxy|'))
               app.parameters.plot_homo_mxy  = 1;
            else
                app.parameters.plot_homo_mxy  = 0;
            end
            if sum(strcmp(value, 'Mz'))
               app.parameters.plot_homo_mz  = 1;
            else
                app.parameters.plot_homo_mz  = 0;
            end
            if sum(strcmp(value, 'phi'))
               app.parameters.plot_homo_phase  = 1;
            else
                app.parameters.plot_homo_phase  = 0;
            end
            if sum(strcmp(value, 'none'))
               app.parameters.plot_homo_none = 1;
            else
                app.parameters.plot_homo_none = 0;
            end
            freq = app.freqSlider.Value;
            if app.parameters.plot_bssfp_signal
                try
                    plot_bssfp_vs_rep(app, round(app.repSlider.Value), freq);
                    plot_bssfp_vs_freq(app, freq);
                catch
                    warning('cant plot bssfp signal');
                end
            else
                plot_mag_vs_dist(app, freq);
            end
        end

        % Value changed function: b1_mag_khz_EditField
        function b1_mag_khz_EditFieldValueChanged(app, event)
            value = app.b1_mag_khz_EditField.Value;
            app.parameters.b1_amp_khz = value;
            calc_fa_b1_app(app, 0, 1);
        end

        % Value changed function: usemapCheckBox
        function usemapCheckBoxValueChanged(app, event)
            value = app.usemapCheckBox.Value;
            if (value == 1)
                calc_b1map(app);
                plot_b1map(app);
                app.refpow_map_caxis.Visible = 'on';
            else
                plot_b1profile(app);
                app.refpow_map_caxis.Visible = 'off';
            end
        end

        % Button down function: UIAxes_b1profile
        function UIAxes_b1profileButtonDown(app, event)
            pointer = get(gca, 'currentpoint');
            app.parameters.pointer2d = round(pointer(1, 1:2));
            if app.parameters.find_rfpower
                find_rfpower(app);
            end
        end

        % Value changed function: findRFPowerCheckBox
        function findRFPowerCheckBoxValueChanged(app, event)
            value = app.findRFPowerCheckBox.Value;
            app.parameters.find_rfpower = value;
        end

        % Button pushed function: calcrefpowButton
        function calcrefpowButtonPushed(app, event)
            calc_refpow(app);
        end

        % Value changed function: ymmEditField
        function ymmEditFieldValueChanged(app, event)
            value = app.ymmEditField.Value;
            app.parameters.refpow_pos_y_mm = value;
            calc_refpow(app);
            plot_b1map(app);
            try
                plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
            end
        end

        % Value changed function: xmmEditField
        function xmmEditFieldValueChanged(app, event)
            value = app.xmmEditField.Value;
            app.parameters.refpow_pos_x_mm = value;
            calc_refpow(app);
            plot_b1map(app);
            try
                plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value);
            end
        end

        % Value changed function: overlayrefpowCheckBox
        function overlayrefpowCheckBoxValueChanged(app, event)
            value = app.overlayrefpowCheckBox.Value;
            if value
                app.refpow_map_caxis_2.Visible = "on";
            else
                app.refpow_map_caxis_2.Visible = "off";
            end
            plot_anat_image(app, app.parameters.anat_image_slice, app.ShowExcitationProfileCheckBox.Value)
        end

        % Value changed function: refpow_map_caxis
        function refpow_map_caxisValueChanged(app, event)
            plot_b1map(app);
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            app.parameters.calc_refpow_outside_map = 1;
            calc_refpow(app);
            app.parameters.calc_refpow_outside_map = 0;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.saveButton.BackgroundColor = [205, 0, 0]/256;
            app.saveButton.Text = 'saving';
            pause(0.000001);
            success = save_data(app);
            
            if success
                app.saveButton.BackgroundColor = [0, 179, 0]/256;
                app.saveButton.Text = 'save';
            else
                app.saveButton.Text = 'saving failed';
            end
        end

        % Callback function
        function duration_refocus_gradValueChanged(app, event)
            value = app.duration_refocus_grad.Value;
            % if the refocus time of the gradient is shorter than half the
            % time that's left when you subtract the pulse from the
            % repetition time:
            if (value <= (app.parameters.repetitions_time_ms - app.parameters.pulse_duration_ms) /2)
                app.parameters.ref_grad_dur_ms = value;
            else
                value = (app.parameters.repetitions_time_ms - app.parameters.pulse_duration_ms) /2;
                app.parameters.ref_grad_dur_ms = value;
                app.duration_refocus_grad.Value = value;
            end
        end

        % Value changed function: metabsCheckBox
        function metabsCheckBoxValueChanged(app, event)
            value = app.metabsCheckBox.Value;
            if value
                app.AlterNTimesEditField.Enable = 'on';
                app.AlterNTimesLabel.Enable = 'on';
                app.alpha2ndPulseEditField.Enable = 'on';
                app.freq_2ndpulse_HzEditField.Enable = 'on';
                app.parameters.simulate_alternating_freqs = 'on';
                app.parameters.save_all_sim_data = 'off';
                app.alpha2prepCheckBox.Enable = 'on';
            else
                app.AlterNTimesEditField.Enable = 'off';
                app.AlterNTimesLabel.Enable = 'off';
                app.parameters.alternate_freq_Ntimes_2nd_freq = 1;
                app.AlterNTimesEditField.Value = 1;
    
                app.alpha2ndPulseEditField.Enable = 'off';
                app.freq_2ndpulse_HzEditField.Enable = 'off';
                app.parameters.simulate_alternating_freqs = 'off';
                app.alpha2prepCheckBox.Enable = 'off';
            end

        end

        % Value changed function: first_repetition_time_msEditField
        function first_repetition_time_msEditFieldValueChanged(app, event)
            value = app.first_repetition_time_msEditField.Value;
            if (value > app.parameters.pulse_duration_ms)
                app.parameters.first_repetition_time_ms = value;
            else
                disp(['Min time >= ' num2str(app.parameters.pulse_duration_ms) 'ms']);
                app.first_repetition_time_msEditField.Value = app.parameters.pulse_duration_ms;
                app.parameters.first_repetition_time_ms = app.parameters.pulse_duration_ms;
            end
            
%             app.parameters.simulate_alternating_freqs = 'off';
%             app.parameters.alpha_2ndfreq = app.alpha2ndPulseEditField.Value;
%             app.parameters.freq_2ndfreq_hz = app.freq_2ndpulse_HzEditField.Value;
%             app.parameters.change_freq_2nd_freq = app.AlterNTimesEditField.Value;
        end

        % Value changed function: alpha2ndPulseEditField
        function alpha2ndPulseEditFieldValueChanged(app, event)
            value = app.alpha2ndPulseEditField.Value;
            app.parameters.alpha_2ndfreq = value;           
        end

        % Value changed function: freq_2ndpulse_HzEditField
        function freq_2ndpulse_HzEditFieldValueChanged(app, event)
            value = app.freq_2ndpulse_HzEditField.Value;
            app.parameters.freq_2ndfreq_hz = value;
        end

        % Value changed function: AlterNTimesEditField
        function AlterNTimesEditFieldValueChanged(app, event)
            value = app.AlterNTimesEditField.Value;
            app.parameters.alternate_freq_Ntimes_2nd_freq = value;
        end

        % Value changed function: freq_1stpulse_HzEditField
        function freq_1stpulse_HzEditFieldValueChanged(app, event)
            value = app.freq_1stpulse_HzEditField.Value;
            app.parameters.freq_1stfreq_hz = value;
        end

        % Value changed function: SpoilerGradCheckBox
        function SpoilerGradCheckBoxValueChanged(app, event)
            value = app.SpoilerGradCheckBox.Value;
            
            if value
%                 app.TipbackCheckBox.Value = false;
%                 app.TipbackCheckBox.Enable = "off";
%                 app.parameters.tipback_pulse = "off";

                app.SpoilerGradCheckBox.Value = true;
%                 app.SpoilerGradCheckBox.Enable = "on";
                app.parameters.spoiler_grad = value;
            else
                app.TipbackCheckBox.Enable = "on";
%                 app.parameters.tipback_pulse = "off";

%                 app.SpoilerGradCheckBox.Value = false;
%                 app.SpoilerGradCheckBox.Enable = "off";
                app.parameters.spoiler_grad = value;
            end
        end

        % Button pushed function: PlotresultsButton
        function PlotresultsButtonPushed(app, event)
            plot_bssfp_signal(app);
        end

        % Value changed function: altphaseEditField
        function altphaseEditFieldValueChanged(app, event)
            value = app.altphaseEditField.Value;
            app.parameters.phase_pulse_deg = value;
        end

        % Button pushed function: startparpoolButton
        function startparpoolButtonPushed(app, event)
            app.startparpoolButton.BackgroundColor = 'cyan';
            p = gcp('nocreate'); % If no pool, do not create new one.
            if isempty(p)
                poolsize = 0;
                try
                    parpool();
                    fprintf('\nparpool started.\n');
                    app.startparpoolButton.BackgroundColor = 'green';
                catch
                    fprintf('\nparpool could not be started.\n');
                end
            else
                poolsize = p.NumWorkers;
                fprintf('\nparpool already running with %d workers\n', poolsize);
            end
            
        end

        % Button pushed function: reset_B1map_Button
        function reset_B1map_ButtonPushed(app, event)
            % delete B1 map:
            disp('');
            try
                app.parameters.b1Transmit_sim = ones(size(app.parameters.b1Transmit_sim));
            end
            try
                app.parameters.refpow_map_header = ones(size(app.parameters.refpow_map_header));
            end
            try
                app.data.refpow_map_calc = ones(size(app.data.refpow_map_calc));
            end
            try
                app.data.b1TransmitProfileFit_log = zeros(size(app.data.b1TransmitProfileFit_log));
            end
            try
                app.data.b1TransmitMap = [];
                app.data.b1TransmitProfileFit_log = [];
                app.data.refpow_map = [];
                
            end
            app.B1ProfileEditField.Value = '';
            app.parameters.b1_map_loaded = false;
            app.inhomoB1ListBox.Enable = "off";

            % plot
            plot_b1profile(app);

            
        end

        % Value changed function: TipbackCheckBox
        function TipbackCheckBoxValueChanged(app, event)
            value = app.TipbackCheckBox.Value;
            if value
                app.TipbackCheckBox.Value = value;
                app.TipbackCheckBox.Enable = "on";
                app.parameters.tipback_pulse = "on";

%                 app.SpoilerGradCheckBox.Value = false;
%                 app.SpoilerGradCheckBox.Enable = "off";
%                 app.parameters.spoiler_grad = 0;
            else
%                 app.TipbackCheckBox.Value = "off";
                app.parameters.tipback_pulse = "off";

%                 app.SpoilerGradCheckBox.Enable = "on";
            end
        end

        % Value changed function: alpha2prepCheckBox
        function alpha2prepCheckBoxValueChanged(app, event)
            value = app.alpha2prepCheckBox.Value;
            app.parameters.first_pulse_always = value;
        end

        % Value changed function: bssfpsignalCheckBox
        function bssfpsignalCheckBoxValueChanged(app, event)
            value = app.bssfpsignalCheckBox.Value;
            app.parameters.plot_bssfp_signal = value;
        end

        % Value changed function: repSlider
        function repSliderValueChanged(app, event)
            value = round(app.repSlider.Value);
            plot_bssfp_vs_rep(app, value, round(app.freqSlider.Value));
            plot_bssfp_vs_freq(app, app.freqSlider.Value);
        end

        % Button pushed function: SimRangeButton
        function SimRangeButtonPushed(app, event)
            % Simulate Range of values, might be useful for finding best
            % settings:
            try 
               pathname = uigetdir('D:\Data\Simulations\test multi sims\');
            catch
               pathname = uigetdir('');
            end
            app.SimulateRepsButton.BackgroundColor = 'r';
            app.SimulateRepsButton.Tooltip = 'Better restart the tool before using this!';
            app.parameters.save_multi_sim_path = pathname;
            disp('');
            if (app.FARange_NEditField.Value > 1)
                fa_range = linspace(app.FARange_1EditField.Value, ...
                                    app.FARange_2EditField.Value, ...
                                    app.FARange_NEditField.Value);
            else
                fa_range = app.FARange_1EditField.Value;
            end

            if (app.FARange_NEditField.Value > 1)
            tr_range = linspace(app.TRRange_1EditField.Value, ...
                                app.TRRange_2EditField.Value, ...
                                app.TRRange_NEditField.Value);
            else
                tr_range = app.TRRange_1EditField.Value;
            end

            if (app.TrfRange_NEditField.Value > 1)
            trf_range = linspace(app.TrfRange_1EditField.Value, ...
                                 app.TrfRange_2EditField.Value, ...
                                 app.TrfRange_NEditField.Value);
            else
                trf_range = app.TrfRange_1EditField.Value;
            end

            if (app.T1Range_NEditField.Value > 1)
            t1_range = linspace(app.T1Range_1EditField.Value, ...
                                app.T1Range_2EditField.Value, ...
                                app.T1Range_NEditField.Value);

            else
                t1_range = app.T1Range_1EditField.Value;
            end

            if (app.T2Range_NEditField.Value > 1)
            t2_range = linspace(app.T2Range_1EditField.Value, ...
                                app.T2Range_2EditField.Value, ...
                                app.T2Range_NEditField.Value);
            else
                t2_range = app.T2Range_1EditField.Value;
            end

            ntot = numel(fa_range) * numel(tr_range)* numel(t1_range)* numel(t2_range);
            app.parameters.multi_sim_n = 0;
            n=0;
            for ifa = 1:numel(fa_range)
            for itr = 1:numel(tr_range)
            for itrf =1:numel(trf_range)
            for it1 = 1:numel(t1_range)
            for it2 = 1:numel(t2_range)
                fa = fa_range(ifa);
                tr = tr_range(itr);
                trf = trf_range(itrf);
                t1 = t1_range(it1);
                t2 = t2_range(it2);
                % if first TR = TR/2
                if app.TR2CheckBox.Value
                    first_tr = tr/2;
                    first_tr_fac = 0.5;
                else
                    first_tr = tr;
                    first_tr_fac =1;
                end
                if app.a2CheckBox.Value
                    first_pulse_fac = 0.5;
                else
                    first_pulse_fac = 1;
                end
            if ((t1 > t2) && (tr > trf) && (first_tr > trf))
                app.parameters.fa_range_val = fa_range(ifa);
                app.parameters.tr_range_val = tr_range(itr);
                app.parameters.t1_range_val = t1_range(it1);
                app.parameters.t2_range_val = t2_range(it2);
                app.parameters.trf_range_val = trf_range(itrf);
                % for now overwrite the set parameters:
                
                app.parameters.T1 = t1_range(it1);
                app.parameters.repetitions_time_ms = tr_range(itr);
                app.parameters.first_repetition_time_ms = app.parameters.repetitions_time_ms * first_tr_fac;
                app.parameters.T2 = t2_range(it2);
                app.parameters.first_pulse_amp = first_pulse_fac;
                app.parameters.alpha = fa_range(ifa);
                app.parameters.pulse_duration_ms = trf_range(itrf);
                calc_pulse_duration_ms_res(app, 1);
                calc_slice_thick(app);
                results = calc_b1(app, app.parameters.alpha);
                app.parameters.b1_amp_khz = results.b1_amp_khz;

                n=n+1;
                app.parameters.multi_sim_n = n;
                simulate_repetitions(app);
            end
            end
            end
            end
            end
            end
        end

        % Value changed function: simrangeCheckBox
        function simrangeCheckBoxValueChanged(app, event)
            value = app.simrangeCheckBox.Value;
            app.parameters.simulate_range_of_vals = value;
        end

        % Value changed function: TrfRange_NEditField
        function TrfRange_NEditFieldValueChanged(app, event)
            value = app.TrfRange_NEditField.Value;
            if value == 1
                app.TrfRange_2EditField.Enable = "off";
            else
                app.TrfRange_2EditField.Enable = "on";
            end
            
        end

        % Value changed function: FARange_NEditField
        function FARange_NEditFieldValueChanged(app, event)
            value = app.FARange_NEditField.Value;
            if value == 1
                app.FARange_2EditField.Enable = "off";
            else
                app.FARange_2EditField.Enable = "on";
            end
        end

        % Value changed function: TRRange_NEditField
        function TRRange_NEditFieldValueChanged(app, event)
            value = app.TRRange_NEditField.Value;
            if value == 1
                app.TRRange_2EditField.Enable = "off";
            else
                app.TRRange_2EditField.Enable = "on";
            end
        end

        % Value changed function: T2Range_NEditField
        function T2Range_NEditFieldValueChanged(app, event)
            value = app.T2Range_NEditField.Value;
            if value == 1
                app.T2Range_2EditField.Enable = "off";
            else
                app.T2Range_2EditField.Enable = "on";
            end
        end

        % Value changed function: T1Range_NEditField
        function T1Range_NEditFieldValueChanged(app, event)
            value = app.T1Range_NEditField.Value;
            if value == 1
                app.T1Range_2EditField.Enable = "off";
            else
                app.T1Range_2EditField.Enable = "on";
            end
        end

        % Value changed function: phasetbEditField
        function phasetbEditFieldValueChanged(app, event)
            value = app.phasetbEditField.Value;
            app.parameters.phase_tipback_pulse = value;
        end

        % Value changed function: TR2CheckBox
        function TR2CheckBoxValueChanged(app, event)
            value = app.TR2CheckBox.Value;
        end

        % Value changed function: a2CheckBox
        function a2CheckBoxValueChanged(app, event)
            value = app.a2CheckBox.Value;
            
        end

        % Button pushed function: Simulate2D_Button
        function Simulate2D_ButtonPushed(app, event)
            simulate_2D(app);
        end

        % Button pushed function: SelectGradient_Button
        function SelectGradient_ButtonPushed(app, event)
            try 
               [filename, pathname] = uigetfile('*', 'Select Gradient ', 'D:\LRZ Sync+Share\phd\bSSFP\uniform excitation surface');
            catch
               [filename, pathname] = uigetfile('');
            end
            try
                app.parameters.gradient_file = [pathname filename];
                app.GradientEditField.Value = filename;
                app.parameters.rfpulse_file_name = filename;
                app.parameters.rfpulse_file_path = pathname;
            end
            figure(app.calc_rf_pulse_toolUIFigure);
        end

        % Button pushed function: LoadGradient_Button
        function LoadGradient_ButtonPushed(app, event)
            % load rf pulse:
            load_gradient(app);
            
            % calculate integration factor
            %calc_sint(app);
            % calculate slice thickness:
            %calc_slice_thick(app);
            
            % plot the pulse
            figure(app.calc_rf_pulse_toolUIFigure);
            plot_rfpulse(app);
        end

        % Value changed function: pulseduration2D_ms_EditField
        function pulseduration2D_ms_EditFieldValueChanged(app, event)
            value = app.pulseduration2D_ms_EditField.Value;
            app.parameters.pulse_duration2D_ms = value;
            % treat pulse duration as change in flipangle:
            calc_fa_b1_app(app, 1, 0);
            calc_pulse_duration_ms_res(app, 2);
            calc_slice_thick(app);
            plot_rfpulse(app);
            
        end

        % Value changed function: pulseduration2D_ms_npoints_EditField
        function pulseduration2D_ms_npoints_EditFieldValueChanged(app, event)
            value = str2double(app.pulseduration2D_ms_npoints_EditField.Value);
            app.parameters.pulse_duration_ms_npoints = value;
            calc_pulse_duration_ms_res(app, 2);
        end

        % Value changed function: gradientduration2D_ms_EditField
        function gradientduration2D_ms_EditFieldValueChanged(app, event)
            value = app.gradientduration2D_ms_EditField.Value;
            app.parameters.gradientduration2D_ms = value;            
        end

        % Value changed function: gradientstrength2D_khzcm_EditField
        function gradientstrength2D_khzcm_EditFieldValueChanged(app, event)
            value = app.gradientstrength2D_khzcm_EditField.Value;
            app.parameters.gradientstrength2D_khzcm = value;            
        end

        % Value changed function: xrange2D_cm_EditField
        function xrange2D_cm_EditFieldValueChanged(app, event)
            value = app.xrange2D_cm_EditField.Value;
            app.parameters.xrange2D_cm = value;            
        end

        % Value changed function: xrange2D_cm_npoints_EditField
        function xrange2D_cm_npoints_EditFieldValueChanged(app, event)
            value = str2double(app.xrange2D_cm_npoints_EditField.Value);
            app.parameters.xrange2D_cm_npoints = value;
        end

        % Value changed function: yrange2D_cm_EditField
        function yrange2D_cm_EditFieldValueChanged(app, event)
            value = app.yrange2D_cm_EditField.Value;
            app.parameters.yrange2D_cm = value;
        end

        % Value changed function: yrange2D_cm_npoints_EditField
        function yrange2D_cm_npoints_EditFieldValueChanged(app, event)
            value = str2double(app.yrange2D_cm_npoints_EditField.Value);
            app.parameters.yrange2D_cm_npoints = value;
        end

        % Value changed function: freq2D_hz_low_EditField
        function freq2D_hz_low_EditFieldValueChanged(app, event)
            value = app.freq2D_hz_low_EditField.Value;
            app.parameters.freq2D_hz_low = value;
        end

        % Value changed function: freq2D_hz_high_EditField
        function freq2D_hz_high_EditFieldValueChanged(app, event)
            value = app.freq2D_hz_high_EditField.Value;
            app.parameters.freq2D_hz_high = value;
        end

        % Value changed function: freq2D_hz_npoints_EditField
        function freq2D_hz_npoints_EditFieldValueChanged(app, event)
            value = str2double(app.freq2D_hz_npoints_EditField.Value);
            app.parameters.freq2D_hz_npoints = value;
        end

        % Value changed function: gradientduration2D_ms_npoints_EditField
        function gradientduration2D_ms_npoints_EditFieldValueChanged(app, event)
            value = str2double(app.gradientduration2D_ms_npoints_EditField.Value);
            app.parameters.gradientduration2D_ms_npoints = value;
        end

        % Value changed function: B1_is_cryo_CheckBox
        function B1_is_cryo_CheckBoxValueChanged(app, event)
            value = app.B1_is_cryo_CheckBox.Value;
            app.parameters.B1_is_cryo = value;
        end

        % Button pushed function: reset_Gradients_Button
        function reset_Gradients_ButtonPushed(app, event)
            % delete Gradients:
            disp('');
            try
                app.data.gradient = [];               
            end
            app.GradientEditField.Value = '';
        end

        % Value changed function: refpow_map_caxis_2
        function refpow_map_caxis_2ValueChanged(app, event)
            value = app.refpow_map_caxis_2.Value;
            show_exc_profile = app.overlayrefpowCheckBox.Value;
            plot_anat_image(app, app.parameters.anat_image_slice, show_exc_profile);
        end

        % Value changing function: repSlider
        function repSliderValueChanging(app, event)
            changingValue = event.Value;
            % live plot:
            repSliderValueChanged(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.calc_rf_pulse_toolUIFigure = uifigure('Visible', 'off');
            app.calc_rf_pulse_toolUIFigure.Name = 'RF pulse simulator';
            app.calc_rf_pulse_toolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.calc_rf_pulse_toolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RFPulseEditFieldLabel = uilabel(app.LeftPanel);
            app.RFPulseEditFieldLabel.Text = 'RF Pulse';
            app.RFPulseEditField = uieditfield(app.LeftPanel, 'text');
            app.RFPulseEditField.Tag = 'rfpulse_Button';
            app.RFPulseEditField.Value = 'path to RF pulse ...';
            app.B1ProfileEditFieldLabel = uilabel(app.LeftPanel);
            app.B1ProfileEditFieldLabel.Text = 'B1 Profile';
            app.B1ProfileEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectRFPulse_Button = uibutton(app.LeftPanel, 'push');
            app.SelectRFPulse_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectRFPulse_ButtonPushed, true);
            app.SelectRFPulse_Button.Tag = 'selectRF_Button';
            app.SelectRFPulse_Button.Text = 'Select';
            app.LoadRFPulse_Button = uibutton(app.LeftPanel, 'push');
            app.LoadRFPulse_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadRFPulse_ButtonPushed, true);
            app.LoadRFPulse_Button.Tag = 'loadRF_Button';
            app.LoadRFPulse_Button.Text = 'Load';
            app.SelectB1Profile_Button = uibutton(app.LeftPanel, 'push');
            app.SelectB1Profile_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectB1Profile_ButtonPushed, true);
            app.SelectB1Profile_Button.Text = 'Select';
            app.LoadB1Profile_Button = uibutton(app.LeftPanel, 'push');
            app.LoadB1Profile_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadB1Profile_ButtonPushed, true);
            app.LoadB1Profile_Button.Text = 'Load';
            app.alphaLabel = uilabel(app.LeftPanel);
            app.alphaLabel.Text = 'alpha [°]';
            app.flipangle_degEditField = uieditfield(app.LeftPanel, 'numeric');
            app.flipangle_degEditField.Limits = [0 Inf];
            app.flipangle_degEditField.ValueChangedFcn = createCallbackFcn(app, @flipangle_degEditFieldValueChanged, true);
            app.flipangle_degEditField.Tag = 'pulse_duration_Edit';
            app.flipangle_degEditField.Value = 10;
            app.alphaLabel_2 = uilabel(app.LeftPanel);
            app.alphaLabel_2.Text = 'BWfac [Hz s]';
            app.sint_EditField = uieditfield(app.LeftPanel, 'numeric');
            app.sint_EditField.Limits = [0.001 100];
            app.sint_EditField.ValueChangedFcn = createCallbackFcn(app, @sint_EditFieldValueChanged, true);
            app.sint_EditField.Tag = 'pulse_duration_Edit';
            app.sint_EditField.Value = 0.2518;
            app.bwfac_HzsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bwfac_HzsEditField.Limits = [0.001 Inf];
            app.bwfac_HzsEditField.Tag = 'pulse_duration_Edit';
            app.bwfac_HzsEditField.Value = 3972;
            app.alphaLabel_3 = uilabel(app.LeftPanel);
            app.alphaLabel_3.Text = 'Sint';
            app.AnatImageEditFieldLabel = uilabel(app.LeftPanel);
            app.AnatImageEditFieldLabel.Text = 'Anat Image';
            app.AnatImageEditField = uieditfield(app.LeftPanel, 'text');
            app.LoadAnatImage_Button = uibutton(app.LeftPanel, 'push');
            app.LoadAnatImage_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadAnatImage_ButtonPushed, true);
            app.LoadAnatImage_Button.Text = 'Load';
            app.calcButton = uibutton(app.LeftPanel, 'push');
            app.calcButton.ButtonPushedFcn = createCallbackFcn(app, @calcButtonPushed, true);
            app.calcButton.Text = 'calc !';
            app.SelectAnatImage_Button = uibutton(app.LeftPanel, 'push');
            app.SelectAnatImage_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectAnatImage_ButtonPushed, true);
            app.SelectAnatImage_Button.Text = 'Select';
            app.SamplePanel = uipanel(app.LeftPanel);
            app.SamplePanel.Title = 'Sample';
            app.NucleusButtonGroup = uibuttongroup(app.SamplePanel);
            app.NucleusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NucleusButtonGroupSelectionChanged, true);
            app.NucleusButtonGroup.Enable = 'off';
            app.NucleusButtonGroup.Title = 'Nucleus';
            app.NucleusButtonGroup.Visible = 'off';
            app.NucleusButtonGroup.Tag = 'nuc_select_Buttongroup';
            app.HButton = uiradiobutton(app.NucleusButtonGroup);
            app.HButton.Tag = 'HButton';
            app.HButton.Text = {'1H'; ''};
            app.HButton.Value = true;
            app.CButton = uiradiobutton(app.NucleusButtonGroup);
            app.CButton.Tag = 'HButton';
            app.CButton.Text = '13C';
            app.T2sEditFieldLabel = uilabel(app.SamplePanel);
            app.T2sEditFieldLabel.Text = 'T2 [s]';
            app.HPEditFieldLabel = uilabel(app.SamplePanel);
            app.HPEditFieldLabel.Text = 'HP';
            app.HPEditField = uieditfield(app.SamplePanel, 'numeric');
            app.HPEditField.ValueChangedFcn = createCallbackFcn(app, @HPEditFieldValueChanged, true);
            app.HPEditField.Tooltip = {'Polarization Level'};
            app.HPEditField.Value = 1;
            app.T2_sEditField = uieditfield(app.SamplePanel, 'text');
            app.T2_sEditField.ValueChangedFcn = createCallbackFcn(app, @T2_sEditFieldValueChanged, true);
            app.T2_sEditField.Value = '0.1';
            app.T1sEditFieldLabel = uilabel(app.SamplePanel);
            app.T1sEditFieldLabel.Text = 'T1 [s]';
            app.T1_sEditField = uieditfield(app.SamplePanel, 'text');
            app.T1_sEditField.ValueChangedFcn = createCallbackFcn(app, @T1_sEditFieldValueChanged, true);
            app.T1_sEditField.Value = '2';
            app.alphaLabel_4 = uilabel(app.LeftPanel);
            app.alphaLabel_4.Text = 'B1 [kHz]';
            app.b1_mag_khz_EditField = uieditfield(app.LeftPanel, 'numeric');
            app.b1_mag_khz_EditField.ValueChangedFcn = createCallbackFcn(app, @b1_mag_khz_EditFieldValueChanged, true);
            app.b1_mag_khz_EditField.Tag = 'pulse_duration_Edit';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.findGradTab = uitab(app.TabGroup);
            app.findGradTab.Title = 'find Grad';
            app.findGradientstrengthforB1compensationPanel = uipanel(app.findGradTab);
            app.findGradientstrengthforB1compensationPanel.Title = 'find Gradient strength for B1 compensation:';
            app.find_Gradients_lowEditField = uieditfield(app.findGradientstrengthforB1compensationPanel, 'numeric');
            app.find_Gradients_lowEditField.ValueChangedFcn = createCallbackFcn(app, @find_Gradients_lowEditFieldValueChanged, true);
            app.find_Gradients_lowEditField.Tag = 'grad_strength_Edit';
            app.find_Gradients_highEditField = uieditfield(app.findGradientstrengthforB1compensationPanel, 'numeric');
            app.find_Gradients_highEditField.ValueChangedFcn = createCallbackFcn(app, @find_Gradients_highEditFieldValueChanged, true);
            app.find_Gradients_highEditField.Tag = 'grad_strength_Edit';
            app.find_Gradients_highEditField.Value = 1;
            app.find_Gradients_npointsEditField = uieditfield(app.findGradientstrengthforB1compensationPanel, 'numeric');
            app.find_Gradients_npointsEditField.ValueChangedFcn = createCallbackFcn(app, @find_Gradients_npointsEditFieldValueChanged, true);
            app.find_Gradients_npointsEditField.Value = 11;
            app.BestGradEditFieldLabel = uilabel(app.findGradientstrengthforB1compensationPanel);
            app.BestGradEditFieldLabel.Text = 'Best Grad';
            app.BestGradEditField = uieditfield(app.findGradientstrengthforB1compensationPanel, 'numeric');
            app.BestGradEditField.ValueDisplayFormat = '%11.7g';
            app.BestGradEditField.Editable = 'off';
            app.find_Gradients_ind1EditField = uieditfield(app.findGradientstrengthforB1compensationPanel, 'numeric');
            app.find_Gradients_ind1EditField.Limits = [1 Inf];
            app.find_Gradients_ind1EditField.ValueChangedFcn = createCallbackFcn(app, @find_Gradients_ind1EditFieldValueChanged, true);
            app.find_Gradients_ind1EditField.Value = 1;
            app.find_Gradients_ind2EditField = uieditfield(app.findGradientstrengthforB1compensationPanel, 'numeric');
            app.find_Gradients_ind2EditField.Limits = [2 Inf];
            app.find_Gradients_ind2EditField.ValueChangedFcn = createCallbackFcn(app, @find_Gradients_ind2EditFieldValueChanged, true);
            app.find_Gradients_ind2EditField.Value = 16;
            app.G1kHzcmLabel = uilabel(app.findGradientstrengthforB1compensationPanel);
            app.G1kHzcmLabel.Text = 'G1 [kHz/cm]';
            app.G2kHzcmLabel = uilabel(app.findGradientstrengthforB1compensationPanel);
            app.G2kHzcmLabel.Text = 'G2 [kHz/cm]';
            app.NpointsLabel_2 = uilabel(app.findGradientstrengthforB1compensationPanel);
            app.NpointsLabel_2.Text = 'N [points]';
            app.pointsyLabel = uilabel(app.findGradientstrengthforB1compensationPanel);
            app.pointsyLabel.Text = 'points [y]';
            app.progress_grad_findLabel = uilabel(app.findGradientstrengthforB1compensationPanel);
            app.progress_grad_findLabel.Text = '';
            app.find_GradButton = uibutton(app.findGradTab, 'push');
            app.find_GradButton.ButtonPushedFcn = createCallbackFcn(app, @find_GradButtonPushed, true);
            app.find_GradButton.Tag = 'simulate_Button';
            app.find_GradButton.Text = 'find Grad !';
            app.RFpowerTab = uitab(app.TabGroup);
            app.RFpowerTab.Title = 'RF power';
            app.usemapCheckBox = uicheckbox(app.RFpowerTab);
            app.usemapCheckBox.ValueChangedFcn = createCallbackFcn(app, @usemapCheckBoxValueChanged, true);
            app.usemapCheckBox.Text = 'use map';
            app.RFPowerWEditField = uieditfield(app.RFpowerTab, 'numeric');
            app.RFPowerLabel = uilabel(app.RFpowerTab);
            app.RFPowerLabel.Text = 'RF Power';
            app.RFPowerWEditFieldLabel = uilabel(app.RFpowerTab);
            app.RFPowerWEditFieldLabel.Text = 'RF Power [W]';
            app.findRFPowerCheckBox = uicheckbox(app.RFpowerTab);
            app.findRFPowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @findRFPowerCheckBoxValueChanged, true);
            app.findRFPowerCheckBox.Text = 'find RF Power';
            app.ymmEditFieldLabel = uilabel(app.RFpowerTab);
            app.ymmEditFieldLabel.Text = 'y [mm]';
            app.ymmEditField = uieditfield(app.RFpowerTab, 'numeric');
            app.ymmEditField.ValueChangedFcn = createCallbackFcn(app, @ymmEditFieldValueChanged, true);
            app.calcrefpowButton = uibutton(app.RFpowerTab, 'push');
            app.calcrefpowButton.ButtonPushedFcn = createCallbackFcn(app, @calcrefpowButtonPushed, true);
            app.calcrefpowButton.Text = 'calc refpow';
            app.RefpowLabel = uilabel(app.RFpowerTab);
            app.RefpowLabel.Text = 'Refpow';
            app.xmmEditFieldLabel = uilabel(app.RFpowerTab);
            app.xmmEditFieldLabel.Text = 'x [mm]';
            app.xmmEditField = uieditfield(app.RFpowerTab, 'numeric');
            app.xmmEditField.ValueChangedFcn = createCallbackFcn(app, @xmmEditFieldValueChanged, true);
            app.overlayrefpowCheckBox = uicheckbox(app.RFpowerTab);
            app.overlayrefpowCheckBox.ValueChangedFcn = createCallbackFcn(app, @overlayrefpowCheckBoxValueChanged, true);
            app.overlayrefpowCheckBox.Text = 'overlay refpow';
            app.WButton = uibutton(app.RFpowerTab, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = '0W';
            app.saveTab = uitab(app.TabGroup);
            app.saveTab.Title = 'save';
            app.saveoptions_ListBox = uilistbox(app.saveTab);
            app.saveoptions_ListBox.Items = {'homo', 'inhomo', 'time', 'freqs', 'dist', 'reps', 'single', 'plots'};
            app.saveoptions_ListBox.Multiselect = 'on';
            app.saveoptions_ListBox.Tooltip = {'You can use "Ctrl" to (un-) select options. For now it''s recommended to just select all the options'};
            app.saveoptions_ListBox.Value = {'reps'};
            app.repEditFieldLabel = uilabel(app.saveTab);
            app.repEditFieldLabel.Text = 'rep';
            app.repEditField_low = uieditfield(app.saveTab, 'numeric');
            app.repEditField_low.Limits = [1 Inf];
            app.repEditField_low.Value = 1;
            app.repEditField_high = uieditfield(app.saveTab, 'numeric');
            app.repEditField_high.Limits = [1 Inf];
            app.repEditField_high.Value = 1;
            app.saveButton = uibutton(app.saveTab, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.fileEditFieldLabel = uilabel(app.saveTab);
            app.fileEditFieldLabel.Text = 'file';
            app.save_fileEditField = uieditfield(app.saveTab, 'text');
            app.nslices = uilabel(app.LeftPanel);
            app.nslices.Text = 'nslices';
            app.nslices_2 = uilabel(app.LeftPanel);
            app.nslices_2.Text = {'mat'; ''};
            app.nslices_3 = uilabel(app.LeftPanel);
            app.nslices_3.Text = 'offset';
            app.SimulateRepetitionsTabGroup = uitabgroup(app.LeftPanel);
            app.BasicParameterTab = uitab(app.SimulateRepetitionsTabGroup);
            app.BasicParameterTab.Title = 'Basic parameters';
            app.PlotresultsButton = uibutton(app.BasicParameterTab, 'push');
            app.PlotresultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotresultsButtonPushed, true);
            app.PlotresultsButton.Tooltip = {'plot the simulation results again'};
            app.PlotresultsButton.Text = 'Plot results';
            app.TipbackCheckBox = uicheckbox(app.BasicParameterTab);
            app.TipbackCheckBox.ValueChangedFcn = createCallbackFcn(app, @TipbackCheckBoxValueChanged, true);
            app.TipbackCheckBox.Tooltip = {'Tips the Magnetization after N repetitions back to the z-axis'};
            app.TipbackCheckBox.Text = 'Tipback';
            app.SpoilerGradCheckBox = uicheckbox(app.BasicParameterTab);
            app.SpoilerGradCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpoilerGradCheckBoxValueChanged, true);
            app.SpoilerGradCheckBox.Tooltip = {'"Spoil" Mxy Magnetization after N repetitions'};
            app.SpoilerGradCheckBox.Text = 'Spoiler Grad';
            app.incphaseEditField = uieditfield(app.BasicParameterTab, 'numeric');
            app.incphaseEditField.ValueChangedFcn = createCallbackFcn(app, @incphaseEditFieldValueChanged, true);
            app.incphaseEditField.Value = 180;
            app.first_repetition_time_msEditField = uieditfield(app.BasicParameterTab, 'numeric');
            app.first_repetition_time_msEditField.Limits = [0.001 100];
            app.first_repetition_time_msEditField.ValueChangedFcn = createCallbackFcn(app, @first_repetition_time_msEditFieldValueChanged, true);
            app.first_repetition_time_msEditField.Tag = 'grad_strength_Edit';
            app.first_repetition_time_msEditField.Tooltip = {'Time between first and second pulse (peak to peak).'};
            app.first_repetition_time_msEditField.Value = 3;
            app.TRmsLabel = uilabel(app.BasicParameterTab);
            app.TRmsLabel.Text = 'TR [ms]';
            app.NpointsLabel = uilabel(app.BasicParameterTab);
            app.NpointsLabel.Text = 'N [points]';
            app.NRepsLabel = uilabel(app.BasicParameterTab);
            app.NRepsLabel.Text = 'N [Reps]';
            app.incphaseLabel = uilabel(app.BasicParameterTab);
            app.incphaseLabel.Text = 'inc. phase [°]';
            app.progress_simRepLabel = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel.Text = '';
            app.progress_simRepLabel_2 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_2.Text = '';
            app.progress_simRepLabel_3 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_3.Text = '';
            app.progress_simRepLabel_4 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_4.Text = '';
            app.progress_simRepLabel_5 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_5.Text = '';
            app.progress_simRepLabel_6 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_6.Text = '';
            app.progress_simRepLabel_7 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_7.Text = '';
            app.progress_simRepLabel_8 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_8.Text = '';
            app.progress_simRepLabel_9 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_9.Text = '';
            app.progress_simRepLabel_10 = uilabel(app.BasicParameterTab);
            app.progress_simRepLabel_10.Text = '';
            app.FirstpulseampEditFieldLabel = uilabel(app.BasicParameterTab);
            app.FirstpulseampEditFieldLabel.Text = 'First pulse amp';
            app.freq_1stpulse_EditFieldLabel = uilabel(app.BasicParameterTab);
            app.freq_1stpulse_EditFieldLabel.Tooltip = {'Frequency 1st pulse'};
            app.freq_1stpulse_EditFieldLabel.Text = 'f [Hz]';
            app.freq_1stpulse_HzEditField = uieditfield(app.BasicParameterTab, 'numeric');
            app.freq_1stpulse_HzEditField.ValueChangedFcn = createCallbackFcn(app, @freq_1stpulse_HzEditFieldValueChanged, true);
            app.freq_1stpulse_HzEditField.Tooltip = {'Frequency offset.'};
            app.amp_1stpulse_EditField = uieditfield(app.BasicParameterTab, 'numeric');
            app.amp_1stpulse_EditField.ValueChangedFcn = createCallbackFcn(app, @amp_1stpulse_EditFieldValueChanged, true);
            app.amp_1stpulse_EditField.Tooltip = {'Amplitude of the first pulse (0 - 1)'};
            app.amp_1stpulse_EditField.Value = 0.5;
            app.first_repetition_time_msLabel = uilabel(app.BasicParameterTab);
            app.first_repetition_time_msLabel.Text = '1st TR [ms]';
            app.nrepetitions_EditField = uieditfield(app.BasicParameterTab, 'numeric');
            app.nrepetitions_EditField.Limits = [1 Inf];
            app.nrepetitions_EditField.ValueChangedFcn = createCallbackFcn(app, @nrepetitions_EditFieldValueChanged, true);
            app.nrepetitions_EditField.Tooltip = {'Number of phase encodes'};
            app.nrepetitions_EditField.Value = 100;
            app.repetition_time_npointsEditField = uieditfield(app.BasicParameterTab, 'numeric');
            app.repetition_time_npointsEditField.Limits = [1 Inf];
            app.repetition_time_npointsEditField.ValueChangedFcn = createCallbackFcn(app, @repetition_time_npointsEditFieldValueChanged2, true);
            app.repetition_time_npointsEditField.Tooltip = {'Number of points that the go into the Bloch Simulation.'};
            app.repetition_time_npointsEditField.Value = 514;
            app.repetition_time_msEditField = uieditfield(app.BasicParameterTab, 'numeric');
            app.repetition_time_msEditField.Limits = [0.001 100];
            app.repetition_time_msEditField.ValueChangedFcn = createCallbackFcn(app, @repetition_time_msEditFieldValueChanged, true);
            app.repetition_time_msEditField.Tag = 'grad_strength_Edit';
            app.repetition_time_msEditField.Tooltip = {'Repetition Time'};
            app.repetition_time_msEditField.Value = 6;
            app.AdvParameterTab = uitab(app.SimulateRepetitionsTabGroup);
            app.AdvParameterTab.Title = 'Adv. parameters';
            app.duration_refocus_grad = uieditfield(app.AdvParameterTab, 'numeric');
            app.duration_refocus_grad.Limits = [0 100];
            app.duration_refocus_grad.Tag = 'grad_strength_Edit';
            app.duration_refocus_grad.Tooltip = {'duration of the slice refphasing gradient'};
            app.TRGmsLabel = uilabel(app.AdvParameterTab);
            app.TRGmsLabel.Text = 'TRG [ms]';
            app.alphaEditFieldLabel = uilabel(app.AdvParameterTab);
            app.alphaEditFieldLabel.Enable = 'off';
            app.alphaEditFieldLabel.Tooltip = {'Simulate 2 metabolites (change excitation frequency after X repetitions, N times)'};
            app.alphaEditFieldLabel.Text = 'alpha [°]';
            app.alpha2ndPulseEditField = uieditfield(app.AdvParameterTab, 'numeric');
            app.alpha2ndPulseEditField.ValueChangedFcn = createCallbackFcn(app, @alpha2ndPulseEditFieldValueChanged, true);
            app.alpha2ndPulseEditField.Enable = 'off';
            app.alpha2ndPulseEditField.Tooltip = {'Flipangle of  the 2nd pulse'};
            app.alpha2ndPulseEditField.Value = 10;
            app.metabsCheckBox = uicheckbox(app.AdvParameterTab);
            app.metabsCheckBox.ValueChangedFcn = createCallbackFcn(app, @metabsCheckBoxValueChanged, true);
            app.metabsCheckBox.Tooltip = {'Simulate 2 metabolites (change excitation frequency after X repetitions, N times)'};
            app.metabsCheckBox.Text = '2 metabs.';
            app.AlterNTimesLabel = uilabel(app.AdvParameterTab);
            app.AlterNTimesLabel.Enable = 'off';
            app.AlterNTimesLabel.Text = {'Alternate '; 'N times'};
            app.AlterNTimesEditField = uieditfield(app.AdvParameterTab, 'numeric');
            app.AlterNTimesEditField.ValueChangedFcn = createCallbackFcn(app, @AlterNTimesEditFieldValueChanged, true);
            app.AlterNTimesEditField.Enable = 'off';
            app.AlterNTimesEditField.Tooltip = {'When to change between metabolites'};
            app.AlterNTimesEditField.Value = 1;
            app.fHzEditFieldLabel = uilabel(app.AdvParameterTab);
            app.fHzEditFieldLabel.Enable = 'off';
            app.fHzEditFieldLabel.Tooltip = {'Frequency 2nd pulse'};
            app.fHzEditFieldLabel.Text = 'f [Hz]';
            app.freq_2ndpulse_HzEditField = uieditfield(app.AdvParameterTab, 'numeric');
            app.freq_2ndpulse_HzEditField.ValueChangedFcn = createCallbackFcn(app, @freq_2ndpulse_HzEditFieldValueChanged, true);
            app.freq_2ndpulse_HzEditField.Enable = 'off';
            app.freq_2ndpulse_HzEditField.Tooltip = {'Frequency 2nd pulse'};
            app.freq_2ndpulse_HzEditField.Value = -900;
            app.altphaseEditFieldLabel = uilabel(app.AdvParameterTab);
            app.altphaseEditFieldLabel.Text = 'alt. phase';
            app.altphaseEditField = uieditfield(app.AdvParameterTab, 'numeric');
            app.altphaseEditField.ValueChangedFcn = createCallbackFcn(app, @altphaseEditFieldValueChanged, true);
            app.altphaseEditField.Tooltip = {'changing pulse phase after each "image". From 0 to set phase and back.'};
            app.homoB1CheckBox = uicheckbox(app.AdvParameterTab);
            app.homoB1CheckBox.Enable = 'off';
            app.homoB1CheckBox.Visible = 'off';
            app.homoB1CheckBox.Tooltip = {'Simulate repeated Excitation.'; 'Assume homogeneous B1 field.'};
            app.homoB1CheckBox.Text = 'homo. B1';
            app.homoB1CheckBox.Value = true;
            app.highresoutCheckBox = uicheckbox(app.AdvParameterTab);
            app.highresoutCheckBox.ValueChangedFcn = createCallbackFcn(app, @highresoutCheckBoxValueChanged, true);
            app.highresoutCheckBox.Tooltip = {'Save every simulated timepoint (N [points] per repetition). This dataset might get quite big really fast (N reps * N points * N freq * N y).'};
            app.highresoutCheckBox.Text = 'high res out';
            app.alpha2prepCheckBox = uicheckbox(app.AdvParameterTab);
            app.alpha2prepCheckBox.ValueChangedFcn = createCallbackFcn(app, @alpha2prepCheckBoxValueChanged, true);
            app.alpha2prepCheckBox.Text = 'alpha/2 prep';
            app.alpha2prepCheckBox.Value = true;
            app.phasetbEditFieldLabel = uilabel(app.AdvParameterTab);
            app.phasetbEditFieldLabel.Text = 'phase tb';
            app.phasetbEditField = uieditfield(app.AdvParameterTab, 'numeric');
            app.phasetbEditField.ValueChangedFcn = createCallbackFcn(app, @phasetbEditFieldValueChanged, true);
            app.phasetbEditField.Tooltip = {'phase of the tipback pulse (in degree, difference to normal phase of pulse)'};
            app.SimrangeTab = uitab(app.SimulateRepetitionsTabGroup);
            app.SimrangeTab.Title = 'Sim. range';
            app.T1EditFieldLabel = uilabel(app.SimrangeTab);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1Range_1EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.T1Range_1EditField.Value = 0.2;
            app.T1Range_2EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.T1Range_2EditField.Value = 30;
            app.T1Range_NEditField = uieditfield(app.SimrangeTab, 'numeric');
            app.T1Range_NEditField.ValueChangedFcn = createCallbackFcn(app, @T1Range_NEditFieldValueChanged, true);
            app.T1Range_NEditField.Value = 2;
            app.T2Range_2EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.T2Range_2EditField.Value = 25;
            app.T2Range_NEditField = uieditfield(app.SimrangeTab, 'numeric');
            app.T2Range_NEditField.ValueChangedFcn = createCallbackFcn(app, @T2Range_NEditFieldValueChanged, true);
            app.T2Range_NEditField.Value = 2;
            app.T2Label = uilabel(app.SimrangeTab);
            app.T2Label.Text = 'T2';
            app.T2Range_1EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.T2Range_1EditField.Value = 0.1;
            app.T2Label_2 = uilabel(app.SimrangeTab);
            app.T2Label_2.Text = 'TR';
            app.TRRange_1EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.TRRange_1EditField.Value = 2;
            app.TRRange_2EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.TRRange_2EditField.Value = 10;
            app.TRRange_NEditField = uieditfield(app.SimrangeTab, 'numeric');
            app.TRRange_NEditField.ValueChangedFcn = createCallbackFcn(app, @TRRange_NEditFieldValueChanged, true);
            app.TRRange_NEditField.Value = 2;
            app.FARange_NEditField = uieditfield(app.SimrangeTab, 'numeric');
            app.FARange_NEditField.ValueChangedFcn = createCallbackFcn(app, @FARange_NEditFieldValueChanged, true);
            app.FARange_NEditField.Value = 2;
            app.FARange_2EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.FARange_2EditField.Value = 90;
            app.FARange_1EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.FARange_1EditField.Value = 2;
            app.T2Label_3 = uilabel(app.SimrangeTab);
            app.T2Label_3.Text = 'FA';
            app.SimRangeButton = uibutton(app.SimrangeTab, 'push');
            app.SimRangeButton.ButtonPushedFcn = createCallbackFcn(app, @SimRangeButtonPushed, true);
            app.SimRangeButton.Text = 'Sim Range!';
            app.simrangeCheckBox = uicheckbox(app.SimrangeTab);
            app.simrangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @simrangeCheckBoxValueChanged, true);
            app.simrangeCheckBox.Text = 'sim. range';
            app.TrfRange_NEditField = uieditfield(app.SimrangeTab, 'numeric');
            app.TrfRange_NEditField.ValueChangedFcn = createCallbackFcn(app, @TrfRange_NEditFieldValueChanged, true);
            app.TrfRange_NEditField.Value = 2;
            app.TrfRange_2EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.TrfRange_2EditField.Value = 30;
            app.TrfRange_1EditField = uieditfield(app.SimrangeTab, 'numeric');
            app.TrfRange_1EditField.Value = 0.1;
            app.TrfEditFieldLabel = uilabel(app.SimrangeTab);
            app.TrfEditFieldLabel.Text = 'Trf';
            app.plotresCheckBox = uicheckbox(app.SimrangeTab);
            app.plotresCheckBox.Text = 'plot res.';
            app.TR2CheckBox = uicheckbox(app.SimrangeTab);
            app.TR2CheckBox.ValueChangedFcn = createCallbackFcn(app, @TR2CheckBoxValueChanged, true);
            app.TR2CheckBox.Tooltip = {'First TR = TR /2 '};
            app.TR2CheckBox.Text = 'TR / 2';
            app.a2CheckBox = uicheckbox(app.SimrangeTab);
            app.a2CheckBox.ValueChangedFcn = createCallbackFcn(app, @a2CheckBoxValueChanged, true);
            app.a2CheckBox.Text = 'a / 2';
            app.SimulaterepeatedExcitationFISPbSSFPLabel = uilabel(app.LeftPanel);
            app.SimulaterepeatedExcitationFISPbSSFPLabel.Text = 'Simulate repeated Excitation (FISP/bSSFP)';
            app.SimulateRepsButton = uibutton(app.LeftPanel, 'push');
            app.SimulateRepsButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateRepsButtonPushed, true);
            app.SimulateRepsButton.Tag = 'simulate_Button';
            app.SimulateRepsButton.Text = 'Simulate Reps!';
            app.progress_sim_repLabel = uilabel(app.LeftPanel);
            app.progress_sim_repLabel.Text = '';
            app.startparpoolButton = uibutton(app.LeftPanel, 'push');
            app.startparpoolButton.ButtonPushedFcn = createCallbackFcn(app, @startparpoolButtonPushed, true);
            app.startparpoolButton.Tooltip = {'Starting the parallel pool (CPU cores will run in parallel). Parallel pool will be started in the repeated excitations anyways.'};
            app.startparpoolButton.Text = 'start par. pool';
            app.reset_B1map_Button = uibutton(app.LeftPanel, 'push');
            app.reset_B1map_Button.ButtonPushedFcn = createCallbackFcn(app, @reset_B1map_ButtonPushed, true);
            app.reset_B1map_Button.Tooltip = {'deletes the B1 profile'};
            app.reset_B1map_Button.Text = 'reset';
            app.SelectGradient_Button = uibutton(app.LeftPanel, 'push');
            app.SelectGradient_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectGradient_ButtonPushed, true);
            app.SelectGradient_Button.Tag = 'selectRF_Button';
            app.SelectGradient_Button.Text = 'Select';
            app.LoadGradient_Button = uibutton(app.LeftPanel, 'push');
            app.LoadGradient_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadGradient_ButtonPushed, true);
            app.LoadGradient_Button.Tag = 'loadRF_Button';
            app.LoadGradient_Button.Text = 'Load';
            app.GradientEditFieldLabel = uilabel(app.LeftPanel);
            app.GradientEditFieldLabel.Text = 'Gradient';
            app.GradientEditField = uieditfield(app.LeftPanel, 'text');
            app.GradientEditField.Tag = 'rfpulse_Button';
            app.GradientEditField.Value = 'path to Gradient...';
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.D1_simulationTab = uitab(app.TabGroup2);
            app.D1_simulationTab.Title = '1D';
            app.pointsLabel = uilabel(app.D1_simulationTab);
            app.pointsLabel.Text = 'points';
            app.resolutionLabel = uilabel(app.D1_simulationTab);
            app.resolutionLabel.Text = 'resolution';
            app.valueLabel = uilabel(app.D1_simulationTab);
            app.valueLabel.Text = 'value';
            app.EditField = uieditfield(app.D1_simulationTab, 'text');
            app.EditField.Enable = 'off';
            app.EditField.Value = '1';
            app.slice_thick = uilabel(app.D1_simulationTab);
            app.slice_thick.Enable = 'off';
            app.slice_thick.Tooltip = {'slicethickness'};
            app.slice_thick.Text = '0';
            app.yGradientStrengthkHzcmLabel = uilabel(app.D1_simulationTab);
            app.yGradientStrengthkHzcmLabel.Text = 'yGradient Strength [kHz/cm]';
            app.ycmLabel = uilabel(app.D1_simulationTab);
            app.ycmLabel.Text = 'FOV [cm]';
            app.freq_hz_res_Label = uilabel(app.D1_simulationTab);
            app.freqHzEditFieldLabel = uilabel(app.D1_simulationTab);
            app.freqHzEditFieldLabel.Text = 'freq [Hz]';
            app.yrange_cm_res_Label = uilabel(app.D1_simulationTab);
            app.pulseduration_ms_res_Label = uilabel(app.D1_simulationTab);
            app.PulseDurationmsEditFieldLabel = uilabel(app.D1_simulationTab);
            app.PulseDurationmsEditFieldLabel.Text = 'Pulse Duration [ms]';
            app.pulseduration_ms_EditField = uieditfield(app.D1_simulationTab, 'numeric');
            app.pulseduration_ms_EditField.Limits = [0.001 100];
            app.pulseduration_ms_EditField.ValueChangedFcn = createCallbackFcn(app, @pulseduration_ms_EditFieldValueChanged, true);
            app.pulseduration_ms_EditField.Tag = 'pulse_duration_Edit';
            app.pulseduration_ms_EditField.Value = 2;
            app.pulseduration_ms_npoints_EditField = uieditfield(app.D1_simulationTab, 'text');
            app.pulseduration_ms_npoints_EditField.ValueChangedFcn = createCallbackFcn(app, @pulseduration_ms_npoints_EditFieldValueChanged, true);
            app.pulseduration_ms_npoints_EditField.Tag = 'pulse_duration_res';
            app.pulseduration_ms_npoints_EditField.Value = '1000';
            app.gradientstrength_khzcm_EditField = uieditfield(app.D1_simulationTab, 'numeric');
            app.gradientstrength_khzcm_EditField.Limits = [-10000 10000];
            app.gradientstrength_khzcm_EditField.ValueDisplayFormat = '%11.7g';
            app.gradientstrength_khzcm_EditField.ValueChangedFcn = createCallbackFcn(app, @gradientstrength_khzcm_EditFieldValueChanged, true);
            app.gradientstrength_khzcm_EditField.Tag = 'grad_strength_Edit';
            app.gradientstrength_khzcm_EditField.Value = 1;
            app.yrange_cm_EditField = uieditfield(app.D1_simulationTab, 'numeric');
            app.yrange_cm_EditField.ValueChangedFcn = createCallbackFcn(app, @yrange_cm_EditFieldValueChanged, true);
            app.yrange_cm_EditField.Tag = 'grad_strength_Edit';
            app.yrange_cm_EditField.Value = 3.2;
            app.yrange_cm_npoints_EditField = uieditfield(app.D1_simulationTab, 'text');
            app.yrange_cm_npoints_EditField.ValueChangedFcn = createCallbackFcn(app, @yrange_cm_npoints_EditFieldValueChanged, true);
            app.yrange_cm_npoints_EditField.Value = '33';
            app.freq_hz_high_EditField = uieditfield(app.D1_simulationTab, 'numeric');
            app.freq_hz_high_EditField.ValueChangedFcn = createCallbackFcn(app, @freq_hz_high_EditFieldValueChanged, true);
            app.freq_hz_high_EditField.Tag = 'grad_strength_Edit';
            app.freq_hz_high_EditField.Value = 1000;
            app.freq_hz_low_EditField = uieditfield(app.D1_simulationTab, 'numeric');
            app.freq_hz_low_EditField.ValueChangedFcn = createCallbackFcn(app, @freq_hz_low_EditFieldValueChanged, true);
            app.freq_hz_low_EditField.Tag = 'grad_strength_Edit';
            app.freq_hz_low_EditField.Value = -1000;
            app.freq_hz_npoints_EditField = uieditfield(app.D1_simulationTab, 'text');
            app.freq_hz_npoints_EditField.ValueChangedFcn = createCallbackFcn(app, @freq_hz_npoints_EditFieldValueChanged, true);
            app.freq_hz_npoints_EditField.Value = '31';
            app.Simulate1D_Button = uibutton(app.D1_simulationTab, 'push');
            app.Simulate1D_Button.ButtonPushedFcn = createCallbackFcn(app, @Simulate1D_ButtonPushed, true);
            app.Simulate1D_Button.Tag = 'simulate_Button';
            app.Simulate1D_Button.Text = 'Simulate !';
            app.D2_simulationTab = uitab(app.TabGroup2);
            app.D2_simulationTab.Title = '2D';
            app.EditField2D = uieditfield(app.D2_simulationTab, 'text');
            app.EditField2D.Enable = 'off';
            app.EditField2D.Value = '1';
            app.slice_thick2D = uilabel(app.D2_simulationTab);
            app.slice_thick2D.Enable = 'off';
            app.slice_thick2D.Tooltip = {'slicethickness'};
            app.slice_thick2D.Text = '0';
            app.yGradientStrengthkHzcmLabel_2 = uilabel(app.D2_simulationTab);
            app.yGradientStrengthkHzcmLabel_2.Text = 'yGradient Strength [kHz/cm]';
            app.gradientstrength2D_khzcm_EditField = uieditfield(app.D2_simulationTab, 'numeric');
            app.gradientstrength2D_khzcm_EditField.Limits = [-10000 10000];
            app.gradientstrength2D_khzcm_EditField.ValueDisplayFormat = '%11.7g';
            app.gradientstrength2D_khzcm_EditField.ValueChangedFcn = createCallbackFcn(app, @gradientstrength2D_khzcm_EditFieldValueChanged, true);
            app.gradientstrength2D_khzcm_EditField.Tag = 'grad_strength_Edit';
            app.gradientstrength2D_khzcm_EditField.Tooltip = {'Gradient Amplitude.'};
            app.gradientstrength2D_khzcm_EditField.Value = 1;
            app.pointsLabel2D = uilabel(app.D2_simulationTab);
            app.pointsLabel2D.Text = 'points';
            app.resolutionLabel2D = uilabel(app.D2_simulationTab);
            app.resolutionLabel2D.Text = 'resolution';
            app.valueLabel2D = uilabel(app.D2_simulationTab);
            app.valueLabel2D.Text = 'value';
            app.pulseduration2D_ms_npoints_EditField = uieditfield(app.D2_simulationTab, 'text');
            app.pulseduration2D_ms_npoints_EditField.ValueChangedFcn = createCallbackFcn(app, @pulseduration2D_ms_npoints_EditFieldValueChanged, true);
            app.pulseduration2D_ms_npoints_EditField.Tag = 'pulse_duration_res';
            app.pulseduration2D_ms_npoints_EditField.Tooltip = {'Number of points to be simulated. '};
            app.pulseduration2D_ms_npoints_EditField.Value = '1000';
            app.pulseduration2D_ms_res_Label = uilabel(app.D2_simulationTab);
            app.pulseduration2D_ms_EditFieldLabel = uilabel(app.D2_simulationTab);
            app.pulseduration2D_ms_EditFieldLabel.Text = 'Pulse Duration [ms]';
            app.pulseduration2D_ms_EditField = uieditfield(app.D2_simulationTab, 'numeric');
            app.pulseduration2D_ms_EditField.Limits = [0 10000];
            app.pulseduration2D_ms_EditField.ValueChangedFcn = createCallbackFcn(app, @pulseduration2D_ms_EditFieldValueChanged, true);
            app.pulseduration2D_ms_EditField.Tag = 'pulse_duration_Edit';
            app.pulseduration2D_ms_EditField.Tooltip = {'RF pulse duration.'};
            app.pulseduration2D_ms_EditField.Value = 5;
            app.gradientduration2D_ms_EditFieldLabel = uilabel(app.D2_simulationTab);
            app.gradientduration2D_ms_EditFieldLabel.Text = 'Gradient Duration [ms]';
            app.gradientduration2D_ms_EditField = uieditfield(app.D2_simulationTab, 'numeric');
            app.gradientduration2D_ms_EditField.Limits = [0 10000];
            app.gradientduration2D_ms_EditField.ValueChangedFcn = createCallbackFcn(app, @gradientduration2D_ms_EditFieldValueChanged, true);
            app.gradientduration2D_ms_EditField.Tag = 'pulse_duration_Edit';
            app.gradientduration2D_ms_EditField.Tooltip = {'Duration of gradient(s).'};
            app.gradientduration2D_ms_EditField.Value = 5;
            app.gradientduration2D_ms_npoints_EditField = uieditfield(app.D2_simulationTab, 'text');
            app.gradientduration2D_ms_npoints_EditField.ValueChangedFcn = createCallbackFcn(app, @gradientduration2D_ms_npoints_EditFieldValueChanged, true);
            app.gradientduration2D_ms_npoints_EditField.Tag = 'pulse_duration_res';
            app.gradientduration2D_ms_npoints_EditField.Tooltip = {'Number of points to be simulated. '};
            app.gradientduration2D_ms_npoints_EditField.Value = '1000';
            app.gradientduration2D_ms_resLabel = uilabel(app.D2_simulationTab);
            app.xrange2D_cm_npoints_EditField = uieditfield(app.D2_simulationTab, 'text');
            app.xrange2D_cm_npoints_EditField.ValueChangedFcn = createCallbackFcn(app, @xrange2D_cm_npoints_EditFieldValueChanged, true);
            app.xrange2D_cm_npoints_EditField.Tooltip = {'Number of points of FOV in x.'};
            app.xrange2D_cm_npoints_EditField.Value = '33';
            app.xrange2D_cm_resLabel = uilabel(app.D2_simulationTab);
            app.x2D_cm_Label = uilabel(app.D2_simulationTab);
            app.x2D_cm_Label.Text = 'FOVx [cm]';
            app.xrange2D_cm_EditField = uieditfield(app.D2_simulationTab, 'numeric');
            app.xrange2D_cm_EditField.ValueChangedFcn = createCallbackFcn(app, @xrange2D_cm_EditFieldValueChanged, true);
            app.xrange2D_cm_EditField.Tag = 'grad_strength_Edit';
            app.xrange2D_cm_EditField.Tooltip = {'FOV in x (centered around 0.)'};
            app.xrange2D_cm_EditField.Value = 3.2;
            app.yrange2D_cm_npoints_EditField = uieditfield(app.D2_simulationTab, 'text');
            app.yrange2D_cm_npoints_EditField.ValueChangedFcn = createCallbackFcn(app, @yrange2D_cm_npoints_EditFieldValueChanged, true);
            app.yrange2D_cm_npoints_EditField.Tooltip = {'Number of points of FOV in y.'};
            app.yrange2D_cm_npoints_EditField.Value = '33';
            app.yrange2D_cm_res_Label = uilabel(app.D2_simulationTab);
            app.yrange2D_cm_EditField = uieditfield(app.D2_simulationTab, 'numeric');
            app.yrange2D_cm_EditField.ValueChangedFcn = createCallbackFcn(app, @yrange2D_cm_EditFieldValueChanged, true);
            app.yrange2D_cm_EditField.Tag = 'grad_strength_Edit';
            app.yrange2D_cm_EditField.Tooltip = {'FOV in y (centered around 0.)'};
            app.yrange2D_cm_EditField.Value = 3.2;
            app.y2D_cm_Label = uilabel(app.D2_simulationTab);
            app.y2D_cm_Label.Text = 'FOVy [cm]';
            app.freq2D_hz_high_EditField = uieditfield(app.D2_simulationTab, 'numeric');
            app.freq2D_hz_high_EditField.ValueChangedFcn = createCallbackFcn(app, @freq2D_hz_high_EditFieldValueChanged, true);
            app.freq2D_hz_high_EditField.Tag = 'grad_strength_Edit';
            app.freq2D_hz_high_EditField.Tooltip = {'Highest frequency to be simulated.'};
            app.freq2D_hz_high_EditField.Value = 1000;
            app.freq2D_hz_npoints_EditField = uieditfield(app.D2_simulationTab, 'text');
            app.freq2D_hz_npoints_EditField.ValueChangedFcn = createCallbackFcn(app, @freq2D_hz_npoints_EditFieldValueChanged, true);
            app.freq2D_hz_npoints_EditField.Tooltip = {'Number of frequencies that will be simulated.'};
            app.freq2D_hz_npoints_EditField.Value = '11';
            app.freq2D_hz_res_Label = uilabel(app.D2_simulationTab);
            app.freq2D_hz_EditFieldLabel = uilabel(app.D2_simulationTab);
            app.freq2D_hz_EditFieldLabel.Text = 'freq [Hz]';
            app.freq2D_hz_low_EditField = uieditfield(app.D2_simulationTab, 'numeric');
            app.freq2D_hz_low_EditField.ValueChangedFcn = createCallbackFcn(app, @freq2D_hz_low_EditFieldValueChanged, true);
            app.freq2D_hz_low_EditField.Tag = 'grad_strength_Edit';
            app.freq2D_hz_low_EditField.Tooltip = {'Lowest frequency to be simulated.'};
            app.freq2D_hz_low_EditField.Value = -1000;
            app.Simulate2D_Button = uibutton(app.D2_simulationTab, 'push');
            app.Simulate2D_Button.ButtonPushedFcn = createCallbackFcn(app, @Simulate2D_ButtonPushed, true);
            app.Simulate2D_Button.Text = '2D sim';
            app.B1_is_cryo_CheckBox = uicheckbox(app.LeftPanel);
            app.B1_is_cryo_CheckBox.ValueChangedFcn = createCallbackFcn(app, @B1_is_cryo_CheckBoxValueChanged, true);
            app.B1_is_cryo_CheckBox.Text = 'cryo';
            app.reset_Gradients_Button = uibutton(app.LeftPanel, 'push');
            app.reset_Gradients_Button.ButtonPushedFcn = createCallbackFcn(app, @reset_Gradients_ButtonPushed, true);
            app.reset_Gradients_Button.Tooltip = {'deletes the loaded Gradient profile'};
            app.reset_Gradients_Button.Text = 'reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_b1profile = uiaxes(app.RightPanel);
            title(app.UIAxes_b1profile, 'B1 profile')
            xlabel(app.UIAxes_b1profile, 'y [cm]')
            ylabel(app.UIAxes_b1profile, 'Y')
            zlabel(app.UIAxes_b1profile, 'Z')
            app.UIAxes_b1profile.ButtonDownFcn = createCallbackFcn(app, @UIAxes_b1profileButtonDown, true);
            app.UIAxes_rfpulse = uiaxes(app.RightPanel);
            title(app.UIAxes_rfpulse, {'RF Pulse'; ''})
            xlabel(app.UIAxes_rfpulse, {'time [ms]'; ''})
            ylabel(app.UIAxes_rfpulse, 'Y')
            zlabel(app.UIAxes_rfpulse, 'Z')
            app.UIAxes_mag_vs_freq = uiaxes(app.RightPanel);
            title(app.UIAxes_mag_vs_freq, {'Magnetization vs. distance'; ''})
            xlabel(app.UIAxes_mag_vs_freq, {'y [cm]'; ''})
            ylabel(app.UIAxes_mag_vs_freq, 'Y')
            zlabel(app.UIAxes_mag_vs_freq, 'Z')
            app.UIAxes_anat_image = uiaxes(app.RightPanel);
            title(app.UIAxes_anat_image, 'Anat Image')
            xlabel(app.UIAxes_anat_image, 'X')
            ylabel(app.UIAxes_anat_image, 'Y')
            zlabel(app.UIAxes_anat_image, 'Z')
            app.freqSliderLabel = uilabel(app.RightPanel);
            app.freqSliderLabel.Text = 'freq';
            app.freqSlider = uislider(app.RightPanel);
            app.freqSlider.ValueChangedFcn = createCallbackFcn(app, @freqSliderValueChanged, true);
            app.freqSlider.ValueChangingFcn = createCallbackFcn(app, @freqSliderValueChanging, true);
            app.Show2FreqsCheckBox = uicheckbox(app.RightPanel);
            app.Show2FreqsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Show2FreqsCheckBoxValueChanged, true);
            app.Show2FreqsCheckBox.Text = 'Show 2 Freqs';
            app.ShowExcitationProfileCheckBox = uicheckbox(app.RightPanel);
            app.ShowExcitationProfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowExcitationProfileCheckBoxValueChanged, true);
            app.ShowExcitationProfileCheckBox.Enable = 'off';
            app.ShowExcitationProfileCheckBox.Text = ' Show Excitation Profile';
            app.SliceSpinnerLabel = uilabel(app.RightPanel);
            app.SliceSpinnerLabel.Enable = 'off';
            app.SliceSpinnerLabel.Text = 'Slice ';
            app.SliceSpinner = uispinner(app.RightPanel);
            app.SliceSpinner.Limits = [1 Inf];
            app.SliceSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceSpinnerValueChanged, true);
            app.SliceSpinner.Enable = 'off';
            app.SliceSpinner.Value = 1;
            app.MetabolitesPanel = uipanel(app.RightPanel);
            app.MetabolitesPanel.Title = 'Metabolites';
            app.Freq1EditField_2Label = uilabel(app.MetabolitesPanel);
            app.Freq1EditField_2Label.Text = 'Freq 1';
            app.Freq1_ppmEditField = uieditfield(app.MetabolitesPanel, 'numeric');
            app.Freq1_ppmEditField.ValueChangedFcn = createCallbackFcn(app, @Freq1_ppmEditFieldValueChanged, true);
            app.Freq1_ppmEditField.Value = 175;
            app.Freq2EditField_2Label = uilabel(app.MetabolitesPanel);
            app.Freq2EditField_2Label.Text = 'Freq 2';
            app.Freq2_ppmEditField = uieditfield(app.MetabolitesPanel, 'numeric');
            app.Freq2_ppmEditField.ValueChangedFcn = createCallbackFcn(app, @Freq2_ppmEditFieldValueChanged, true);
            app.Freq2_ppmEditField.Value = 183.5;
            app.ppmLabel = uilabel(app.MetabolitesPanel);
            app.ppmLabel.Text = 'ppm';
            app.ppmLabel_2 = uilabel(app.MetabolitesPanel);
            app.ppmLabel_2.Text = 'ppm';
            app.BasicFreqEditFieldLabel = uilabel(app.MetabolitesPanel);
            app.BasicFreqEditFieldLabel.Text = 'Basic Freq';
            app.BasicFreqEditField = uieditfield(app.MetabolitesPanel, 'numeric');
            app.BasicFreqEditField.ValueChangedFcn = createCallbackFcn(app, @BasicFreqEditFieldValueChanged, true);
            app.BasicFreqEditField.Value = 183.5;
            app.ppmLabel_3 = uilabel(app.MetabolitesPanel);
            app.ppmLabel_3.Text = 'ppm';
            app.PulseFreqEditFieldLabel = uilabel(app.MetabolitesPanel);
            app.PulseFreqEditFieldLabel.Text = 'Pulse Freq';
            app.HzLabel_3 = uilabel(app.MetabolitesPanel);
            app.HzLabel_3.Text = 'Hz';
            app.PulseFreqEditField = uieditfield(app.MetabolitesPanel, 'numeric');
            app.PulseFreqEditField.ValueChangedFcn = createCallbackFcn(app, @PulseFreqEditFieldValueChanged, true);
            app.FrequenciesPanel = uipanel(app.RightPanel);
            app.FrequenciesPanel.Title = 'Frequencies';
            app.Freq1EditFieldLabel = uilabel(app.FrequenciesPanel);
            app.Freq1EditFieldLabel.Text = 'Freq 1';
            app.Freq1EditField = uieditfield(app.FrequenciesPanel, 'numeric');
            app.Freq1EditField.ValueChangedFcn = createCallbackFcn(app, @Freq1EditFieldValueChanged, true);
            app.Freq2EditFieldLabel = uilabel(app.FrequenciesPanel);
            app.Freq2EditFieldLabel.Text = 'Freq 2';
            app.Freq2EditField = uieditfield(app.FrequenciesPanel, 'numeric');
            app.Freq2EditField.ValueChangedFcn = createCallbackFcn(app, @Freq2EditFieldValueChanged, true);
            app.HzLabel = uilabel(app.FrequenciesPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.FrequenciesPanel);
            app.HzLabel_2.Text = 'Hz';
            app.UseFrequenciesCheckBox = uicheckbox(app.RightPanel);
            app.UseFrequenciesCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFrequenciesCheckBoxValueChanged, true);
            app.UseFrequenciesCheckBox.Text = 'Use Frequencies';
            app.UseFrequenciesCheckBox.Value = true;
            app.inhomoB1ListBoxLabel = uilabel(app.RightPanel);
            app.inhomoB1ListBoxLabel.Enable = 'off';
            app.inhomoB1ListBoxLabel.Text = 'inhomo. B1';
            app.inhomoB1ListBox = uilistbox(app.RightPanel);
            app.inhomoB1ListBox.Items = {'Mx', 'My', '|Mxy|', 'Mz', 'phi', 'none'};
            app.inhomoB1ListBox.Multiselect = 'on';
            app.inhomoB1ListBox.ValueChangedFcn = createCallbackFcn(app, @inhomoB1ListBoxValueChanged, true);
            app.inhomoB1ListBox.Enable = 'off';
            app.inhomoB1ListBox.Value = {'none'};
            app.homoB1ListBoxLabel = uilabel(app.RightPanel);
            app.homoB1ListBoxLabel.Text = 'homo. B1';
            app.homoB1ListBox = uilistbox(app.RightPanel);
            app.homoB1ListBox.Items = {'Mx', 'My', '|Mxy|', 'Mz', 'phi', 'none'};
            app.homoB1ListBox.Multiselect = 'on';
            app.homoB1ListBox.ValueChangedFcn = createCallbackFcn(app, @homoB1ListBoxValueChanged, true);
            app.homoB1ListBox.Value = {'|Mxy|'};
            app.refpow_map_caxis = uislider(app.RightPanel);
            app.refpow_map_caxis.Limits = [1e-06 100];
            app.refpow_map_caxis.ValueChangedFcn = createCallbackFcn(app, @refpow_map_caxisValueChanged, true);
            app.refpow_map_caxis.Visible = 'off';
            app.refpow_map_caxis.Value = 1e-06;
            app.bssfpsignalCheckBox = uicheckbox(app.RightPanel);
            app.bssfpsignalCheckBox.ValueChangedFcn = createCallbackFcn(app, @bssfpsignalCheckBoxValueChanged, true);
            app.bssfpsignalCheckBox.Tooltip = {'if activated, bssfp signal vs repetitions is plotted'};
            app.bssfpsignalCheckBox.Text = 'bssfp signal';
            app.responseprofileCheckBox = uicheckbox(app.RightPanel);
            app.responseprofileCheckBox.Tooltip = {'if ticked, the frequency response profile is plotted.'};
            app.responseprofileCheckBox.Text = 'response profile';
            app.responseprofileCheckBox.Value = true;
            app.repSliderLabel = uilabel(app.RightPanel);
            app.repSliderLabel.Enable = 'off';
            app.repSliderLabel.Text = 'rep';
            app.repSlider = uislider(app.RightPanel);
            app.repSlider.Limits = [1 100];
            app.repSlider.ValueChangedFcn = createCallbackFcn(app, @repSliderValueChanged, true);
            app.repSlider.ValueChangingFcn = createCallbackFcn(app, @repSliderValueChanging, true);
            app.repSlider.Enable = 'off';
            app.repSlider.Tooltip = {'Lets you choose the response profile at the nth repetition.'};
            app.repSlider.Value = 1;
            app.refpow_map_caxis_2 = uislider(app.RightPanel);
            app.refpow_map_caxis_2.Limits = [0 2000];
            app.refpow_map_caxis_2.ValueChangedFcn = createCallbackFcn(app, @refpow_map_caxis_2ValueChanged, true);
            app.refpow_map_caxis_2.Visible = 'off';
            app.refpow_map_caxis_2.Value = 1e-06;
            % Show the figure after all components are created
            app.calc_rf_pulse_toolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulate_rf_pulse_b1_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.calc_rf_pulse_toolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.calc_rf_pulse_toolUIFigure)
        end
    end
end